classdef MakeMuscleGroups < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MuscleGroupsLabel           matlab.ui.control.Label
        MuscleGroupsTable           matlab.ui.control.Table
        FilterByEditField           matlab.ui.control.EditField
        FilterByEditFieldLabel      matlab.ui.control.Label
        EditButton                  matlab.ui.control.Button
        DeleteButton                matlab.ui.control.Button
        CopyButton                  matlab.ui.control.Button
        MirrorButton                matlab.ui.control.Button
        AddButton                   matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        CloseButton                 matlab.ui.control.Button
        InputModelFileEditField     matlab.ui.control.EditField
        InputModelFileLabel         matlab.ui.control.Label
        InputModelFileSearchButton  matlab.ui.control.Button
        InputModelFileError         matlab.ui.control.Image
    end

    
    properties (Access = private, SetObservable)
        input_model_file string;
        errorFlag logical = false;
        modelErrorFlag logical = false;
        modelStruct struct;
        groups cell;
        groupNames cell;
        modelMuscles cell;
    end


    methods (Access = private)
        
        function ErrorsCallback(app)
            app.errorFlag = false;

            % Check for osim model errors
            [app.modelErrorFlag, modelErrorMessage] = parseModelFileGui( ...
                app, app.input_model_file);
            if app.modelErrorFlag
                throwGuiError(modelErrorMessage, ...
                    app.InputModelFileEditField, app.InputModelFileError)
                app.errorFlag = true;
            else
                clearGuiError(app.InputModelFileEditField, app.InputModelFileError)
            end
            app.EnableActionsCallback();
        end

        function EnableActionsCallback(app)
            if strcmp(app.input_model_file, "") || app.errorFlag
                app.AddButton.Enable = 'off';
                app.SaveButton.Enable = 'off';
            else
                app.AddButton.Enable = 'on';
                app.SaveButton.Enable = 'on';
            end
        end

        function makeModelStruct(app)
            app.modelStruct = xml2struct(app.input_model_file);
            app.groups = app.modelStruct.OpenSimDocument.Model.ForceSet. ...
                groups.ObjectGroup;
            app.parseModelMuscles();
        end

        function parseModelMuscles(app)
            model = Model(app.input_model_file);
            muscles = {};
            for i = 0:model.getForceSet.getMuscles().getSize() - 1
                muscles{end+1} = model.getForceSet().getMuscles(). ...
                    get(i).getName().toCharArray()';
            end
            app.modelMuscles = muscles;
            % muscleSet = model.getForceSet().getMuscles();
        end

    end

    properties(Access = private) % listener properties
        InputModelFileListener
        GroupNamesListener
    end

    methods(Access = private) % listener functions

        function makeListeners(app)
            app.InputModelFileListener = addlistener(app, ...
                'input_model_file', 'PostSet', ...
                @(src,event)updateInputModelFile(app));

            app.GroupNamesListener = addlistener(app, ...
                'groups', 'PostSet', ...
                @(src,event)updateGroupNames(app));
        end

        function updateInputModelFile(app)
            app.InputModelFileEditField.Value = getRelativePath( ...
                app.input_model_file);
            app.ErrorsCallback()
            if ~app.modelErrorFlag
                app.makeModelStruct
            end
        end

        function updateGroupNames(app)
            app.groupNames = cell(1, length(app.groups));
            for i = 1 : length(app.groups)
                app.groupNames{i} = app.groups{i}.Attributes.name;
            end
            % app.MuscleGroupsListBox.Items = app.groupNames;
            app.MuscleGroupsTable.Data = app.groupNames';
        end

        function filterGroupNames(app, keyword)
            filteredIndices = contains(app.groupNames, keyword, ...
                "IgnoreCase",true);

            filteredGroups = {app.groups{filteredIndices}};
            filteredGroupNames = cell(1, length(filteredGroups));
            for i = 1 : length(filteredGroups)
                filteredGroupNames{i} = filteredGroups{i}.Attributes.name;
            end

            app.MuscleGroupsTable.Data = filteredGroupNames';
        end
    end

    methods(Access = public)
        
        function setSelectedObjects(app, objects)
            % Function to assign objects selected by ObjectSelectionWindow
            % struct() = objects;
            group = struct("members", [], "Attributes", []);
            group.members = strjoin(objects, " ");
            group.Attributes.name = 'Name your task';
            app.addGroup(group)
            % app.MuscleGroupsTable.findobj.Data{113};
            %% select that task to edit here
        end

        function addGroup(app, group)
            app.groups{end+1} = group;

        end

        function editGroup(app, group)
            app.groups(app.MuscleGroupsTable.index) = group;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.makeListeners()
        end

        % Value changed function: InputModelFileEditField
        function InputModelFileEditFieldValueChanged(app, event)
            app.input_model_file = getAbsolutePath( ...
                app.InputModelFileEditField.Value);
        end

        % Button pushed function: InputModelFileSearchButton
        function InputModelFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osim', "Select an OpenSim Model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end

            app.input_model_file = fullfile(path, file);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            ObjectSelectionWindow(app, app.modelMuscles, []);
        end

        % Value changed function: FilterByEditField
        function FilterByEditFieldValueChanged(app, event)
            keyword = app.FilterByEditField.Value;
            app.filterGroupNames(keyword)
        end

        % Callback function: not associated with a component
        function MuscleGroupsListBoxDoubleClicked(app, event)
            app.MuscleGroupsListBox.Position

            
        end

        % Cell edit callback: MuscleGroupsTable
        function MuscleGroupsTableCellEdit(app, event)
            index = event.Indices(1);
            newName = string(event.NewData);
        
            if strlength(strtrim(newName)) == 0
                % revert change
                app.ObjectTable.Data.Name(index) = event.PreviousData;
                return
            end

            app.groups{index}.Attributes.name = convertStringsToChars(newName);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputModelFileError = uiimage(app.UIFigure);
            app.InputModelFileError.Visible = 'off';
            app.InputModelFileError.ImageSource = fullfile(pathToMLAPP, 'Images', 'error.png');
            app.InputModelFileSearchButton = uibutton(app.UIFigure, 'push');
            app.InputModelFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputModelFileSearchButtonPushed, true);
            app.InputModelFileSearchButton.Icon = fullfile(pathToMLAPP, 'Images', 'folderIcon.svg');
            app.InputModelFileSearchButton.Text = '';
            app.InputModelFileLabel = uilabel(app.UIFigure);
            app.InputModelFileLabel.Text = 'Input Model File';
            app.InputModelFileEditField = uieditfield(app.UIFigure, 'text');
            app.InputModelFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputModelFileEditFieldValueChanged, true);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.Text = 'Close';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.MirrorButton = uibutton(app.UIFigure, 'push');
            app.MirrorButton.Text = 'Mirror';
            app.CopyButton = uibutton(app.UIFigure, 'push');
            app.CopyButton.Text = 'Copy';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.Text = 'Delete';
            app.EditButton = uibutton(app.UIFigure, 'push');
            app.EditButton.Text = 'Edit';
            app.FilterByEditFieldLabel = uilabel(app.UIFigure);
            app.FilterByEditFieldLabel.Text = 'Filter By';
            app.FilterByEditField = uieditfield(app.UIFigure, 'text');
            app.FilterByEditField.ValueChangedFcn = createCallbackFcn(app, @FilterByEditFieldValueChanged, true);
            app.MuscleGroupsTable = uitable(app.UIFigure);
            app.MuscleGroupsTable.ColumnName = '';
            app.MuscleGroupsTable.RowName = {};
            app.MuscleGroupsTable.SelectionType = 'row';
            app.MuscleGroupsTable.ColumnEditable = true;
            app.MuscleGroupsTable.CellEditCallback = createCallbackFcn(app, @MuscleGroupsTableCellEdit, true);
            app.MuscleGroupsTable.Multiselect = 'off';
            app.MuscleGroupsLabel = uilabel(app.UIFigure);
            app.MuscleGroupsLabel.Text = 'Muscle Groups';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MakeMuscleGroups

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end