classdef GCPBaseVisual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResetButton                    matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        HelpButton                     matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        LoadSettingsFileButton         matlab.ui.control.Button
        RcnlLogo                       matlab.ui.control.Image
        InputsButton                   matlab.ui.control.Button
        ContactSurfacesButton          matlab.ui.control.Button
        GCPTasksButton                 matlab.ui.control.Button
        AdvancedButton                 matlab.ui.control.Button
        MTPImage                       matlab.ui.control.Image
        Mask1                          matlab.ui.control.Image
        TabGroup                       matlab.ui.container.TabGroup
        InputsTab                      matlab.ui.container.Tab
        MTPResultsDirectoryLabel       matlab.ui.control.Label
        InputForceFile                 matlab.ui.control.EditField
        InputForceFileSearchButton     matlab.ui.control.Button
        InputForceFileStatus           matlab.ui.control.Image
        InputMotionFileLabel           matlab.ui.control.Label
        GCPResultsDirectoryStatus      matlab.ui.control.Image
        GCPResultsDirectoryEditField   matlab.ui.control.EditField
        GCPResultsDirectoryEditFieldLabel  matlab.ui.control.Label
        GCPResultsDirectorySearchButton  matlab.ui.control.Button
        InputMotionFileStatus          matlab.ui.control.Image
        InputMotionFileEditField       matlab.ui.control.EditField
        InputMotionFileSearchButton    matlab.ui.control.Button
        InputOsimxFileStatus           matlab.ui.control.Image
        InputOsimxFileEditField        matlab.ui.control.EditField
        InputOsimxFileEditFieldLabel   matlab.ui.control.Label
        InputOsimxFileSearchButton     matlab.ui.control.Button
        InputModelFileStatus           matlab.ui.control.Image
        InputModelFileEditField        matlab.ui.control.EditField
        InputModelFileEditFieldLabel   matlab.ui.control.Label
        InputModelFileSearchButton     matlab.ui.control.Button
        ContactSurfacesTab             matlab.ui.container.Tab
        MidfootSuperiorMarkerStatus    matlab.ui.control.Image
        LateralMarkerStatus            matlab.ui.control.Image
        HeelMarkerStatus               matlab.ui.control.Image
        MedialMarkerStatus             matlab.ui.control.Image
        ToeMarkerStatus                matlab.ui.control.Image
        HindfootBodyStatus             matlab.ui.control.Image
        ElectricalCenterColumnsStatus  matlab.ui.control.Image
        MomentColumnsStatus            matlab.ui.control.Image
        ForceColumnsStatus             matlab.ui.control.Image
        TimeRangeStatus                matlab.ui.control.Image
        GridHeightEditField            matlab.ui.control.NumericEditField
        GridHeightEditFieldLabel       matlab.ui.control.Label
        GridWidthEditField             matlab.ui.control.NumericEditField
        GridWidthEditFieldLabel        matlab.ui.control.Label
        MidfootSuperiorMarkerDropDown  matlab.ui.control.DropDown
        MidfootSuperiorMarkerLabel     matlab.ui.control.Label
        HeelMarkerDropDown             matlab.ui.control.DropDown
        HeelMarkerDropDownLabel        matlab.ui.control.Label
        LateralMarkerDropDown          matlab.ui.control.DropDown
        LateralMarkerDropDownLabel     matlab.ui.control.Label
        MedialMarkerDropDown           matlab.ui.control.DropDown
        MedialMarkerLabel              matlab.ui.control.Label
        ToeMarkerDropDown              matlab.ui.control.DropDown
        ToeMarkerDropDownLabel         matlab.ui.control.Label
        HindfootBodyDropDown           matlab.ui.control.DropDown
        HindfootBodyNameLabel          matlab.ui.control.Label
        ForceColumnsDropDown_3         matlab.ui.control.DropDown
        ForceColumnsDropDown_2         matlab.ui.control.DropDown
        MomentColumnsDropDown_3        matlab.ui.control.DropDown
        MomentColumnsDropDown_2        matlab.ui.control.DropDown
        ElectricalCenterColumnsDropDown_3  matlab.ui.control.DropDown
        ElectricalCenterColumnsDropDown_2  matlab.ui.control.DropDown
        ElectricalCenterColumnsDropDown  matlab.ui.control.DropDown
        ElectricalCenterColumnsDropDownLabel  matlab.ui.control.Label
        MomentColumnsDropDown          matlab.ui.control.DropDown
        MomentColumnsLabel             matlab.ui.control.Label
        ForceColumnsDropDown           matlab.ui.control.DropDown
        ForceColumnsLabel              matlab.ui.control.Label
        BeltSpeedEditField             matlab.ui.control.NumericEditField
        BeltSpeedEditFieldLabel        matlab.ui.control.Label
        EndTimeEditField               matlab.ui.control.NumericEditField
        EndTimeEditFieldLabel          matlab.ui.control.Label
        StartTimeEditField             matlab.ui.control.NumericEditField
        StartTimeEditFieldLabel        matlab.ui.control.Label
        IsLeftFootCheckBox             matlab.ui.control.CheckBox
        ContactSurfacesStatus          matlab.ui.control.Image
        ContactSurfacesTable           matlab.ui.control.Table
        ContactSurfacesLabel           matlab.ui.control.Label
        GCPTasksTab                    matlab.ui.container.Tab
        NeighborStandardDeviationEditField  matlab.ui.control.NumericEditField
        NeighborStandardDeviationEditFieldLabel  matlab.ui.control.Label
        DesignVariablesStatus          matlab.ui.control.Image
        CostTermsStatus                matlab.ui.control.Image
        TasksStatus                    matlab.ui.control.Image
        MoveTaskDownButton             matlab.ui.control.Button
        MoveTaskUpButton               matlab.ui.control.Button
        TasksTable                     matlab.ui.control.Table
        TasksLabel                     matlab.ui.control.Label
        DesignVariablesTable           matlab.ui.control.Table
        EditDesignVariablesLabel       matlab.ui.control.Label
        EditCostTermsLabel             matlab.ui.control.Label
        CostTermPanel                  matlab.ui.container.Panel
        CostTermsTable                 matlab.ui.control.Table
        ErrorCenterEditField           matlab.ui.control.NumericEditField
        ErrorCenterEditField_2Label    matlab.ui.control.Label
        MaxAllowableErrorEditField     matlab.ui.control.NumericEditField
        MaxAllowableErrorEditField_2Label  matlab.ui.control.Label
        AdvancedTab                    matlab.ui.container.Tab
        AdvancedSettingsStatus         matlab.ui.control.Image
        AdvancedSettingsTable          matlab.ui.control.Table
        GroundContactModelPersonalizationToolLabel  matlab.ui.control.Label
    end

    
    properties (Access = private, SetObservable)
        model_coordinates string = [];
        model_groups string = [];

        input_model_file string = "";
        input_osimx_file string = "";
        data_directory string = "";
        mtp_results_directory string = "";
        results_directory string = "";
        coordinate_list string = [];
        trial_prefixes string = [];
        v_max_factor double = 10;

        activation_muscle_groups string = [];
        normalized_fiber_length_muscle_groups string = [];
        synergyGroups string = [];

        MuscleTendonLengthInitialization handle = ...
            MuscleTendonLengthInitializationClass();

        RCNLCostTerm cell = cell(0)

        RCNLSynergy cell = cell(0)

        costTermStruct = struct( ...
                'moment_tracking',            {{'true'   0     5}}, ...
                'activation_tracking',        {{'true'   0     0.05}}, ...
                'activation_minimization',    {{'false'   0     0.1}}, ...
                'grouped_activations',        {{'false'   0     0.1}}, ...
                'grouped_fiber_lengths',      {{'false'   0     0.1}} ...
                );


        advancedSettingNames = ...
            ["v_max_factor"
            "max_iterations"
            "max_function_evaluations"
            "step_tolerance"
            "function_tolerance"
            "optimality_tolerance"
            "diff_min_change"]

        advancedSettingValues = ...
            [10
            1000
            100000000
            1e-6
            1e-6
            1e-6
            0.0001]

        needToSave = true;
        objectSelectionType;
    end

    properties(Access = private)  % listner properties
        InputModelFileListener
        OsimXFileListener
        DataDirectoryListener
        MtpResultsDirectoryListener
        NcpResultsDirectoryListener
        CoordinateListListener
        trialPrefixesListener
        ActivationGroupsListener
        FiberLengthGroupsListener
        PassiveDataDirectoryListener
        MaxFiberLengthListener
        MinFiberLengthListener
        advancedSettingsListener
        selectedTabListener
        
        ncpCostTermListener
        synergyGroupsListener
        enableMtliListener
        mtliCostTermListener

    end

    methods (Access = private) % listener methods
        function makeListeners(app)
            app.InputModelFileListener = addlistener(app, ...
                'input_model_file', 'PostSet', ...
                @(src,event)InputModelFileListenerFunction(app));

            app.OsimXFileListener = addlistener(app, ...
                'input_osimx_file', 'PostSet', ...
                @(src,event)OsimXFileListenerFunction(app));

            app.DataDirectoryListener = addlistener(app, ...
                'data_directory', 'PostSet', ...
                @(src,event)DataDirectoryListenerFunction(app));

            app.MtpResultsDirectoryListener = addlistener(app, ...
                'mtp_results_directory', 'PostSet', ...
                @(src,event)MtpResultsDirectoryListenerFunction(app));

            app.NcpResultsDirectoryListener = addlistener(app, ...
                'results_directory', 'PostSet', ...
                @(src,event)NcpResultsDirectoryListenerFunction(app));

            app.CoordinateListListener = addlistener(app, ...
                'coordinate_list', 'PostSet', ...
                @(src,event)CoordinateListListenerFunction(app));

            app.trialPrefixesListener = addlistener(app, ...
                'trial_prefixes', 'PostSet', ...
                @(src, event)updateTrialPrefixes(app));

            app.ActivationGroupsListener = addlistener(app, ...
                'activation_muscle_groups', 'PostSet', ...
                @(src,event)ActivationGroupsListenerFunction(app));

            app.FiberLengthGroupsListener = addlistener(app, ...
                'normalized_fiber_length_muscle_groups', 'PostSet', ...
                @(src,event)FiberLengthGroupsListenerFunction(app));

            app.PassiveDataDirectoryListener = addlistener(...
                app.MuscleTendonLengthInitialization, ...
                'passive_data_input_directory', 'PostSet', ...
                @(src,event)updatePassiveDataDirectory(app));

            app.MaxFiberLengthListener = addlistener(...
                app.MuscleTendonLengthInitialization, ...
                'max_normalized_muscle_fiber_length', 'PostSet', ...
                @(src,event)maxFiberLengthListenerFunction(app));

            app.MinFiberLengthListener = addlistener(...
                app.MuscleTendonLengthInitialization, ...
                'min_normalized_muscle_fiber_length', 'PostSet', ...
                @(src,event)minFiberLengthListenerFunction(app));

            app.advancedSettingsListener = addlistener(app, ...
                'advancedSettingValues', 'PostSet', ...
                @(src, event)updateAdvancedSettingValues(app));

            app.selectedTabListener = addlistener(app.TabGroup, ...
                'SelectedTab', 'PostSet', ...
                @(src, event)formatTabButtons(app));

            app.enableMtliListener = addlistener(app.MuscleTendonLengthInitialization, ...
                'is_enabled', 'PostSet', ...
                @(src, event)updateEnableMtliBox(app));

            app.advancedSettingsListener = addlistener(app, ...
                'RCNLCostTerm', 'PostSet', ...
                @(src, event)updateNCPCostTermsTable(app));

            app.synergyGroupsListener = addlistener(app, ...
                'synergyGroups', 'PostSet', ...
                @(src, event)updateRCNLSynergy(app));

            app.mtliCostTermListener = addlistener(app.MuscleTendonLengthInitialization, ...
                'RCNLCostTerm', 'PostSet', ...
                @(src, event)updateMtliCostTermsFields(app));
        end

        function InputModelFileListenerFunction(app)
            app.InputModelFileEditField.Value = getRelativePath( ...
                app.input_model_file);
            parseModelFileGui(app, app.input_model_file);
            app.needToSave = true;
        end

        function OsimXFileListenerFunction(app)
            app.InputOsimxFileEditField.Value = getRelativePath( ...
                app.input_osimx_file);
            app.needToSave = true;
        end

        function DataDirectoryListenerFunction(app)
            app.InputMotionFileEditField.Value = getRelativePath( ...
                app.data_directory);
            parseMtpDataDirectoryGui(app, app.data_directory);
            app.needToSave = true;
        end

        function MtpResultsDirectoryListenerFunction(app)
            app.InputForceFile.Value = getRelativePath( ...
                app.mtp_results_directory);
            app.needToSave = true;
        end

        function NcpResultsDirectoryListenerFunction(app)
            app.GCPResultsDirectoryEditField.Value = getRelativePath( ...
                app.results_directory);
            app.needToSave = true;
        end

        function CoordinateListListenerFunction(app)
            app.CoordinatesListTextArea.Value =  ...
                strjoin(app.coordinate_list, ", ");
            app.needToSave = true;
        end

        function updateTrialPrefixes(app)
            app.TrialPrefixesEditField.Value = app.trial_prefixes;
        end

        function ActivationGroupsListenerFunction(app)
            app.ActivationMuscleGroupsTextArea.Value = ...
                strjoin(app.activation_muscle_groups, ", ");
            app.needToSave;
        end

        function FiberLengthGroupsListenerFunction(app)
            app.NormalizedFiberLengthMuscleGroupsTextArea.Value = ...
                strjoin(app.normalized_fiber_length_muscle_groups, ", ");
            app.needToSave;
        end

        function updateEnableMtliBox(app)
            app.EnableMTLICheckBox.Value = strcmp( ...
                app.MuscleTendonLengthInitialization.is_enabled, 'true');
        end

        function updatePassiveDataDirectory(app)
            app.PassiveDataDirectoryEditField.Value = ...
                getRelativePath(app.MuscleTendonLengthInitialization. ...
                passive_data_input_directory);
        end

        function maxFiberLengthListenerFunction(app)
            app.MaxNormalizedFiberLengthEditField.Value = ...
                app.MuscleTendonLengthInitialization.max_normalized_muscle_fiber_length;
        end

        function minFiberLengthListenerFunction(app)
            app.MinNormalizedFiberLengthEditField.Value = ...
                app.MuscleTendonLengthInitialization.min_normalized_muscle_fiber_length;
        end

        function updateAdvancedSettingValues(app)
            app.AdvancedSettingsTable.Data.Values = ...
                app.advancedSettingValues;
        end

        function formatTabButtons(app) % good
            switch app.TabGroup.SelectedTab
                case app.InputsTab
                    app.InputsButton.BackgroundColor = [1 1 1];
                    app.InputsButton.FontColor = [0.13,0.18,0.40];
                    
                    app.ContactSurfacesButton.BackgroundColor = [0.13,0.18,0.40];
                    app.ContactSurfacesButton.FontColor = [1 1 1];

                    app.GCPTasksButton.BackgroundColor = [0.13,0.18,0.40];
                    app.GCPTasksButton.FontColor = [1 1 1];

                    app.AuxiliaryToolsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AuxiliaryToolsButton.FontColor = [1 1 1];

                    app.AdvancedButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AdvancedButton.FontColor = [1 1 1];

                case app.ContactSurfacesTab
                    app.InputsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.InputsButton.FontColor = [1 1 1];
                    
                    app.ContactSurfacesButton.BackgroundColor = [1 1 1];
                    app.ContactSurfacesButton.FontColor = [0.13,0.18,0.40];

                    app.GCPTasksButton.BackgroundColor = [0.13,0.18,0.40];
                    app.GCPTasksButton.FontColor = [1 1 1];

                    app.AuxiliaryToolsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AuxiliaryToolsButton.FontColor = [1 1 1];

                    app.AdvancedButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AdvancedButton.FontColor = [1 1 1];

                case app.GCPTasksTab
                    app.InputsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.InputsButton.FontColor = [1 1 1];
                    
                    app.ContactSurfacesButton.BackgroundColor = [0.13,0.18,0.40];
                    app.ContactSurfacesButton.FontColor = [1 1 1];

                    app.GCPTasksButton.BackgroundColor = [1 1 1];
                    app.GCPTasksButton.FontColor = [0.13,0.18,0.40];

                    app.AuxiliaryToolsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AuxiliaryToolsButton.FontColor = [1 1 1];

                    app.AdvancedButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AdvancedButton.FontColor = [1 1 1];

                case app.AuxiliaryTab
                    app.InputsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.InputsButton.FontColor = [1 1 1];
                    
                    app.ContactSurfacesButton.BackgroundColor = [0.13,0.18,0.40];
                    app.ContactSurfacesButton.FontColor = [1 1 1];

                    app.GCPTasksButton.BackgroundColor = [0.13,0.18,0.40];
                    app.GCPTasksButton.FontColor = [1 1 1];

                    app.AuxiliaryToolsButton.BackgroundColor = [1 1 1];
                    app.AuxiliaryToolsButton.FontColor = [0.13,0.18,0.40];

                    app.AdvancedButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AdvancedButton.FontColor = [1 1 1];

                case app.AdvancedTab
                    app.InputsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.InputsButton.FontColor = [1 1 1];
                    
                    app.ContactSurfacesButton.BackgroundColor = [0.13,0.18,0.40];
                    app.ContactSurfacesButton.FontColor = [1 1 1];

                    app.GCPTasksButton.BackgroundColor = [0.13,0.18,0.40];
                    app.GCPTasksButton.FontColor = [1 1 1];

                    app.AuxiliaryToolsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AuxiliaryToolsButton.FontColor = [1 1 1];

                    app.AdvancedButton.BackgroundColor = [1 1 1];
                    app.AdvancedButton.FontColor = [0.13,0.18,0.40];
            end
        end

        function makeDefaultCostTermSet(app)
            costTermNames = fieldnames(app.costTermStruct);
            for i = 1 : length(costTermNames)
                costTerm = RCNLCostTermClass();
                costTerm.is_enabled = app.costTermStruct.(costTermNames{i}){1};
                costTerm.type = costTermNames{i};
                costTerm.error_center = app.costTermStruct.(costTermNames{i}){2};
                costTerm.max_allowable_error = app.costTermStruct.(costTermNames{i}){3};
                app.RCNLCostTerm{i} = costTerm;
            end
        end

        function initMtli(app)
            app.MuscleTendonLengthInitialization.makeDefaultCostTermSet();
        end

        function updateNCPCostTermsTable(app)
            isEnabled = true(length(app.RCNLCostTerm), 1);
            costTermTypes = strings(length(app.RCNLCostTerm), 1);
            for i = 1 : length(app.RCNLCostTerm)
                isEnabled(i) = strcmp(app.RCNLCostTerm{i}.is_enabled, "true");
                costTermTypes(i) = app.RCNLCostTerm{i}.type;
            end
            app.NCPCostTermsTable.Data = table(isEnabled, costTermTypes);
        end

        function updateRCNLSynergy(app)
            numSynergies = ones(length(app.RCNLSynergy));
            for i = 1 : length(app.RCNLSynergy)
                numSynergies(i) = app.RCNLSynergy{i}.num_synergies;
            end
            for i = 1 : length(app.synergyGroups)
                app.RCNLSynergy{i}.muscle_group_name = app.synergyGroups(i);
                if i > length(numSynergies)
                    app.RCNLSynergy{i}.num_synergies = 1;
                else
                    app.RCNLSynergy{i}.num_synergies = numSynergies(i);
                end
            end
            if length(app.RCNLSynergy) >  length(app.synergyGroups)
                app.RCNLSynergy = {app.RCNLSynergy{1:length(app.synergyGroups)}};
            end
            app.updateSynergyGroupsTable()
        end

        function updateSynergyGroupsTable(app)
            numSynergies = zeros(length(app.RCNLSynergy), 1);
            synergyNames = strings(length(app.RCNLSynergy), 1);
            for i = 1 : length(app.RCNLSynergy)
                numSynergies(i) = app.RCNLSynergy{i}.num_synergies;
                synergyNames(i) = app.RCNLSynergy{i}.muscle_group_name;
            end
            app.SynergyGroupsTable.Data = table(synergyNames, numSynergies);
        end

        function updateMtliCostTermsFields(app)
            numCostTerms = length(app.MuscleTendonLengthInitialization. ...
                RCNLCostTerm);
            isEnabled = false(numCostTerms, 1);
            costTermNames = strings(numCostTerms, 1);
            for i = 1 : numCostTerms
                isEnabled(i) = strcmp(app.MuscleTendonLengthInitialization. ...
                    RCNLCostTerm{i}.is_enabled, "true");
                costTermNames(i) = app.MuscleTendonLengthInitialization. ...
                    RCNLCostTerm{i}.type;
                app.MtliCostTermsTable.Data = table(isEnabled, costTermNames);
            end
        end
    end

    methods(Access=public)
        function setSelectedObjects(app, objects)
            % Function to assign objects selected by ObjectSelectionWindow
        switch app.objectSelectionType
            case 'coordinate'
                app.coordinate_list = objects;
            case 'activationGroup'
                app.activation_muscle_groups = objects;
            case 'fiberLengthGroup'
                app.normalized_fiber_length_muscle_groups = objects;
            case 'synergyGroup'
                app.synergyGroups = objects;
        end
        end
        
        function modelFile = getInputModelFile(app)
            modelFile = app.input_model_file;
        end

        function model_coordinates = getModelCoordinate(app)
            model_coordinates = app.model_coordinates;
        end

        function setModelCoordinates(app, coordinates)
            app.model_coordinates = coordinates;
        end

        function setModelGroups(app, groups)
            app.model_groups = groups;
        end
        
        function setTimePoints(app, timePoints)
            app.timePoints = timePoints;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.makeListeners()
            app.makeDefaultCostTermSet();
            app.initMtli()
            Options = app.advancedSettingNames;
            Values = [
                sprintf("%.0f", app.advancedSettingValues(1))
                sprintf("%.0f", app.advancedSettingValues(2))
                sprintf("%.3e", app.advancedSettingValues(3))
                sprintf("%.3e", app.advancedSettingValues(4))
                sprintf("%.3e", app.advancedSettingValues(5))
                sprintf("%.3e", app.advancedSettingValues(6))
                sprintf("%.3e", app.advancedSettingValues(7))];
            app.AdvancedSettingsTable.Data = table( ...
                Options, Values);
            app.updateMtliCostTermsFields()
            app.formatTabButtons()
        end

        % Button pushed function: LoadSettingsFileButton
        function LoadSettingsFileButtonPushed(app, event)
            [file, path] = uigetfile('*.xml', "Load XML Settings File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.loadSettingsFile(fullfile(path, file));
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file, path] = uiputfile('*.xml', "Save XML Settings File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.saveSettingsFile(fullfile(path, file))
            % app.currentSettingsFile = fullfile(path, file);
            app.needToSave = false;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web("https://nmsm.rice.edu/guides-and-publications/tool-overviews/model-personalization/muscle-tendon-personalization/")
        end

        % Button pushed function: InputsButton
        function InputsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.InputsTab;
        end

        % Button pushed function: ContactSurfacesButton
        function ContactSurfacesButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ContactSurfacesTab;
        end

        % Button pushed function: GCPTasksButton
        function GCPTasksButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.GCPTasksTab;
        end

        % Callback function: not associated with a component
        function AuxiliaryToolsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AuxiliaryTab;
        end

        % Button pushed function: AdvancedButton
        function AdvancedButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AdvancedTab;
        end

        % Value changed function: InputModelFileEditField
        function InputModelFileEditFieldValueChanged(app, event)
            app.input_model_file = GetFullPath( ...
                app.InputModelFileEditField.Value);
        end

        % Button pushed function: InputModelFileSearchButton
        function InputModelFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osim', "Select an OpenSim Model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.input_model_file = fullfile(path, file);
        end

        % Value changed function: InputOsimxFileEditField
        function InputOsimxFileEditFieldValueChanged(app, event)
            app.input_osimx_file = GetFullPath( ...
                app.InputOsimxFileEditField.Value);
        end

        % Button pushed function: InputOsimxFileSearchButton
        function InputOsimxFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osimx', "Select an Osimx model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.input_osimx_file = fullfile(path, file);
        end

        % Value changed function: InputMotionFileEditField
        function InputMotionFileEditFieldValueChanged(app, event)
            app.data_directory = GetFullPath( ...
                app.InputMotionFileEditField.Value);
        end

        % Button pushed function: InputMotionFileSearchButton
        function InputMotionFileSearchButtonPushed(app, event)
            folder = uigetdir("Select your input data folder");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.data_directory = folder;
        end

        % Value changed function: InputForceFile
        function InputForceFileValueChanged(app, event)
            app.mtp_results_directory =  GetFullPath( ... 
                app.InputForceFile.Value);
        end

        % Button pushed function: InputForceFileSearchButton
        function InputForceFileSearchButtonPushed(app, event)
            folder = uigetdir("Select your MTP data folder");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.mtp_results_directory = folder;
        end

        % Value changed function: GCPResultsDirectoryEditField
        function GCPResultsDirectoryEditFieldValueChanged(app, event)
            app.results_directory = GetFullPath( ...
                app.GCPResultsDirectoryEditField.Value);
        end

        % Button pushed function: GCPResultsDirectorySearchButton
        function GCPResultsDirectorySearchButtonPushed(app, event)
            folder = uigetdir("Select a folder to store your results");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.results_directory = folder;
        end

        % Callback function: not associated with a component
        function CoordinateListEditButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_coordinates, ...
                app.coordinate_list)
            app.objectSelectionType = 'coordinate';
        end

        % Callback function: not associated with a component
        function TrialPrefixesEditFieldValueChanged(app, event)
            value = app.TrialPrefixesEditField.Value;
            app.trial_prefixes = value;
        end

        % Callback function: not associated with a component
        function EditActivationGroupsButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_groups, ...
                app.activation_muscle_groups)
            app.objectSelectionType = 'activationGroup';
        end

        % Callback function: not associated with a component
        function EditFiberLengthGroupsButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_groups, ...
                app.normalized_fiber_length_muscle_groups)
            app.objectSelectionType = 'fiberLengthGroup';
        end

        % Image clicked function: RcnlLogo
        function RcnlLogoImageClicked(app, event)
            web("https://nmsm.rice.edu/")
        end

        % Callback function: not associated with a component
        function NCPCostTermsTableCellEdit(app, event)
            indices = event.Indices;
            isEnabled = app.NCPCostTermsTable.Data.isEnabled(indices(1));
            if isEnabled
                app.RCNLCostTerm{indices(1)}. ...
                    is_enabled = 'true';
            else
                app.RCNLCostTerm{indices(1)}. ...
                    is_enabled = 'false';
            end
        end

        % Callback function: not associated with a component
        function NCPCostTermsTableSelectionChanged(app, event)
            costTermIndex = app.NCPCostTermsTable.Selection;
            app.NCPMaxAllowableErrorEditField.Value = ...
                app.RCNLCostTerm{costTermIndex}. ...
                max_allowable_error;
        end

        % Callback function: not associated with a component
        function EnableMTLICheckBoxValueChanged(app, event)
            if app.EnableMTLICheckBox.Value
                app.MuscleTendonLengthInitialization.is_enabled = "true";
            else
                app.MuscleTendonLengthInitialization.is_enabled = "false";
            end
        end

        % Callback function: not associated with a component
        function PassiveDataDirectoryEditFieldValueChanged(app, event)
            app.MuscleTendonLengthInitialization.passive_data_input_directory = ...
                GetFullPath(app.PassiveDataDirectoryEditField.Value);
        end

        % Callback function: not associated with a component
        function PassiveDataDirectorySearchButtonPushed(app, event)
            folder = uigetdir("Select your passive data folder");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.MuscleTendonLengthInitialization.passive_data_input_directory = folder;
        end

        % Callback function: not associated with a component
        function MinNormalizedFiberLengthEditFieldValueChanged(app, event)
            value = app.MinNormalizedFiberLengthEditField.Value;
            app.MuscleTendonLengthInitialization.min_normalized_muscle_fiber_length = value;
        end

        % Callback function: not associated with a component
        function MaxNormalizedFiberLengthEditFieldValueChanged(app, event)
            value = app.MaxNormalizedFiberLengthEditField.Value;
            app.MuscleTendonLengthInitialization.max_normalized_muscle_fiber_length = value;
        end

        % Cell edit callback: AdvancedSettingsTable
        function AdvancedSettingsTableCellEdit(app, event)
            index = event.Indices(1);

            if isnan(double(convertCharsToStrings(app.AdvancedSettingsTable.Data.paramValues(index))))
                app.AdvancedSettingsTable.Data.paramValues(index) = "NaN";
            else
                app.advancedSettingValues(index) = app.AdvancedSettingsTable.Data.paramValues(index);
            end
        end

        % Callback function: not associated with a component
        function NCPMaxAllowableErrorEditFieldValueChanged(app, event)
            costTermIndex = app.NCPCostTermsTable.Selection;
            app.RCNLCostTerm{costTermIndex}.max_allowable_error = ...
                app.NCPMaxAllowableErrorEditField.Value;
        end

        % Callback function: not associated with a component
        function AddSynergyGroupButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_groups, ...
                app.synergyGroups)
            app.objectSelectionType = 'synergyGroup';
        end

        % Callback function: not associated with a component
        function SynergyGroupsTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.RCNLSynergy{indices(1)}.num_synergies = newData;
            
        end

        % Callback function: not associated with a component
        function MtliCostTermsTableSelectionChanged(app, event)
            if isempty(app.MtliCostTermsTable.Selection)
                return
            end
            costTermIndex = app.MtliCostTermsTable.Selection;
            app.MtliErrorCenterEditField.Value = ...
                app.MuscleTendonLengthInitialization.RCNLCostTerm{costTermIndex}. ...
                error_center;
            app.MtliMaxAllowableErrorEditField.Value = ...
                app.MuscleTendonLengthInitialization.RCNLCostTerm{costTermIndex}. ...
                max_allowable_error;
        end

        % Callback function: not associated with a component
        function MtliMaxAllowableErrorEditFieldValueChanged(app, event)
            app.MuscleTendonLengthInitialization.RCNLCostTerm{ ...
                app.MtliCostTermsTable.Selection}.max_allowable_error = ...
                app.MtliMaxAllowableErrorEditField.Value;
        end

        % Callback function: not associated with a component
        function MtliErrorCenterEditFieldValueChanged(app, event)
            app.MuscleTendonLengthInitialization.RCNLCostTerm{ ...
                app.MtliCostTermsTable.Selection}.error_center = ...
                app.MtliErrorCenterEditField.Value;
        end

        % Callback function: not associated with a component
        function MtliCostTermsTableCellEdit(app, event)
            indices = event.Indices;
            isEnabled = app.MtliCostTermsTable.Data.isEnabled(indices(1));
            if isEnabled
                app.MuscleTendonLengthInitialization.RCNLCostTerm{indices(1)}. ...
                    is_enabled = "true";
            else
                app.MuscleTendonLengthInitialization.RCNLCostTerm{indices(1)}. ...
                    is_enabled = "false";
            end
        end

        % Callback function: not associated with a component
        function MtliAdvancedSettingsTableCellEdit(app, event)
            indices = event.Indices;
            app.MuscleTendonLengthInitialization.setParameterValueByIndex(...
                indices(1), app.MtliAdvancedSettingsTable.Data(indices(1), indices(2)).value);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GroundContactModelPersonalizationToolLabel = uilabel(app.UIFigure);
            app.GroundContactModelPersonalizationToolLabel.Text = 'Ground Contact Model Personalization Tool';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputModelFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputModelFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputModelFileSearchButtonPushed, true);
            app.InputModelFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputModelFileSearchButton.Text = '';
            app.InputModelFileEditFieldLabel = uilabel(app.InputsTab);
            app.InputModelFileEditFieldLabel.Text = 'Input Model File';
            app.InputModelFileEditField = uieditfield(app.InputsTab, 'text');
            app.InputModelFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputModelFileEditFieldValueChanged, true);
            app.InputModelFileStatus = uiimage(app.InputsTab);
            app.InputModelFileStatus.Visible = 'off';
            app.InputModelFileStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputOsimxFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputOsimxFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputOsimxFileSearchButtonPushed, true);
            app.InputOsimxFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputOsimxFileSearchButton.Text = '';
            app.InputOsimxFileEditFieldLabel = uilabel(app.InputsTab);
            app.InputOsimxFileEditFieldLabel.Text = 'Input Osimx File';
            app.InputOsimxFileEditField = uieditfield(app.InputsTab, 'text');
            app.InputOsimxFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputOsimxFileEditFieldValueChanged, true);
            app.InputOsimxFileStatus = uiimage(app.InputsTab);
            app.InputOsimxFileStatus.Visible = 'off';
            app.InputOsimxFileStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputMotionFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputMotionFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputMotionFileSearchButtonPushed, true);
            app.InputMotionFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputMotionFileSearchButton.Text = '';
            app.InputMotionFileEditField = uieditfield(app.InputsTab, 'text');
            app.InputMotionFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputMotionFileEditFieldValueChanged, true);
            app.InputMotionFileStatus = uiimage(app.InputsTab);
            app.InputMotionFileStatus.Visible = 'off';
            app.InputMotionFileStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.GCPResultsDirectorySearchButton = uibutton(app.InputsTab, 'push');
            app.GCPResultsDirectorySearchButton.ButtonPushedFcn = createCallbackFcn(app, @GCPResultsDirectorySearchButtonPushed, true);
            app.GCPResultsDirectorySearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.GCPResultsDirectorySearchButton.Text = '';
            app.GCPResultsDirectoryEditFieldLabel = uilabel(app.InputsTab);
            app.GCPResultsDirectoryEditFieldLabel.Text = 'GCP Results Directory';
            app.GCPResultsDirectoryEditField = uieditfield(app.InputsTab, 'text');
            app.GCPResultsDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @GCPResultsDirectoryEditFieldValueChanged, true);
            app.GCPResultsDirectoryStatus = uiimage(app.InputsTab);
            app.GCPResultsDirectoryStatus.Visible = 'off';
            app.GCPResultsDirectoryStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputMotionFileLabel = uilabel(app.InputsTab);
            app.InputMotionFileLabel.Text = 'Input Motion File';
            app.InputForceFileStatus = uiimage(app.InputsTab);
            app.InputForceFileStatus.Visible = 'off';
            app.InputForceFileStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputForceFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputForceFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputForceFileSearchButtonPushed, true);
            app.InputForceFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputForceFileSearchButton.Text = '';
            app.InputForceFile = uieditfield(app.InputsTab, 'text');
            app.InputForceFile.ValueChangedFcn = createCallbackFcn(app, @InputForceFileValueChanged, true);
            app.MTPResultsDirectoryLabel = uilabel(app.InputsTab);
            app.MTPResultsDirectoryLabel.Text = 'Input Force File';
            app.ContactSurfacesTab = uitab(app.TabGroup);
            app.ContactSurfacesLabel = uilabel(app.ContactSurfacesTab);
            app.ContactSurfacesLabel.Text = {'Contact'; 'Surfaces'};
            app.ContactSurfacesTable = uitable(app.ContactSurfacesTab);
            app.ContactSurfacesTable.ColumnName = {''; 'Contact Surface'};
            app.ContactSurfacesTable.RowName = {};
            app.ContactSurfacesTable.ColumnSortable = [false true];
            app.ContactSurfacesTable.SelectionType = 'row';
            app.ContactSurfacesTable.ColumnEditable = true;
            app.ContactSurfacesTable.Multiselect = 'off';
            app.ContactSurfacesStatus = uiimage(app.ContactSurfacesTab);
            app.ContactSurfacesStatus.Visible = 'off';
            app.ContactSurfacesStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.IsLeftFootCheckBox = uicheckbox(app.ContactSurfacesTab);
            app.IsLeftFootCheckBox.Text = 'Is Left Foot';
            app.StartTimeEditFieldLabel = uilabel(app.ContactSurfacesTab);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.ContactSurfacesTab, 'numeric');
            app.EndTimeEditFieldLabel = uilabel(app.ContactSurfacesTab);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.EndTimeEditField = uieditfield(app.ContactSurfacesTab, 'numeric');
            app.BeltSpeedEditFieldLabel = uilabel(app.ContactSurfacesTab);
            app.BeltSpeedEditFieldLabel.Text = 'Belt Speed';
            app.BeltSpeedEditField = uieditfield(app.ContactSurfacesTab, 'numeric');
            app.ForceColumnsLabel = uilabel(app.ContactSurfacesTab);
            app.ForceColumnsLabel.Text = {'Force'; 'Columns'};
            app.ForceColumnsDropDown = uidropdown(app.ContactSurfacesTab);
            app.ForceColumnsDropDown.Items = {'ground_force_1_vx'};
            app.ForceColumnsDropDown.Value = 'ground_force_1_vx';
            app.MomentColumnsLabel = uilabel(app.ContactSurfacesTab);
            app.MomentColumnsLabel.Text = {'Moment'; 'Columns'};
            app.MomentColumnsDropDown = uidropdown(app.ContactSurfacesTab);
            app.ElectricalCenterColumnsDropDownLabel = uilabel(app.ContactSurfacesTab);
            app.ElectricalCenterColumnsDropDownLabel.Text = {'Electrical'; 'Center'; 'Columns'};
            app.ElectricalCenterColumnsDropDown = uidropdown(app.ContactSurfacesTab);
            app.ElectricalCenterColumnsDropDown_2 = uidropdown(app.ContactSurfacesTab);
            app.ElectricalCenterColumnsDropDown_3 = uidropdown(app.ContactSurfacesTab);
            app.MomentColumnsDropDown_2 = uidropdown(app.ContactSurfacesTab);
            app.MomentColumnsDropDown_3 = uidropdown(app.ContactSurfacesTab);
            app.ForceColumnsDropDown_2 = uidropdown(app.ContactSurfacesTab);
            app.ForceColumnsDropDown_2.Items = {'ground_force_1_vx'};
            app.ForceColumnsDropDown_2.Value = 'ground_force_1_vx';
            app.ForceColumnsDropDown_3 = uidropdown(app.ContactSurfacesTab);
            app.ForceColumnsDropDown_3.Items = {'ground_force_1_vx'};
            app.ForceColumnsDropDown_3.Value = 'ground_force_1_vx';
            app.HindfootBodyNameLabel = uilabel(app.ContactSurfacesTab);
            app.HindfootBodyNameLabel.Text = {'Hindfoot'; 'Body'};
            app.HindfootBodyDropDown = uidropdown(app.ContactSurfacesTab);
            app.ToeMarkerDropDownLabel = uilabel(app.ContactSurfacesTab);
            app.ToeMarkerDropDownLabel.Text = 'Toe Marker';
            app.ToeMarkerDropDown = uidropdown(app.ContactSurfacesTab);
            app.MedialMarkerLabel = uilabel(app.ContactSurfacesTab);
            app.MedialMarkerLabel.Text = 'Medial Marker';
            app.MedialMarkerDropDown = uidropdown(app.ContactSurfacesTab);
            app.LateralMarkerDropDownLabel = uilabel(app.ContactSurfacesTab);
            app.LateralMarkerDropDownLabel.Text = 'Lateral Marker';
            app.LateralMarkerDropDown = uidropdown(app.ContactSurfacesTab);
            app.HeelMarkerDropDownLabel = uilabel(app.ContactSurfacesTab);
            app.HeelMarkerDropDownLabel.Text = 'Heel Marker';
            app.HeelMarkerDropDown = uidropdown(app.ContactSurfacesTab);
            app.MidfootSuperiorMarkerLabel = uilabel(app.ContactSurfacesTab);
            app.MidfootSuperiorMarkerLabel.Text = {'Midfoot Superior'; 'Marker'};
            app.MidfootSuperiorMarkerDropDown = uidropdown(app.ContactSurfacesTab);
            app.GridWidthEditFieldLabel = uilabel(app.ContactSurfacesTab);
            app.GridWidthEditFieldLabel.Text = 'Grid Width';
            app.GridWidthEditField = uieditfield(app.ContactSurfacesTab, 'numeric');
            app.GridWidthEditField.Limits = [1 Inf];
            app.GridWidthEditField.Value = 5;
            app.GridHeightEditFieldLabel = uilabel(app.ContactSurfacesTab);
            app.GridHeightEditFieldLabel.Text = 'Grid Height';
            app.GridHeightEditField = uieditfield(app.ContactSurfacesTab, 'numeric');
            app.GridHeightEditField.Limits = [1 Inf];
            app.GridHeightEditField.Value = 15;
            app.TimeRangeStatus = uiimage(app.ContactSurfacesTab);
            app.TimeRangeStatus.Visible = 'off';
            app.TimeRangeStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.ForceColumnsStatus = uiimage(app.ContactSurfacesTab);
            app.ForceColumnsStatus.Visible = 'off';
            app.ForceColumnsStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.MomentColumnsStatus = uiimage(app.ContactSurfacesTab);
            app.MomentColumnsStatus.Visible = 'off';
            app.MomentColumnsStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.ElectricalCenterColumnsStatus = uiimage(app.ContactSurfacesTab);
            app.ElectricalCenterColumnsStatus.Visible = 'off';
            app.ElectricalCenterColumnsStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.HindfootBodyStatus = uiimage(app.ContactSurfacesTab);
            app.HindfootBodyStatus.Visible = 'off';
            app.HindfootBodyStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.ToeMarkerStatus = uiimage(app.ContactSurfacesTab);
            app.ToeMarkerStatus.Visible = 'off';
            app.ToeMarkerStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.MedialMarkerStatus = uiimage(app.ContactSurfacesTab);
            app.MedialMarkerStatus.Visible = 'off';
            app.MedialMarkerStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.HeelMarkerStatus = uiimage(app.ContactSurfacesTab);
            app.HeelMarkerStatus.Visible = 'off';
            app.HeelMarkerStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.LateralMarkerStatus = uiimage(app.ContactSurfacesTab);
            app.LateralMarkerStatus.Visible = 'off';
            app.LateralMarkerStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.MidfootSuperiorMarkerStatus = uiimage(app.ContactSurfacesTab);
            app.MidfootSuperiorMarkerStatus.Visible = 'off';
            app.MidfootSuperiorMarkerStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.GCPTasksTab = uitab(app.TabGroup);
            app.CostTermPanel = uipanel(app.GCPTasksTab);
            app.MaxAllowableErrorEditField_2Label = uilabel(app.CostTermPanel);
            app.MaxAllowableErrorEditField_2Label.Text = 'Max Allowable Error';
            app.MaxAllowableErrorEditField = uieditfield(app.CostTermPanel, 'numeric');
            app.MaxAllowableErrorEditField.Limits = [1e-08 Inf];
            app.MaxAllowableErrorEditField.AllowEmpty = 'on';
            app.MaxAllowableErrorEditField.Value = [];
            app.ErrorCenterEditField_2Label = uilabel(app.CostTermPanel);
            app.ErrorCenterEditField_2Label.Text = 'Error Center';
            app.ErrorCenterEditField = uieditfield(app.CostTermPanel, 'numeric');
            app.ErrorCenterEditField.AllowEmpty = 'on';
            app.ErrorCenterEditField.Value = [];
            app.CostTermsTable = uitable(app.CostTermPanel);
            app.CostTermsTable.ColumnName = {''; 'Cost Term'};
            app.CostTermsTable.RowName = {};
            app.CostTermsTable.SelectionType = 'row';
            app.CostTermsTable.ColumnEditable = [true false];
            app.CostTermsTable.Multiselect = 'off';
            app.EditCostTermsLabel = uilabel(app.GCPTasksTab);
            app.EditCostTermsLabel.Text = 'Edit Cost Terms';
            app.EditDesignVariablesLabel = uilabel(app.GCPTasksTab);
            app.EditDesignVariablesLabel.Text = 'Edit Design Variables';
            app.DesignVariablesTable = uitable(app.GCPTasksTab);
            app.DesignVariablesTable.ColumnName = {''; 'Design Variable'};
            app.DesignVariablesTable.RowName = {};
            app.DesignVariablesTable.SelectionType = 'row';
            app.DesignVariablesTable.ColumnEditable = [true false];
            app.DesignVariablesTable.Multiselect = 'off';
            app.TasksLabel = uilabel(app.GCPTasksTab);
            app.TasksLabel.Text = 'Tasks';
            app.TasksTable = uitable(app.GCPTasksTab);
            app.TasksTable.ColumnName = {''; 'Task'};
            app.TasksTable.RowName = {};
            app.TasksTable.ColumnSortable = [false true];
            app.TasksTable.SelectionType = 'row';
            app.TasksTable.ColumnEditable = true;
            app.TasksTable.Multiselect = 'off';
            app.MoveTaskUpButton = uibutton(app.GCPTasksTab, 'push');
            app.MoveTaskUpButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'ArrowUp.svg');
            app.MoveTaskUpButton.Text = '';
            app.MoveTaskDownButton = uibutton(app.GCPTasksTab, 'push');
            app.MoveTaskDownButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'ArrowDown.svg');
            app.MoveTaskDownButton.Text = '';
            app.TasksStatus = uiimage(app.GCPTasksTab);
            app.TasksStatus.Visible = 'off';
            app.TasksStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.CostTermsStatus = uiimage(app.GCPTasksTab);
            app.CostTermsStatus.Visible = 'off';
            app.CostTermsStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.DesignVariablesStatus = uiimage(app.GCPTasksTab);
            app.DesignVariablesStatus.Visible = 'off';
            app.DesignVariablesStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.NeighborStandardDeviationEditFieldLabel = uilabel(app.GCPTasksTab);
            app.NeighborStandardDeviationEditFieldLabel.Text = 'Neighbor Standard Deviation';
            app.NeighborStandardDeviationEditField = uieditfield(app.GCPTasksTab, 'numeric');
            app.NeighborStandardDeviationEditField.Limits = [0 Inf];
            app.NeighborStandardDeviationEditField.Value = 0.2;
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedSettingsTable = uitable(app.AdvancedTab);
            app.AdvancedSettingsTable.ColumnName = {'Option'; 'Value'};
            app.AdvancedSettingsTable.RowName = {};
            app.AdvancedSettingsTable.SelectionType = 'row';
            app.AdvancedSettingsTable.ColumnEditable = [false true];
            app.AdvancedSettingsTable.CellEditCallback = createCallbackFcn(app, @AdvancedSettingsTableCellEdit, true);
            app.AdvancedSettingsStatus = uiimage(app.AdvancedTab);
            app.AdvancedSettingsStatus.Visible = 'off';
            app.AdvancedSettingsStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.Mask1 = uiimage(app.UIFigure);
            app.Mask1.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'greyMask.png');
            app.MTPImage = uiimage(app.UIFigure);
            app.MTPImage.ImageSource = 'gcpFigure.png';
            app.AdvancedButton = uibutton(app.UIFigure, 'push');
            app.AdvancedButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedButtonPushed, true);
            app.AdvancedButton.Text = 'Advanced';
            app.GCPTasksButton = uibutton(app.UIFigure, 'push');
            app.GCPTasksButton.ButtonPushedFcn = createCallbackFcn(app, @GCPTasksButtonPushed, true);
            app.GCPTasksButton.Text = 'GCP Tasks';
            app.ContactSurfacesButton = uibutton(app.UIFigure, 'push');
            app.ContactSurfacesButton.ButtonPushedFcn = createCallbackFcn(app, @ContactSurfacesButtonPushed, true);
            app.ContactSurfacesButton.Text = 'Contact Surfaces';
            app.InputsButton = uibutton(app.UIFigure, 'push');
            app.InputsButton.ButtonPushedFcn = createCallbackFcn(app, @InputsButtonPushed, true);
            app.InputsButton.Text = 'GCP Inputs';
            app.RcnlLogo = uiimage(app.UIFigure);
            app.RcnlLogo.ImageClickedFcn = createCallbackFcn(app, @RcnlLogoImageClicked, true);
            app.RcnlLogo.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'rcnlIcon.png');
            app.LoadSettingsFileButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsFileButtonPushed, true);
            app.LoadSettingsFileButton.Text = 'Load';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GCPBaseVisual

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end