classdef JMPBase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MoveTaskDownButton             matlab.ui.control.Button
        MoveTaskUpButton               matlab.ui.control.Button
        InputModelFileError            matlab.ui.control.Image
        RunButton                      matlab.ui.control.Button
        JMPTasksListBox                matlab.ui.control.ListBox
        JMPTasksListBoxLabel           matlab.ui.control.Label
        RcnlLogo                       matlab.ui.control.Image
        JointModelPersonalizationToolLabel  matlab.ui.control.Label
        HelpButton                     matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        InputModelFileEditField        matlab.ui.control.EditField
        InputModelFileSearchButton     matlab.ui.control.Button
        OutputModelFileEditField       matlab.ui.control.EditField
        OutputModelFileSearchButton    matlab.ui.control.Button
        LoadSettingsFileButton         matlab.ui.control.Button
        DeleteJMPTaskButton            matlab.ui.control.Button
        EditJMPTaskButton              matlab.ui.control.Button
        AddJMPTaskButton               matlab.ui.control.Button
        OutputModelFileEditFieldLabel  matlab.ui.control.Label
        InputModelFileEditFieldLabel   matlab.ui.control.Label
        JMPImage                       matlab.ui.control.Image
    end


    properties (Access = private, SetObservable)
        OutputModelFileAuto logical = true;
        allowable_error double = 0.005;
        TaskList cell;
        TaskNames string = [];
        input_model_file string = "";
        output_model_file string = "";
        model_markers string;
        model_bodies string;
        model_joints string;
        ErrorFlag logical = false;
        currentSettingsFile string; % Variable to store the current settings file name.
        needToSave logical = false; % flag indicating if the settings file changed and needs to be saved before running.
        modelErrorFlag logical = false;
    end

    properties (Access = public)
        accuracy double = 1e-6;
        diff_min_change double = 0.001;
        optimality_tolerance double = 1e-5;
        function_tolerance double = 1e-5;
        step_tolerance double = 1e-5;
        max_function_evaluations double = 200;
    end

    methods (Access = private)

        function ErrorsCallback(app)
            app.ErrorFlag = false;

            % Check for osim model errors
            [app.modelErrorFlag, modelErrorMessage] = parseModelFileGui( ...
                app, app.input_model_file);
            if app.modelErrorFlag
                throwGuiError(modelErrorMessage, ...
                    app.InputModelFileEditField, app.InputModelFileError)
                app.ErrorFlag = true;
            else
                clearGuiError(app.InputModelFileEditField, app.InputModelFileError)
            end
            app.EnableActionsCallback();
        end

        function EnableActionsCallback(app)
            % Enable or disable fields
            if strcmp(app.input_model_file, "") || app.modelErrorFlag
                app.AddJMPTaskButton.Enable = 'off';
                app.AddJMPTaskButton.Tooltip = "You must select a valid model file before adding a new task";
            else
                app.AddJMPTaskButton.Enable = 'on';
                app.AddJMPTaskButton.Tooltip = "";
            end

            if isempty(app.TaskList)
                app.EditJMPTaskButton.Enable = 'off';
                app.DeleteJMPTaskButton.Enable = 'off';
            else
                app.EditJMPTaskButton.Enable = 'on';
                app.DeleteJMPTaskButton.Enable = 'on';
            end

            if length(app.TaskList) > 1
                app.MoveTaskUpButton.Enable = 'on';
                app.MoveTaskDownButton.Enable = 'on';
            else
                app.MoveTaskUpButton.Enable = 'off';
                app.MoveTaskDownButton.Enable = 'off';
            end

            if ~app.ErrorFlag && ~strcmp(app.input_model_file, "") && ...
                    ~isempty(app.TaskList)
                app.RunButton.Enable = 'on';
            else
                app.RunButton.Enable = 'off';
            end
        end

        function LoadJMPSettingsFile(app, settingsFileName)
            app.currentSettingsFile = settingsFileName;
            settingsTree = xml2struct(settingsFileName);
            settingsTree = settingsTree.NMSMPipelineDocument. ...
                JointModelPersonalizationTool;
            settingsTree = formatXmlDataForGui(settingsTree);
            app.allowable_error = settingsTree.allowable_error;

            app.input_model_file = settingsTree.input_model_file;
            app.InputModelFileEditField.Value = app.input_model_file;

            app.output_model_file = settingsTree.output_model_file;
            app.OutputModelFileEditField.Value = app.output_model_file;

            if length(settingsTree.JMPTaskList.JMPTask) > 1
                app.TaskList = settingsTree.JMPTaskList.JMPTask;
            else
                app.TaskList = {settingsTree.JMPTaskList.JMPTask};
            end

            for i = 1 : length(settingsTree.JMPTaskList.JMPTask)
                if isfield(app.TaskList{i}, "JMPJointSet")
                    if length(app.TaskList{i}.JMPJointSet.JMPJoint) > 1
                        app.TaskList{i}.JointSet = app.TaskList{i}.JMPJointSet.JMPJoint;
                    else
                        app.TaskList{i}.JointSet = {app.TaskList{i}.JMPJointSet.JMPJoint};
                    end
                else
                    app.TaskList{i}.JointSet = {};
                end
                if isfield(app.TaskList{i}, "JMPBodySet")
                    if length(app.TaskList{i}.JMPBodySet.JMPBody) > 1
                        app.TaskList{i}.BodySet = app.TaskList{i}.JMPBodySet.JMPBody;
                    else
                        app.TaskList{i}.BodySet = {app.TaskList{i}.JMPBodySet.JMPBody};
                    end
                else
                    app.TaskList{i}.BodySet = {};
                end

            end
            app.ErrorsCallback()
            app.needToSave = false;
        end

        function SaveJMPSettingsFile(app, fileName)
            settingsTree = makeJMPSettingsStruct(app);
            settingsFileStruct.NMSMPipelineDocument.JointModelPersonalizationTool = ...
                settingsTree;
            settingsFileStruct.NMSMPipelineDocument.Attributes.Version = ...
                convertStringsToChars(getPipelineVersion());
            struct2xml(settingsFileStruct, fileName)
            app.currentSettingsFile = fullfile(fileName);
            app.needToSave = false;
        end
        
        function settingsTree = makeJMPSettingsStruct(app)
            settingsTree.input_model_file = getRelativePath(app.getInputModelFile(), pwd);
            settingsTree.output_model_file = getRelativePath(app.getOutputModelFile(), pwd);
            settingsTree.allowable_error = app.getMaxAllowableError();
            settingsTree.JMPTaskList = makeJMPTaskListStruct(app);
            settingsTree = setOptimizationParams(app, settingsTree);
            settingsTree = formatGuiDataForXml(settingsTree);
        end
        
        function JMPTaskList = makeJMPTaskListStruct(app)
            taskList = app.getTaskList;
            JMPTaskList = struct();  % Sensitive variable name
            for i = 1 : length(taskList)
                task = taskList{i};  
                task.marker_file_name = getRelativePath(task.marker_file_name, pwd);
                if ~isempty(task.JointSet)
                    task.JMPJointSet = struct();  % Sensitive variable name
                    task.JMPJointSet.JMPJoint = task.JointSet;  % Sensitive variable name
                    task = rmfield(task, "JointSet");
                end
                if ~isempty(task.BodySet)
                    task.JMPBodySet = struct();  % Sensitive variable name
                    task.JMPBodySet.JMPBody = task.BodySet;  % Sensitive variable name
                    task = rmfield(task, "BodySet");
                end
                
                JMPTaskList.JMPTask{i} = task;  % Sensitive variable name
            end
        end
        
        function settingsTree = setOptimizationParams(app, settingsTree)
            settingsTree.accuracy = app.accuracy;
            settingsTree.diff_min_change = app.diff_min_change;
            settingsTree.optimality_tolerance = app.optimality_tolerance;
            settingsTree.function_tolerance = app.function_tolerance;
            settingsTree.step_tolerance = app.step_tolerance;
            settingsTree.max_function_evaluations = app.max_function_evaluations;
        end

        function moveTaskUp(app)
            selectedTaskIndex = app.JMPTasksListBox.ValueIndex;
            if selectedTaskIndex == 1
                return
            end
            indicesList = 1:1:length(app.TaskList);
            indicesList(selectedTaskIndex) = selectedTaskIndex-1;
            indicesList(selectedTaskIndex-1) = selectedTaskIndex;
            app.TaskList{selectedTaskIndex}.index = selectedTaskIndex-1;
            app.TaskList{selectedTaskIndex-1}.index = selectedTaskIndex;
            app.TaskList = {app.TaskList{indicesList}};
        end

        function moveTaskDown(app)
            selectedTaskIndex = app.JMPTasksListBox.ValueIndex;
            if selectedTaskIndex == length(app.TaskList)
                return
            end
            indicesList = 1:1:length(app.TaskList);
            indicesList(selectedTaskIndex) = selectedTaskIndex+1;
            indicesList(selectedTaskIndex+1) = selectedTaskIndex;
            app.TaskList{selectedTaskIndex}.index = selectedTaskIndex+1;
            app.TaskList{selectedTaskIndex+1}.index = selectedTaskIndex;
            app.TaskList = {app.TaskList{indicesList}};
        end
    end

    methods (Access = public)

        function modelFile = getInputModelFile(app)
            modelFile = app.input_model_file;
        end

        function modelFile = getOutputModelFile(app)
            modelFile = app.output_model_file;
        end

        function maxAllowableError = getMaxAllowableError(app)
            maxAllowableError = app.allowable_error;
        end

        function task = getTask(app, index)
            task = app.TaskList{index};
        end

        function taskList = getTaskList(app)
            taskList = app.TaskList;
        end

        function addTask(app, task)
            app.TaskList{end+1} = task;
        end

        function editTask(app, task)
            selectedTaskIndex = app.JMPTasksListBox.ValueIndex;
            app.TaskList{selectedTaskIndex} = task;
        end

        function deleteTask(app, taskIndex)
            app.TaskList = app.TaskList(~taskIndex);
            app.EnableActionsCallback
            app.needToSave = true;
        end

        function model_markers = getModelMarkers(app)
            model_markers = app.model_markers;
        end

        function model_bodies = getModelBodies(app)
            model_bodies = app.model_bodies;
        end

        function model_joints = getModelJoints(app)
            model_joints = app.model_joints;
        end

        function SetModelMarkers(app, markers)
            app.model_markers = markers;
        end

        function SetModelJoints(app, joints)
            app.model_joints = joints;
        end

        function SetModelBodies(app, bodies)
            app.model_bodies = bodies;
        end
    end

    properties(Access = private)  % listner properties
        InputModelFileListener
        OutputModelFileListener
        JMPTasksListListener
    end

    methods (Access = private) % listener methods
        function makeListeners(app)
            app.InputModelFileListener = addlistener(app, ...
                'input_model_file', 'PostSet', ...
                @(src,event)updateInputModelFileField(app));

            app.OutputModelFileListener = addlistener(app, ...
                'output_model_file', 'PostSet', ...
                @(src,event)updateOutputModelFileField(app));

            app.JMPTasksListListener = addlistener(app, ...
                'TaskList', 'PostSet', ...
                @(src,event)updateJMPTasksListBox(app));
        end

        function updateInputModelFileField(app)
            app.InputModelFileEditField.Value = getRelativePath( ...
                app.input_model_file);
            app.ErrorsCallback()
            app.needToSave = true;
        end

        function updateOutputModelFileField(app)
            app.OutputModelFileEditField.Value = getRelativePath( ...
                app.output_model_file);
            app.needToSave = true;
        end

        function updateJMPTasksListBox(app)
            app.TaskNames = [];
            for i = 1 : length(app.TaskList)
                app.TaskNames(i) = app.TaskList{i}.Attributes.name;
            end
            app.JMPTasksListBox.Items = {};
            app.JMPTasksListBox.Items = app.TaskNames;
            app.ErrorsCallback()
            app.needToSave = true;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.makeListeners()
            % app.JMPTasksListBox.
        end

        % Button pushed function: InputModelFileSearchButton
        function InputModelFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osim', "Select an OpenSim Model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end

            app.input_model_file = fullfile(path, file);
            if strcmp(app.output_model_file, "")
                app.output_model_file = fullfile(path, ...
                    strrep(file, ".osim", "_JMP.osim"));
            end
        end

        % Value changed function: InputModelFileEditField
        function InputModelFileEditFieldValueChanged(app, event)
            app.input_model_file = getAbsolutePath( ...
                app.InputModelFileEditField.Value);
        end

        % Value changed function: OutputModelFileEditField
        function OutputModelFileEditFieldValueChanged(app, event)
            app.output_model_file = getAbsolutePath( ...
                app.OutputModelFileEditField.Value);
        end

        % Button pushed function: OutputModelFileSearchButton
        function OutputModelFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osim', "Select an OpenSim Model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.output_model_file = fullfile(path, file);
        end

        % Button pushed function: AddJMPTaskButton
        function AddJMPTaskButtonPushed(app, event)
            childApp = JMPTaskCreation(app, []);
            childApp.UIFigure.HandleVisibility = 'off';
        end

        % Button pushed function: EditJMPTaskButton
        function EditJMPTaskButtonPushed(app, event)
            selectedTaskName = app.JMPTasksListBox.Value;
            if isempty(selectedTaskName)
                return
            end
            taskToEdit = app.TaskList{strcmp(selectedTaskName, app.TaskNames)};
            JMPTaskCreation(app, taskToEdit);
        end

        % Button pushed function: DeleteJMPTaskButton
        function DeleteJMPTaskButtonPushed(app, event)
            selectedTaskIndex = app.JMPTasksListBox.ValueIndex;
            indicesVector = 1:1:numel(app.JMPTasksListBox.Items) == selectedTaskIndex;
            app.deleteTask(indicesVector);
            app.EnableActionsCallback
        end

        % Button pushed function: MoveTaskUpButton
        function MoveTaskUpButtonPushed(app, event)
            app.moveTaskUp()
        end

        % Button pushed function: MoveTaskDownButton
        function MoveTaskDownButtonPushed(app, event)
            app.moveTaskDown()
        end

        % Button pushed function: LoadSettingsFileButton
        function LoadSettingsFileButtonPushed(app, event)
            [file, path] = uigetfile('*.xml', "Load XML Settings File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.LoadJMPSettingsFile(fullfile(path, file));
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file, path] = uiputfile('*.xml', "Save XML Settings File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.SaveJMPSettingsFile(fullfile(path, file));
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if app.needToSave
                [file, path] = uiputfile('*.xml', "Save XML Settings File");
                % User hit "Cancel"
                if isequal(file, 0)
                    return
                end
                app.SaveJMPSettingsFile(fullfile(path, file));
                app.currentSettingsFile = fullfile(path, file);
                app.needToSave = false;
            end
            JMPRun(app, app.currentSettingsFile);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web("https://nmsm.rice.edu/guides-and-publications/tool-overviews/model-personalization/joint-model-personalization/")
        end

        % Image clicked function: RcnlLogo
        function RcnlLogoClicked(app, event)
            web("https://nmsm.rice.edu/")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, '..', 'Images', 'rcnlIcon.png');
            app.JMPImage = uiimage(app.UIFigure);
            app.JMPImage.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'jmpFigure.png');
            app.InputModelFileEditFieldLabel = uilabel(app.UIFigure);
            app.InputModelFileEditFieldLabel.Text = 'Input Model File';
            app.OutputModelFileEditFieldLabel = uilabel(app.UIFigure);
            app.OutputModelFileEditFieldLabel.Text = 'Output Model File';
            app.AddJMPTaskButton = uibutton(app.UIFigure, 'push');
            app.AddJMPTaskButton.ButtonPushedFcn = createCallbackFcn(app, @AddJMPTaskButtonPushed, true);
            app.AddJMPTaskButton.Enable = 'off';
            app.AddJMPTaskButton.Tooltip = {'You must select a valid model file before adding a new task'};
            app.AddJMPTaskButton.Text = 'Add';
            app.EditJMPTaskButton = uibutton(app.UIFigure, 'push');
            app.EditJMPTaskButton.ButtonPushedFcn = createCallbackFcn(app, @EditJMPTaskButtonPushed, true);
            app.EditJMPTaskButton.Enable = 'off';
            app.EditJMPTaskButton.Text = 'Edit';
            app.DeleteJMPTaskButton = uibutton(app.UIFigure, 'push');
            app.DeleteJMPTaskButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteJMPTaskButtonPushed, true);
            app.DeleteJMPTaskButton.Enable = 'off';
            app.DeleteJMPTaskButton.Text = 'Delete';
            app.LoadSettingsFileButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsFileButtonPushed, true);
            app.LoadSettingsFileButton.Text = 'Load';
            app.OutputModelFileSearchButton = uibutton(app.UIFigure, 'push');
            app.OutputModelFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @OutputModelFileSearchButtonPushed, true);
            app.OutputModelFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.OutputModelFileSearchButton.Text = '';
            app.OutputModelFileEditField = uieditfield(app.UIFigure, 'text');
            app.OutputModelFileEditField.ValueChangedFcn = createCallbackFcn(app, @OutputModelFileEditFieldValueChanged, true);
            app.InputModelFileSearchButton = uibutton(app.UIFigure, 'push');
            app.InputModelFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputModelFileSearchButtonPushed, true);
            app.InputModelFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputModelFileSearchButton.Text = '';
            app.InputModelFileEditField = uieditfield(app.UIFigure, 'text');
            app.InputModelFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputModelFileEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.JointModelPersonalizationToolLabel = uilabel(app.UIFigure);
            app.JointModelPersonalizationToolLabel.Text = 'Joint Model Personalization Tool';
            app.RcnlLogo = uiimage(app.UIFigure);
            app.RcnlLogo.ImageClickedFcn = createCallbackFcn(app, @RcnlLogoClicked, true);
            app.RcnlLogo.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'rcnlIcon.png');
            app.JMPTasksListBoxLabel = uilabel(app.UIFigure);
            app.JMPTasksListBoxLabel.Text = 'JMP Tasks';
            app.JMPTasksListBox = uilistbox(app.UIFigure);
            app.JMPTasksListBox.Items = {''};
            app.JMPTasksListBox.Value = {};
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.InputModelFileError = uiimage(app.UIFigure);
            app.InputModelFileError.Visible = 'off';
            app.InputModelFileError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.MoveTaskUpButton = uibutton(app.UIFigure, 'push');
            app.MoveTaskUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveTaskUpButtonPushed, true);
            app.MoveTaskUpButton.Enable = 'off';
            app.MoveTaskUpButton.Text = 'Move up';
            app.MoveTaskDownButton = uibutton(app.UIFigure, 'push');
            app.MoveTaskDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveTaskDownButtonPushed, true);
            app.MoveTaskDownButton.Enable = 'off';
            app.MoveTaskDownButton.Text = 'Move Down';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JMPBase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end