classdef JMPBaseNew < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        JMPImage                       matlab.ui.control.Image
        JointModelPersonalizationToolLabel  matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        HelpButton                     matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        LoadSettingsFileButton         matlab.ui.control.Button
        RcnlLogo                       matlab.ui.control.Image
        JMPInputsButton                matlab.ui.control.Button
        AdvancedButton                 matlab.ui.control.Button
        JMPTasksButton                 matlab.ui.control.Button
        Mask1                          matlab.ui.control.Image
        TabGroup                       matlab.ui.container.TabGroup
        InputsTab                      matlab.ui.container.Tab
        InputModelFileEditField        matlab.ui.control.EditField
        InputModelFileEditFieldLabel   matlab.ui.control.Label
        InputModelFileSearchButton     matlab.ui.control.Button
        InputModelFileError            matlab.ui.control.Image
        OutputModelFileEditField       matlab.ui.control.EditField
        OutputModelFileEditFieldLabel  matlab.ui.control.Label
        OutputModelFileSearchButton    matlab.ui.control.Button
        TasksTab                       matlab.ui.container.Tab
        TasksTable                     matlab.ui.control.Table
        MoveTaskDownButton             matlab.ui.control.Button
        MoveTaskUpButton               matlab.ui.control.Button
        TasksPanel                     matlab.ui.container.Panel
        EditButton                     matlab.ui.control.Button
        MarkersTextArea                matlab.ui.control.TextArea
        MarkersTextAreaLabel           matlab.ui.control.Label
        EndTimeField                   matlab.ui.control.NumericEditField
        ToLabel                        matlab.ui.control.Label
        StartTimeField                 matlab.ui.control.NumericEditField
        TimeRangesEditFieldLabel       matlab.ui.control.Label
        MarkersFileEditField           matlab.ui.control.EditField
        MarkersFileEditFieldLabel      matlab.ui.control.Label
        BodiesLabel                    matlab.ui.control.Label
        JointsLabel                    matlab.ui.control.Label
        JointsTable                    matlab.ui.control.Table
        BodiesTable                    matlab.ui.control.Table
        TimeRangeError                 matlab.ui.control.Image
        MarkersFileError               matlab.ui.control.Image
        MarkersFileWarning             matlab.ui.control.Image
        MarkerFileSearchButton         matlab.ui.control.Button
        MuscleGroupsLabel_3            matlab.ui.control.Label
        AdvancedTab                    matlab.ui.container.Tab
        TasksContextMenu               matlab.ui.container.ContextMenu
        DeleteTaskMenu                 matlab.ui.container.Menu
        CopyTaskMenu                   matlab.ui.container.Menu
        CopyTaskMirrorMenu             matlab.ui.container.Menu
        JointsContextMenu              matlab.ui.container.ContextMenu
        EditJointMenu                  matlab.ui.container.Menu
        DeleteJointMenu                matlab.ui.container.Menu
        BodiesContextMenu              matlab.ui.container.ContextMenu
        EditBodyMenu                   matlab.ui.container.Menu
        DeleteBodyMenu                 matlab.ui.container.Menu
    end

    
    properties (Access = private, SetObservable)
        allowable_error double = 0.005;
        JMPTaskList struct;
        JMPTask cell;
        TaskNames string = [];
        currentTask struct = struct();
        currentMarkerFile string;
        selectedTaskIndex double = 1;
        masterTaskCounter double = 0;
        input_model_file string = "";
        output_model_file string = "";
        model_markers string;
        model_bodies string;
        model_joints string;
        ErrorFlag logical = false;

        currentSettingsFile string; % Variable to store the current settings file name.
        needToSave logical = false; % flag indicating if the settings file changed and needs to be saved before running.
        modelErrorFlag logical = false;
    
        % Optimization params
        accuracy double = 1e-6;
        diff_min_change double = 0.001;
        optimality_tolerance double = 1e-5;
        function_tolerance double = 1e-5;
        step_tolerance double = 1e-5;
        max_function_evaluations double = 200;

    end % Description
    
    properties (Access = public)
        
    end
    
    methods (Access = private)
        
        function ErrorsCallback(app)
            app.ErrorFlag = false;

            % Check for osim model errors
            [app.modelErrorFlag, modelErrorMessage] = parseModelFileGui( ...
                app, app.input_model_file);
            if app.modelErrorFlag
                throwGuiError(modelErrorMessage, ...
                    app.InputModelFileEditField, app.InputModelFileError)
                app.ErrorFlag = true;
            else
                clearGuiError(app.InputModelFileEditField, app.InputModelFileError)
            end
            app.EnableActionsCallback();
        end

        function EnableActionsCallback(app)

            % JMP Tasks button
            if app.modelErrorFlag || strcmp(app.input_model_file, "")
                app.JMPTasksButton.Enable = 'off';
            else
                app.JMPTasksButton.Enable = 'on';
            end

            % Tasks panel field
            if ~isempty(app.JMPTask)
                app.TasksPanel.Enable = 'on';
            else
                app.TasksPanel.Enable = 'off';
            end

            % move tasks up and down button

            if length(app.JMPTask) > 1
                app.MoveTaskUpButton.Enable = 'on';
                app.MoveTaskDownButton.Enable = 'on';
            else
                app.MoveTaskUpButton.Enable = 'off';
                app.MoveTaskDownButton.Enable = 'off';
            end

            % Markers file fields

            % Save and run button
        end

        function saveSettingsFile(app, settingsFileName)
            settingsTree = makeJMPSettingsStruct(app);
            settingsFileStruct.NMSMPipelineDocument.JointModelPersonalizationTool = ...
                settingsTree;
            settingsFileStruct.NMSMPipelineDocument.Attributes.Version = ...
                convertStringsToChars(getPipelineVersion());
            struct2xml(settingsFileStruct, settingsFileName)
        end
        function settingsTree = makeJMPSettingsStruct(app)
            settingsTree.input_model_file = getRelativePath(app.input_model_file);
            settingsTree.output_model_file = getRelativePath(app.output_model_file);
            settingsTree.allowable_error = app.allowable_error;
            settingsTree.JMPTaskList = struct();
            settingsTree.JMPTaskList.JMPTask = app.JMPTask;
            for i = 1 : length(app.JMPTask) 
                settingsTree.JMPTaskList.JMPTask{i} = rmfield( ...
                    settingsTree.JMPTaskList.JMPTask{i}, "Auxillary");
                settingsTree.JMPTaskList.JMPTask{i}.marker_file_name = ...
                    getRelativePath(settingsTree.JMPTaskList.JMPTask{i}.marker_file_name);
            end
            settingsTree = setOptimizationParams(app, settingsTree);
            settingsTree = formatGuiDataForXml(settingsTree);
        end
        
        function settingsTree = setOptimizationParams(app, settingsTree)
            settingsTree.accuracy = app.accuracy;
            settingsTree.diff_min_change = app.diff_min_change;
            settingsTree.optimality_tolerance = app.optimality_tolerance;
            settingsTree.function_tolerance = app.function_tolerance;
            settingsTree.step_tolerance = app.step_tolerance;
            settingsTree.max_function_evaluations = app.max_function_evaluations;
        end

        function parseMarkerFileData(app)
            import org.opensim.modeling.TimeSeriesTableVec3
            try
                timeSeriesTable = TimeSeriesTableVec3( ...
                    app.currentTask.marker_file_name);
            catch
                return
            end
            numTimePoints = timeSeriesTable.getNumRows;
            timeVector = timeSeriesTable.getIndependentColumn;
            columnLabelsStringVector = char(timeSeriesTable.getColumnLabels);
            app.currentTask.Auxillary.MarkerFileMarkers = strsplit( ...
                columnLabelsStringVector(2:end-1), ", ");

            startTime = double(timeVector.get(0));
            endTime = double(timeVector.get(numTimePoints-1));
            app.currentTask.time_range = ...
                [startTime endTime];
            app.currentTask.Auxillary.MinTime = app.StartTimeField.Value;
            app.currentTask.Auxillary.MaxTime = app.EndTimeField.Value;
        end

        function CreateEmptyTask(app)
            app.masterTaskCounter = app.masterTaskCounter + 1;
            task.Attributes.name = "Task " + string(app.masterTaskCounter);
            task.marker_file_name = "";
            task.index = length(app.JMPTask)+1;
            task.is_enabled = 'true';
            task.time_range = [0 0];
            task.marker_names = strings(1);
            task.JMPJointSet = struct("JMPJoint", []);
            task.JMPBodySet = struct("JMPBody", []);
            task.Auxillary = struct("JointNames", strings(0), ...
                "BodyNames", strings(0), ...
                "MarkerFileMarkers", strings(0), ...
                "MaxTime", 0, "MinTime", 0);
            app.currentTask = task;
            
        end

        function deleteTask(app, taskIndex)
            app.JMPTask = app.JMPTask(~taskIndex);
            for i = 1 : length(app.JMPTask)
                app.JMPTask{i}.index = i;
            end
            app.EnableActionsCallback
            app.needToSave = true;
        end

        function MoveTaskUp(app)
            if app.selectedTaskIndex == 1 || ...
                app.selectedTaskIndex >= height(app.TasksTable.Data)
                return
            end

            indicesList = 1:1:length(app.JMPTask);

            indicesList(app.selectedTaskIndex) = app.selectedTaskIndex-1;
            indicesList(app.selectedTaskIndex-1) = app.selectedTaskIndex;
            app.JMPTask{app.selectedTaskIndex}.index = app.selectedTaskIndex-1;
            app.JMPTask{app.selectedTaskIndex-1}.index = app.selectedTaskIndex;
            app.JMPTask = {app.JMPTask{indicesList}};
            app.selectedTaskIndex = app.selectedTaskIndex-1;
        end

        function MoveTaskDown(app)
            if app.selectedTaskIndex == height(app.TasksTable.Data)-1 || ...
                app.selectedTaskIndex >= height(app.TasksTable.Data)
                return
            end

            indicesList = 1:1:length(app.JMPTask);
            indicesList(app.selectedTaskIndex) = app.selectedTaskIndex+1;
            indicesList(app.selectedTaskIndex+1) = app.selectedTaskIndex;
            app.JMPTask{app.selectedTaskIndex}.index = app.selectedTaskIndex+1;
            app.JMPTask{app.selectedTaskIndex+1}.index = app.selectedTaskIndex;
            app.JMPTask = {app.JMPTask{indicesList}};
            app.selectedTaskIndex = app.selectedTaskIndex+1;
        end

        function deleteJoint(app, jointIndex)
            app.currentTask.JMPJointSet.JMPJoint = ...
                app.currentTask.JMPJointSet.JMPJoint(~jointIndex);
            app.EnableActionsCallback
            app.needToSave = true;
        end

        function deleteBody(app, bodyIndex)
            app.currentTask.JMPBodySet.JMPBody = ...
                app.currentTask.JMPBodySet.JMPBody(~bodyIndex);
            app.EnableActionsCallback
            app.needToSave = true;
        end
    end
    
    methods (Access = public)

        function setSelectedObjects(app, objects)
            % Function to assign objects selected by ObjectSelectionWindow
            app.currentTask.marker_names = objects;
            app.MarkersTextArea.Value = strjoin(objects, " ");
            app.EnableActionsCallback()
        end

        function addJoint(app, joint)
            app.currentTask.JMPJointSet.JMPJoint{end+1} = joint;
            app.currentTask.Auxillary.JointNames(end+1) = ...
                joint.Attributes.name;
            app.EnableActionsCallback();
            % app.JointSet{end+1} = joint;
            % app.JointNames(end+1) = joint.Attributes.name;
            % app.JointsListBox.Items = app.JointNames;
            % app.EnableActionsCallback()
        end

        function editJoint(app, joint)
            selectedJoint = app.JointsTable.Selection;
            app.currentTask.JMPJointSet.JMPJoint{selectedJoint} = joint;
        end

        function addBody(app, body)
            app.currentTask.JMPBodySet.JMPBody{end+1} = body;
            app.currentTask.Auxillary.BodyNames(end+1) = ...
                body.Attributes.name;
            app.EnableActionsCallback();
        end

        function editBody(app, body)
            selectedBody = app.BodiesTable.Selection;
            app.currentTask.JMPBodySet.JMPBody{selectedBody} = body;
        end
        
        function SetModelMarkers(app, markers)
            app.model_markers = markers;
        end

        function SetModelJoints(app, joints)
            app.model_joints = joints;
        end

        function SetModelBodies(app, bodies)
            app.model_bodies = bodies;
        end

        function joints = getModelJoints(app)
            joints = app.model_joints;
        end

        function joints = getSelectedJoints(app)
            joints = app.currentTask.Auxillary.JointNames;
        end

        function bodies = getSelectedBodies(app)
            bodies = app.currentTask.Auxillary.BodyNames;
        end

        function bodies = getModelBodies(app)
            bodies = app.model_bodies;
        end
    end

    properties(Access = private)  % listner properties
        InputModelFileListener
        OutputModelFileListener
        JMPTasksListListener
        CurrentTaskListener
        SelectedTaskIndexListener
        MarkerFileListener
    end

    methods (Access = private) % listener methods
        function makeListeners(app)
            app.InputModelFileListener = addlistener(app, ...
                'input_model_file', 'PostSet', ...
                @(src,event)updateInputModelFileField(app));

            app.OutputModelFileListener = addlistener(app, ...
                'output_model_file', 'PostSet', ...
                @(src,event)updateOutputModelFileField(app));

            app.JMPTasksListListener = addlistener(app, ...
                'JMPTask', 'PostSet', ...
                @(src,event)updateJMPTasksListBox(app));

            app.CurrentTaskListener = addlistener(app, ...
                'currentTask', 'PostSet', ...
                @(src, event)updateCurrentTask(app));

            app.SelectedTaskIndexListener = addlistener(app, ...
                'selectedTaskIndex', 'PostSet', ...
                @(src, event)UpdateSelectedTask(app));

            app.MarkerFileListener = addlistener(app, ...
                'currentMarkerFile', 'PostSet', ...
                @(src, event)MarkerFileListenerFunction(app));
        end

        function updateInputModelFileField(app)
            app.InputModelFileEditField.Value = getRelativePath( ...
                app.input_model_file);
            app.ErrorsCallback()
            app.needToSave = true;
        end

        function updateOutputModelFileField(app)
            app.OutputModelFileEditField.Value = getRelativePath( ...
                app.output_model_file);
            app.needToSave = true;
        end

        function updateJMPTasksListBox(app)
            app.TaskNames = strings(1);
            isEnabled = strings(1);
            for i = 1 : length(app.JMPTask)
                app.TaskNames(i) = app.JMPTask{i}.Attributes.name;
                isEnabled(i) = app.JMPTask{i}.is_enabled;
            end
            isEnabled(end+1) = 'false';
            isEnabled = isEnabled=='true';
            app.TasksTable.Data = table(isEnabled', [app.TaskNames'; ...
                "Add a new task"]);
            app.EnableActionsCallback()
        end

        function updateCurrentTask(app)
            app.MarkersFileEditField.Value = getRelativePath( ...
                app.currentTask.marker_file_name);
            app.StartTimeField.Value = app.currentTask.time_range(1);
            app.EndTimeField.Value = app.currentTask.time_range(2);
            app.MarkersTextArea.Value = app.currentTask.marker_names;

            % populate joints table
            jointNames = strings(0);
            for i = 1 : length(app.currentTask.JMPJointSet.JMPJoint)
                jointNames(i) = app.currentTask.JMPJointSet.JMPJoint{i}. ...
                    Attributes.name;
            end
            app.JointsTable.Data = table([jointNames'; "+ Add new joint"]);
            app.currentTask.Auxillary.JointNames = jointNames;
            
            % populate bodies table
            bodyNames = strings(0);
            for i = 1 : length(app.currentTask.JMPBodySet.JMPBody)
                bodyNames(i) = app.currentTask.JMPBodySet.JMPBody{i}. ...
                    Attributes.name;
            end
            app.BodiesTable.Data = table([bodyNames'; "+ Add new body"]);
            app.currentTask.Auxillary.BodyNames = bodyNames;

            % Works even with creating a new task because selectedIndex =
            % size + 1;
            app.JMPTask{app.selectedTaskIndex} = app.currentTask;
            app.ErrorsCallback();
        end

        function UpdateSelectedTask(app)
            if app.selectedTaskIndex < height(app.TasksTable.Data)
                app.currentTask = app.JMPTask{app.selectedTaskIndex};
            end
            app.TasksTable.Selection = app.selectedTaskIndex;
            app.ErrorsCallback();
        end

        function MarkerFileListenerFunction(app)
            app.parseMarkerFileData();
            app.ErrorsCallback();
        end

        function formatTabButtons(app)
            switch app.TabGroup.SelectedTab
                case app.InputsTab
                    app.JMPInputsButton.BackgroundColor = [1 1 1];
                    app.JMPInputsButton.FontColor = [0.13,0.18,0.40];
                    
                    app.JMPTasksButton.BackgroundColor = [0.13,0.18,0.40];
                    app.JMPTasksButton.FontColor = [1 1 1];

                    app.AdvancedButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AdvancedButton.FontColor = [1 1 1];

                case app.TasksTab
                    app.JMPInputsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.JMPInputsButton.FontColor = [1 1 1];
                    
                    app.JMPTasksButton.BackgroundColor = [1 1 1];
                    app.JMPTasksButton.FontColor = [0.13,0.18,0.40];

                    app.AdvancedButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AdvancedButton.FontColor = [1 1 1];

                case app.AdvancedTab
                    app.JMPInputsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.JMPInputsButton.FontColor = [1 1 1];
                    
                    app.JMPTasksButton.BackgroundColor = [0.13,0.18,0.40];
                    app.JMPTasksButton.FontColor = [1 1 1];

                    app.AdvancedButton.BackgroundColor = [1 1 1];
                    app.AdvancedButton.FontColor = [0.13,0.18,0.40];
            end
            end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.makeListeners()

            taskNames = ["Add a new task"];
            isEnabled = [false];
            app.TasksTable.Data = table(isEnabled, taskNames);
        end

        % Button pushed function: JMPInputsButton
        function JMPInputsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.InputsTab;
            app.formatTabButtons();
        end

        % Button pushed function: AdvancedButton
        function AdvancedButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AdvancedTab;
            app.formatTabButtons();
        end

        % Button pushed function: JMPTasksButton
        function JMPTasksButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TasksTab;
            app.formatTabButtons();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file, path] = uiputfile('*.xml', "Save XML Settings File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.saveSettingsFile(fullfile(path, file))
            app.currentSettingsFile = fullfile(path, file);
            app.needToSave = false;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web("https://nmsm.rice.edu/guides-and-publications/tool-overviews/model-personalization/muscle-tendon-personalization/")
        end

        % Image clicked function: RcnlLogo
        function RcnlLogoImageClicked(app, event)
            web("https://nmsm.rice.edu/")
        end

        % Button pushed function: InputModelFileSearchButton
        function InputModelFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osim', "Select an OpenSim Model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end

            app.input_model_file = fullfile(path, file);
            if strcmp(app.output_model_file, "")
                app.output_model_file = fullfile(path, ...
                    strrep(file, ".osim", "_JMP.osim"));
            end
        end

        % Value changed function: InputModelFileEditField
        function InputModelFileEditFieldValueChanged(app, event)
            app.input_model_file = getAbsolutePath( ...
                app.InputModelFileEditField.Value);
        end

        % Button pushed function: OutputModelFileSearchButton
        function OutputModelFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osim', "Select an OpenSim Model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.output_model_file = fullfile(path, file);
        end

        % Value changed function: OutputModelFileEditField
        function OutputModelFileEditFieldValueChanged(app, event)
            app.output_model_file = getAbsolutePath( ...
            app.OutputModelFileEditField.Value);
        end

        % Double-clicked callback: TasksTable
        function TasksTableDoubleClicked(app, event)
            
            
        end

        % Button pushed function: MarkerFileSearchButton
        function MarkerFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.trc', "Select a trc marker file.");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
      
            fullpath = fullfile(path, file);
        
            app.currentTask.marker_file_name = fullpath;
            app.currentMarkerFile = app.currentTask.marker_file_name;
        end

        % Value changed function: MarkersFileEditField
        function MarkersFileEditFieldValueChanged(app, event)
            app.currentTask.marker_file_name = getAbsolutePath( ...
                app.MarkersFileEditField.Value);
            app.currentMarkerFile = app.currentTask.marker_file_name;
        end

        % Selection changed function: TasksTable
        function TasksTableSelectionChanged(app, event)
            app.selectedTaskIndex = app.TasksTable.Selection;
            if app.TasksTable.Selection == height(app.TasksTable.Data) % if last element
                app.CreateEmptyTask();
                return
            end
        end

        % Cell edit callback: TasksTable
        function TasksTableCellEdit(app, event)
            indices = event.Indices;
            if indices(2) == 1
                if app.TasksTable.Data.Var1(indices(1))
                    app.currentTask.is_enabled = 'true';
                else
                    app.currentTask.is_enabled = 'false';
                end
            end
            if indices(2) == 2
                app.currentTask.Attributes.name = event.NewData;
            end
            
        end

        % Value changed function: StartTimeField
        function StartTimeFieldValueChanged(app, event)
            app.currentTask.time_range(1) = app.StartTimeField.Value;
        end

        % Value changed function: EndTimeField
        function EndTimeFieldValueChanged(app, event)
            app.currentTask.time_range(2) = app.EndTimeField.Value;
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_markers, ...
                app.currentTask.marker_names);
        end

        % Menu selected function: DeleteTaskMenu
        function DeleteTaskMenuSelected(app, event)
            deletionIndex = 1 : 1 : length(app.JMPTask) == ...
                app.TasksTable.Selection;
            app.deleteTask(deletionIndex);
        end

        % Selection changed function: JointsTable
        function JointsTableSelectionChanged(app, event)
            selection = app.JointsTable.Selection;
            if selection > length(app.currentTask.JMPJointSet.JMPJoint)
                JMPJointSelection(app, []);
            end
        end

        % Selection changed function: BodiesTable
        function BodiesTableSelectionChanged(app, event)
            selection = app.BodiesTable.Selection;
            if selection > length(app.currentTask.JMPBodySet.JMPBody)
                JMPBodySelection(app, []);
            end
        end

        % Menu selected function: EditJointMenu
        function EditJointMenuSelected(app, event)
            selection = app.JointsTable.Selection;
            JMPJointSelection(app, app.currentTask.JMPJointSet.JMPJoint{selection});
        end

        % Menu selected function: DeleteJointMenu
        function DeleteJointMenuSelected(app, event)
            deletionIndex = 1 : 1 : length(app.currentTask.JMPJointSet.JMPJoint) == ...
                app.JointsTable.Selection;
            app.deleteJoint(deletionIndex);
        end

        % Menu selected function: EditBodyMenu
        function EditBodyMenuSelected(app, event)
            selection = app.BodiesTable.Selection;
            JMPBodySelection(app, app.currentTask.JMPBodySet.JMPBody{selection});
        end

        % Menu selected function: DeleteBodyMenu
        function DeleteBodyMenuSelected(app, event)
            deletionIndex = 1 : 1 : length(app.currentTask.JMPBodySet.JMPBody) == ...
                app.BodiesTable.Selection;
            app.deleteBody(deletionIndex);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)

        end

        % Button pushed function: MoveTaskUpButton
        function MoveTaskUpButtonPushed(app, event)
            app.MoveTaskUp()
        end

        % Button pushed function: MoveTaskDownButton
        function MoveTaskDownButtonPushed(app, event)
            app.MoveTaskDown()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.InputsTab = uitab(app.TabGroup);
            app.OutputModelFileSearchButton = uibutton(app.InputsTab, 'push');
            app.OutputModelFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @OutputModelFileSearchButtonPushed, true);
            app.OutputModelFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.OutputModelFileSearchButton.Text = '';
            app.OutputModelFileEditFieldLabel = uilabel(app.InputsTab);
            app.OutputModelFileEditFieldLabel.Text = 'Output Model File';
            app.OutputModelFileEditField = uieditfield(app.InputsTab, 'text');
            app.OutputModelFileEditField.ValueChangedFcn = createCallbackFcn(app, @OutputModelFileEditFieldValueChanged, true);
            app.InputModelFileError = uiimage(app.InputsTab);
            app.InputModelFileError.Visible = 'off';
            app.InputModelFileError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputModelFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputModelFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputModelFileSearchButtonPushed, true);
            app.InputModelFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputModelFileSearchButton.Text = '';
            app.InputModelFileEditFieldLabel = uilabel(app.InputsTab);
            app.InputModelFileEditFieldLabel.Text = 'Input Model File';
            app.InputModelFileEditField = uieditfield(app.InputsTab, 'text');
            app.InputModelFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputModelFileEditFieldValueChanged, true);
            app.TasksTab = uitab(app.TabGroup);
            app.MuscleGroupsLabel_3 = uilabel(app.TasksTab);
            app.MuscleGroupsLabel_3.Text = 'Tasks';
            app.TasksPanel = uipanel(app.TasksTab);
            app.TasksPanel.Enable = 'off';
            app.MarkerFileSearchButton = uibutton(app.TasksPanel, 'push');
            app.MarkerFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerFileSearchButtonPushed, true);
            app.MarkerFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.MarkerFileSearchButton.Text = '';
            app.MarkersFileWarning = uiimage(app.TasksPanel);
            app.MarkersFileWarning.Visible = 'off';
            app.MarkersFileWarning.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'warning.png');
            app.MarkersFileError = uiimage(app.TasksPanel);
            app.MarkersFileError.Visible = 'off';
            app.MarkersFileError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.TimeRangeError = uiimage(app.TasksPanel);
            app.TimeRangeError.Visible = 'off';
            app.TimeRangeError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.BodiesTable = uitable(app.TasksPanel);
            app.BodiesTable.ColumnName = '';
            app.BodiesTable.RowName = {};
            app.BodiesTable.SelectionType = 'row';
            app.BodiesTable.SelectionChangedFcn = createCallbackFcn(app, @BodiesTableSelectionChanged, true);
            app.BodiesTable.Multiselect = 'off';
            app.JointsTable = uitable(app.TasksPanel);
            app.JointsTable.ColumnName = '';
            app.JointsTable.RowName = {};
            app.JointsTable.SelectionType = 'row';
            app.JointsTable.SelectionChangedFcn = createCallbackFcn(app, @JointsTableSelectionChanged, true);
            app.JointsTable.Multiselect = 'off';
            app.JointsLabel = uilabel(app.TasksPanel);
            app.JointsLabel.Text = 'Joints';
            app.BodiesLabel = uilabel(app.TasksPanel);
            app.BodiesLabel.Text = 'Bodies';
            app.MarkersFileEditFieldLabel = uilabel(app.TasksPanel);
            app.MarkersFileEditFieldLabel.Text = 'Markers File';
            app.MarkersFileEditField = uieditfield(app.TasksPanel, 'text');
            app.MarkersFileEditField.ValueChangedFcn = createCallbackFcn(app, @MarkersFileEditFieldValueChanged, true);
            app.TimeRangesEditFieldLabel = uilabel(app.TasksPanel);
            app.TimeRangesEditFieldLabel.Text = 'Time Range (s):';
            app.StartTimeField = uieditfield(app.TasksPanel, 'numeric');
            app.StartTimeField.ValueChangedFcn = createCallbackFcn(app, @StartTimeFieldValueChanged, true);
            app.StartTimeField.Enable = 'off';
            app.ToLabel = uilabel(app.TasksPanel);
            app.ToLabel.Text = 'To:';
            app.EndTimeField = uieditfield(app.TasksPanel, 'numeric');
            app.EndTimeField.ValueChangedFcn = createCallbackFcn(app, @EndTimeFieldValueChanged, true);
            app.EndTimeField.Enable = 'off';
            app.MarkersTextAreaLabel = uilabel(app.TasksPanel);
            app.MarkersTextAreaLabel.Text = 'Markers';
            app.MarkersTextArea = uitextarea(app.TasksPanel);
            app.MarkersTextArea.Editable = 'off';
            app.EditButton = uibutton(app.TasksPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Enable = 'off';
            app.EditButton.Text = 'Edit';
            app.MoveTaskUpButton = uibutton(app.TasksTab, 'push');
            app.MoveTaskUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveTaskUpButtonPushed, true);
            app.MoveTaskUpButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'ArrowUp.svg');
            app.MoveTaskUpButton.Enable = 'off';
            app.MoveTaskUpButton.Text = '';
            app.MoveTaskDownButton = uibutton(app.TasksTab, 'push');
            app.MoveTaskDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveTaskDownButtonPushed, true);
            app.MoveTaskDownButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'ArrowDown.svg');
            app.MoveTaskDownButton.Enable = 'off';
            app.MoveTaskDownButton.Text = '';
            app.TasksTable = uitable(app.TasksTab);
            app.TasksTable.ColumnName = {''; 'Task'};
            app.TasksTable.RowName = {};
            app.TasksTable.ColumnSortable = [false true];
            app.TasksTable.SelectionType = 'row';
            app.TasksTable.ColumnEditable = true;
            app.TasksTable.CellEditCallback = createCallbackFcn(app, @TasksTableCellEdit, true);
            app.TasksTable.DoubleClickedFcn = createCallbackFcn(app, @TasksTableDoubleClicked, true);
            app.TasksTable.SelectionChangedFcn = createCallbackFcn(app, @TasksTableSelectionChanged, true);
            app.TasksTable.Multiselect = 'off';
            app.AdvancedTab = uitab(app.TabGroup);
            app.Mask1 = uiimage(app.UIFigure);
            app.Mask1.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'greyMask.png');
            app.JMPTasksButton = uibutton(app.UIFigure, 'push');
            app.JMPTasksButton.ButtonPushedFcn = createCallbackFcn(app, @JMPTasksButtonPushed, true);
            app.JMPTasksButton.Enable = 'off';
            app.JMPTasksButton.Text = 'JMP Tasks';
            app.AdvancedButton = uibutton(app.UIFigure, 'push');
            app.AdvancedButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedButtonPushed, true);
            app.AdvancedButton.Text = 'Advanced';
            app.JMPInputsButton = uibutton(app.UIFigure, 'push');
            app.JMPInputsButton.ButtonPushedFcn = createCallbackFcn(app, @JMPInputsButtonPushed, true);
            app.JMPInputsButton.Text = 'JMP Inputs';
            app.RcnlLogo = uiimage(app.UIFigure);
            app.RcnlLogo.ImageClickedFcn = createCallbackFcn(app, @RcnlLogoImageClicked, true);
            app.RcnlLogo.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'rcnlIcon.png');
            app.LoadSettingsFileButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsFileButton.Text = 'Load';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.JointModelPersonalizationToolLabel = uilabel(app.UIFigure);
            app.JointModelPersonalizationToolLabel.Text = 'Joint Model Personalization Tool';
            app.JMPImage = uiimage(app.UIFigure);
            app.JMPImage.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'jmpFigure.png');
            app.TasksContextMenu = uicontextmenu(app.UIFigure);
            app.DeleteTaskMenu = uimenu(app.TasksContextMenu);
            app.DeleteTaskMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteTaskMenuSelected, true);
            app.DeleteTaskMenu.Text = 'Delete';
            app.CopyTaskMenu = uimenu(app.TasksContextMenu);
            app.CopyTaskMenu.Text = 'Copy';
            app.CopyTaskMirrorMenu = uimenu(app.TasksContextMenu);
            app.CopyTaskMirrorMenu.Text = 'Copy Mirror';
            % Assign app.TasksContextMenu
            app.TasksTable.ContextMenu = app.TasksContextMenu;
            app.JointsContextMenu = uicontextmenu(app.UIFigure);
            app.EditJointMenu = uimenu(app.JointsContextMenu);
            app.EditJointMenu.MenuSelectedFcn = createCallbackFcn(app, @EditJointMenuSelected, true);
            app.EditJointMenu.Text = 'Edit';
            app.DeleteJointMenu = uimenu(app.JointsContextMenu);
            app.DeleteJointMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteJointMenuSelected, true);
            app.DeleteJointMenu.Text = 'Delete';
            % Assign app.JointsContextMenu
            app.JointsTable.ContextMenu = app.JointsContextMenu;
            app.BodiesContextMenu = uicontextmenu(app.UIFigure);
            app.EditBodyMenu = uimenu(app.BodiesContextMenu);
            app.EditBodyMenu.MenuSelectedFcn = createCallbackFcn(app, @EditBodyMenuSelected, true);
            app.EditBodyMenu.Text = 'Edit';
            app.DeleteBodyMenu = uimenu(app.BodiesContextMenu);
            app.DeleteBodyMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteBodyMenuSelected, true);
            app.DeleteBodyMenu.Text = 'Delete';
            % Assign app.BodiesContextMenu
            app.BodiesTable.ContextMenu = app.BodiesContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JMPBaseNew

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end