classdef JMPBodySelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        OKWarning              matlab.ui.control.Image
        BodyNameError          matlab.ui.control.Image
        CloseButton            matlab.ui.control.Button
        OKButton               matlab.ui.control.Button
        ScaleBodyLabel         matlab.ui.control.Label
        MoveMarkersZCheckBox   matlab.ui.control.CheckBox
        MoveMarkersYCheckBox   matlab.ui.control.CheckBox
        MoveMarkersXCheckBox   matlab.ui.control.CheckBox
        MoveMarkersLabel       matlab.ui.control.Label
        ScaleBodyCheckBox      matlab.ui.control.CheckBox
        BodyNameDropDown       matlab.ui.control.DropDown
        BodyNameDropDownLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        JMPTaskCreation
        JMPBody struct = struct("Attributes", [], "scale_body", [], ...
            "move_markers", []);
        editingFlag logical = false;
        originalBodyName string = "";
        errorFlag logical = false;
    end

    methods (Access = private)
        function ErrorsCallback(app)
            app.errorFlag = false;
            if ~any(contains(app.BodyNameDropDown.Value, ...
                    app.JMPTaskCreation.JMPBase.getModelBodies))
                throwGuiError("This body is not found in the .osim model", ...
                    app.BodyNameDropDown, app.BodyNameError);
                app.errorFlag = true;
            elseif any(contains(app.BodyNameDropDown.Value, ...
                    app.JMPTaskCreation.getBodyNames())) && ...
                    ~(app.editingFlag && strcmp(app.BodyNameDropDown.Value, ...
                    app.originalBodyName))
                throwGuiError("This body is already in this task", ...
                    [], app.BodyNameError);
                app.errorFlag = true;
            else
                clearGuiError([], app.BodyNameError);
            end

            if ~any([app.ScaleBodyCheckBox.Value, ...
                    app.MoveMarkersXCheckBox.Value, ...
                    app.MoveMarkersYCheckBox.Value, ...
                    app.MoveMarkersZCheckBox.Value])
                throwGuiWarning("This task has no parameters selected.", ...
                    app.OKButton, app.OKWarning)
            else
                clearGuiError([], app.OKWarning)
            end
            app.EnableActionsCallback()
        end

        function EnableActionsCallback(app)
            if app.errorFlag
                app.OKButton.Enable = 'off';
            else
                app.OKButton.Enable = 'on';
            end
        end
        
        function buildEmptyBody(app)
            app.JMPBody.Attributes.name = app.BodyNameDropDown.Value;
            app.JMPBody.scale_body = 'false';
            app.JMPBody.move_markers = ["false" "false" "false"];
        end

        function loadBody(app, body)
            app.JMPBody = body;
            app.originalBodyName = body.Attributes.name;
            app.BodyNameDropDown.Value = body.Attributes.name;
            if strcmp(body.scale_body, 'true')
                app.ScaleBodyCheckBox.Value = true;
            end
            markerAxes = strcmp(body.move_markers, "true");
            [app.MoveMarkersXCheckBox.Value, ...
                app.MoveMarkersYCheckBox.Value, ...
                app.MoveMarkersZCheckBox.Value] = ...
                deal(markerAxes(1), markerAxes(2), markerAxes(3));
            app.ErrorsCallback()
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, JMPTaskCreation, JMPBody)
            app.JMPTaskCreation = JMPTaskCreation;
            modelBodies = JMPTaskCreation.JMPBase.getModelBodies();
            app.BodyNameDropDown.Items = modelBodies;
            if isempty(JMPBody)
                app.buildEmptyBody()
                app.ErrorsCallback()
            else
                app.editingFlag = true;
                app.loadBody(JMPBody)
            end
        end

        % Value changed function: BodyNameDropDown
        function BodyNameDropDownValueChanged(app, event)
            value = app.BodyNameDropDown.Value;
            app.JMPBody.Attributes.name = value;
            app.ErrorsCallback()
        end

        % Value changed function: MoveMarkersXCheckBox, 
        % ...and 2 other components
        function xCheckBoxValueChanged(app, event)
            app.JMPBody.move_markers( ...
                [app.MoveMarkersXCheckBox.Value, ...
                app.MoveMarkersYCheckBox.Value, ...
                app.MoveMarkersZCheckBox.Value]) = "true";
            app.JMPBody.move_markers( ...
                ~[app.MoveMarkersXCheckBox.Value, ...
                app.MoveMarkersYCheckBox.Value, ...
                app.MoveMarkersZCheckBox.Value]) = "false";
            app.ErrorsCallback()
        end

        % Value changed function: ScaleBodyCheckBox
        function ScaleBodyCheckBoxValueChanged(app, event)
            if app.ScaleBodyCheckBox.Value
                app.JMPBody.scale_body = 'true';
            else
                app.JMPBody.scale_body = 'false';
            end
            app.ErrorsCallback()
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if ~app.editingFlag
                app.JMPTaskCreation.addBody(app.JMPBody);
            else
                app.JMPTaskCreation.editBody(app.JMPBody);
            end
            
            delete(app.UIFigure);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            % app.JMPTaskCreation.selectionExitFunction()
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BodyNameDropDownLabel = uilabel(app.UIFigure);
            app.BodyNameDropDownLabel.Text = 'Body Name';
            app.BodyNameDropDown = uidropdown(app.UIFigure);
            app.BodyNameDropDown.Editable = 'on';
            app.BodyNameDropDown.ValueChangedFcn = createCallbackFcn(app, @BodyNameDropDownValueChanged, true);
            app.ScaleBodyCheckBox = uicheckbox(app.UIFigure);
            app.ScaleBodyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaleBodyCheckBoxValueChanged, true);
            app.ScaleBodyCheckBox.Text = '';
            app.MoveMarkersLabel = uilabel(app.UIFigure);
            app.MoveMarkersLabel.Text = 'Move Markers';
            app.MoveMarkersXCheckBox = uicheckbox(app.UIFigure);
            app.MoveMarkersXCheckBox.ValueChangedFcn = createCallbackFcn(app, @xCheckBoxValueChanged, true);
            app.MoveMarkersXCheckBox.Text = 'x';
            app.MoveMarkersYCheckBox = uicheckbox(app.UIFigure);
            app.MoveMarkersYCheckBox.ValueChangedFcn = createCallbackFcn(app, @xCheckBoxValueChanged, true);
            app.MoveMarkersYCheckBox.Text = 'y';
            app.MoveMarkersZCheckBox = uicheckbox(app.UIFigure);
            app.MoveMarkersZCheckBox.ValueChangedFcn = createCallbackFcn(app, @xCheckBoxValueChanged, true);
            app.MoveMarkersZCheckBox.Text = 'z';
            app.ScaleBodyLabel = uilabel(app.UIFigure);
            app.ScaleBodyLabel.Text = 'Scale Body';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.BodyNameError = uiimage(app.UIFigure);
            app.BodyNameError.Visible = 'off';
            app.BodyNameError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.OKWarning = uiimage(app.UIFigure);
            app.OKWarning.Visible = 'off';
            app.OKWarning.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'warning.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JMPBodySelection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end