classdef JMPRun < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        CloseButton   matlab.ui.control.Button
        CancelButton  matlab.ui.control.Button
        StatusLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)

        CancelOptimization = true; % Description
    end
    
    properties (Access = private)
        JMPBase; % Description
        SettingsFileName string;
        cancelOptimizationFlag logical = false;
        
    end
    
    properties (SetObservable)
        parsing logical = true;
    end

    methods (Access = public)
        
        function stop = CancelOptimizationGui(app, x, optimValues, state)

            drawnow; % lets GUI process button presses
        
            stop = false;
        
            if app.cancelOptimizationFlag
                stop = true;
                app.StatusLabel.Text = "Cancelling...";
            end


            drawnow; % lets GUI process button presses
        end

        function StatusListenerFunction(app)
            if ~app.parsing
                app.StatusLabel.Text = "Running JMP...";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, JMPBase, SettingsFileName)
            app.JMPBase = JMPBase;
            app.SettingsFileName = SettingsFileName;
            StatusListener = addlistener(app, 'parsing', 'PostSet', ...
                @(src, event) StatusListenerFunction(app));
            drawnow
            pause(0.01)
            JointModelPersonalizationTool(SettingsFileName, app)
            app.StatusLabel.Text = "Plotting.";
            plotJmpResultsFromSettingsFile(SettingsFileName);
            app.StatusLabel.Text = "JMP Completed...";
            app.CloseButton.Visible = 'on';
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.cancelOptimizationFlag = true;
            app.CancelButton.Enable = 'off';
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Parsing...';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Visible = 'off';
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JMPRun(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end