classdef JMPRun < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        JMPCompletedLabel     matlab.ui.control.Label
        PlottingResultsLabel  matlab.ui.control.Label
        SavingResultsLabel    matlab.ui.control.Label
        RunningJMPLabel       matlab.ui.control.Label
        ParsingLabel          matlab.ui.control.Label
        CloseButton           matlab.ui.control.Button
        CancelButton          matlab.ui.control.Button
    end

    
    properties (Access = public)

        CancelOptimization = true; % Description
    end
    
    properties (Access = private)
        JMPBase; % Description
        SettingsFileName string;
        cancelOptimizationFlag logical = false;
        
    end
    
    properties (SetObservable)
        parsing logical = true;
    end

    methods (Access = public)
        
        function stop = CancelOptimizationGui(app, x, optimValues, state)
            drawnow; % lets GUI process button presses
            stop = false;
            if app.cancelOptimizationFlag
                stop = true;
            end
            drawnow; % lets GUI process button presses
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, JMPBase, SettingsFileName)
            app.JMPBase = JMPBase;
            app.SettingsFileName = SettingsFileName;
            drawnow
            pause(0.01)
            JointModelPersonalizationTool(SettingsFileName, app)
            app.PlottingResultsLabel.Enable = 'on';
            drawnow
            plotJmpResultsFromSettingsFile(SettingsFileName);
            drawnow
            app.PlottingResultsLabel.Enable = 'off';
            app.JMPCompletedLabel.Enable = 'on';
            app.CloseButton.Enable = 'on';
            app.CancelButton.Enable = 'off';
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.cancelOptimizationFlag = true;
            app.CancelButton.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Enable = 'off';
            app.CloseButton.Text = 'Close';
            app.ParsingLabel = uilabel(app.UIFigure);
            app.ParsingLabel.Text = 'Parsing Settings File...';
            app.RunningJMPLabel = uilabel(app.UIFigure);
            app.RunningJMPLabel.Enable = 'off';
            app.RunningJMPLabel.Text = 'Running JMP...';
            app.SavingResultsLabel = uilabel(app.UIFigure);
            app.SavingResultsLabel.Enable = 'off';
            app.SavingResultsLabel.Text = 'Saving Results...';
            app.PlottingResultsLabel = uilabel(app.UIFigure);
            app.PlottingResultsLabel.Enable = 'off';
            app.PlottingResultsLabel.Text = 'Plotting Results...';
            app.JMPCompletedLabel = uilabel(app.UIFigure);
            app.JMPCompletedLabel.Enable = 'off';
            app.JMPCompletedLabel.Text = 'JMP Completed.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JMPRun(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end