classdef JMPBase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        JMPTasksListBox                matlab.ui.control.ListBox
        JMPTasksListBoxLabel           matlab.ui.control.Label
        RcnlLogo                       matlab.ui.control.Image
        JointModelPersonalizationToolLabel  matlab.ui.control.Label
        HelpButton                     matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        JMPImage                       matlab.ui.control.Image
        InputModelFileEditField        matlab.ui.control.EditField
        InputModelFileSearchButton     matlab.ui.control.Button
        OutputModelFileEditField       matlab.ui.control.EditField
        OutputModelFileSearchButton    matlab.ui.control.Button
        LoadSettingsFileButton         matlab.ui.control.Button
        DeleteJMPTaskButton            matlab.ui.control.Button
        EditJMPTaskButton              matlab.ui.control.Button
        AddJMPTaskButton               matlab.ui.control.Button
        OutputModelFileEditFieldLabel  matlab.ui.control.Label
        InputModelFileEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        OutputModelFileAuto logical = true;
        maxAllowableError double = 0.005;
        TaskListProperties struct = struct();
        TaskList cell;
        TaskNames string;
        input_model_file string;
        model_markers string;
        model_bodies string;
        model_joints string;
    end
    
    methods (Access = private)
        
        function SaveSettingsFile(app, fileName)
            settingsTree = makeJMPSettingsStruct(app);
            settingsFileStruct.NMSMPipelineDocument.JointModelPersonalizationTool = ...
                settingsTree.JointModelPersonalizationTool;
            settingsFileStruct.NMSMPipelineDocument.Attributes.Version = '0_dot_1_dot_0';
            struct2xml(settingsFileStruct, fileName)
        end

        function settingsStruct = makeJMPSettingsStruct(app)
            settingsStruct = struct();
            % settingsStruct.NMSMPipelineDocument.Attributes.Version = '0_dot_1_dot_0';
            settingsStruct.JointModelPersonalizationTool.input_model_file = app.InputModelFileEditField.Value;
            settingsStruct.JointModelPersonalizationTool.output_model_file = app.OutputModelFileEditField.Value;
            settingsStruct.JointModelPersonalizationTool.allowable_error = app.maxAllowableError;
            app.TaskListProperties.is_enabled = 'false';

            settingsStruct.JointModelPersonalizationTool.JMPTaskList = ...
                app.formatTaskList();
            
        end

        function formattedTaskList = formatTaskList(app)
            formattedTaskList = app.TaskList;
            numTasks = numel(formattedTaskList.JMPTask);
            for i = 1 : numTasks
                formattedTaskList.JMPTask{i}.Attributes.name = ...
                    convertStringsToChars( ...
                    formattedTaskList.JMPTask{i}.Attributes.name);

                formattedTaskList.JMPTask{i}.marker_file_name= ...
                    convertStringsToChars( ...
                    formattedTaskList.JMPTask{i}.marker_file_name);

                formattedTaskList.JMPTask{i}.marker_file_name= ...
                    convertStringsToChars( ...
                    formattedTaskList.JMPTask{i}.marker_file_name);

                formattedTaskList.JMPTask{i}.time_range= ...
                    num2str(formattedTaskList.JMPTask{i}.time_range);

                formattedTaskList.JMPTask{1}.marker_names = ...
                    convertStringsToChars(strjoin( ...
                    formattedTaskList.JMPTask{1}.marker_names));


                if isfield(formattedTaskList.JMPTask{i}, "JMPJointSet")
                    formattedTaskList.JMPTask{i}.JMPJointSet.JMPJoint = ...
                        app.formatJointSet(formattedTaskList.JMPTask{i}.JMPJointSet.JMPJoint);
                end
            end
        end

        function formattedJointSet = formatJointSet(app, JMPJoint)
        formattedJointSet = JMPJoint;
        numJoints = numel(formattedJointSet);
        for j = 1 : numJoints
            formattedJointSet{j}.parent_frame_transformation.translation = ...
                convertStringsToChars(strjoin( ...
                formattedJointSet{j}.parent_frame_transformation.translation, " "));
            formattedJointSet{j}.parent_frame_transformation.orientation = ...
                convertStringsToChars(strjoin( ...
                formattedJointSet{j}.parent_frame_transformation.orientation, " "));
            formattedJointSet{j}.child_frame_transformation.translation = ...
                convertStringsToChars(strjoin( ...
                formattedJointSet{j}.child_frame_transformation.translation, " "));
            formattedJointSet{j}.child_frame_transformation.orientation = ...
                convertStringsToChars(strjoin( ... 
                formattedJointSet{j}.child_frame_transformation.orientation, " "));
        end
        end

        function parseModelFile(app)
            if ~exist(app.input_model_file)
                warning("The given model file does not exist")
                return
            end
            app.model_markers = [];
            app.model_bodies = [];
            app.model_joints = [];
            
            model = Model(app.input_model_file);
            parseModelMarkers(app, model);
            parseModelJoints(app, model);
            parseModelBodies(app, model);
        end

        function parseModelMarkers(app, model)
            numMarkers = model.getNumMarkers;
            markerSet = model.getMarkerSet();
            for i = 1 : numMarkers
                app.model_markers(end+1) = markerSet.get(i-1).getName();
            end
        end

        function parseModelJoints(app, model)
            numJoints = model.getNumJoints;
            jointSet = model.getJointSet();
            for i = 1 : numJoints
                app.model_joints(end+1) = jointSet.get(i-1).getName();
            end
        end

        function parseModelBodies(app, model)
            numBodies = model.getNumBodies;
            bodySet = model.getBodySet();
            for i = 1 : numBodies
                app.model_bodies(end+1) = bodySet.get(i-1).getName();
            end
        end
    end
    
    methods (Access = public)
        
        function taskList = getJMPTaskList(app)
            taskList = app.TaskList;
        end

        function addJMPTask(app, task)
            app.TaskList{end+1} = task;
            app.TaskNames(end+1) = task.Attributes.name;
            app.JMPTasksListBox.Items = app.TaskNames;
        end

        function task = getJMPTask(app, index)
            task = app.TaskList{index};
        end

        function editJMPTask(app, task)
            selectedTaskIndex = app.JMPTasksListBox.ValueIndex;
            app.TaskList{selectedTaskIndex} = task;
            app.TaskNames(selectedTaskIndex) = task.Attributes.name;
            app.JMPTasksListBox.Items = app.TaskNames;
        end

        function deleteJMPTask(app, taskIndex)
            app.TaskList = app.TaskList(~taskIndex);
            app.TaskNames = app.TaskNames(~taskIndex);
            app.JMPTasksListBox.Items = app.TaskNames;
        end

        function model_markers = getJMPModelMarkers(app)
            model_markers = app.model_markers;
        end

        function model_bodies = getJMPModelBodies(app)
            model_bodies = app.model_bodies;
        end

        function model_joints = getJMPModelJoints(app)
            model_joints = app.model_joints;
            % app.JMPTasksListBox.Selection
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changed function: OutputModelFileEditField
        function OutputModelFileEditFieldValueChanged(app, event)
            app.OutputModelFileAuto = false;
            
        end

        % Button pushed function: InputModelFileSearchButton
        function InputModelFileSearchButtonPushed(app, event)
        [file, path] = uigetfile('*.osim', "Select an OpenSim Model File");
        % User hit "Cancel"
        if isequal(file, 0)
            return
        end
    
        fullpath = fullfile(path, file);
    
        % Update the text field
        app.InputModelFileEditField.Value = fullpath;
        app.input_model_file = fullpath;
        if app.OutputModelFileAuto
                app.OutputModelFileEditField.Value = ...
                    strrep(app.InputModelFileEditField.Value, ...
                    ".osim", "_JMP.osim");
        end
        parseModelFile(app)
        end

        % Button pushed function: OutputModelFileSearchButton
        function OutputModelFileSearchButtonPushed(app, event)
        [file, path] = uigetfile('*.osim', "Select an OpenSim Model File");
            % User hit "Cancel"
        if isequal(file, 0)
            return
        end
    
        fullpath = fullfile(path, file);
    
        % Update the text field
        app.OutputModelFileEditField.Value = fullpath;
        end

        % Button pushed function: AddJMPTaskButton
        function AddJMPTaskButtonPushed(app, event)
            JMPTaskCreation(app, []);
        end

        % Button pushed function: EditJMPTaskButton
        function EditJMPTaskButtonPushed(app, event)
            selectedTaskName = app.JMPTasksListBox.Value;
            if isempty(selectedTaskName)
                return
            end
            taskToEdit = app.TaskList{strcmp(selectedTaskName, app.TaskNames)};
            JMPTaskCreation(app, taskToEdit);
        end

        % Button pushed function: DeleteJMPTaskButton
        function DeleteJMPTaskButtonPushed(app, event)
            selectedTaskIndex = app.JMPTasksListBox.ValueIndex;
            indicesVector = 1:1:numel(app.JMPTasksListBox.Items) == selectedTaskIndex;
            app.deleteJMPTask(indicesVector);
        end

        % Value changed function: InputModelFileEditField
        function InputModelFileEditFieldValueChanged(app, event)
            app.input_model_file = app.InputModelFileEditField.Value;
            parseModelFile(app)
        end

        % Value changing function: InputModelFileEditField
        function InputModelFileEditFieldValueChanging(app, event)
            changingValue = event.Value;
        end

        % Image clicked function: RcnlLogo
        function RcnlLogoClicked(app, event)
            web("https://nmsm.rice.edu/")
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web("https://nmsm.rice.edu/guides-and-publications/tool-overviews/model-personalization/joint-model-personalization/")
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file, path] = uiputfile('*.xml', "Save XML Settings File");
            SaveSettingsFile(app, fullfile(path, file));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputModelFileEditFieldLabel = uilabel(app.UIFigure);
            app.InputModelFileEditFieldLabel.Text = 'Input Model File';
            app.OutputModelFileEditFieldLabel = uilabel(app.UIFigure);
            app.OutputModelFileEditFieldLabel.Text = 'Output Model File';
            app.AddJMPTaskButton = uibutton(app.UIFigure, 'push');
            app.AddJMPTaskButton.ButtonPushedFcn = createCallbackFcn(app, @AddJMPTaskButtonPushed, true);
            app.AddJMPTaskButton.Text = 'Add';
            app.EditJMPTaskButton = uibutton(app.UIFigure, 'push');
            app.EditJMPTaskButton.ButtonPushedFcn = createCallbackFcn(app, @EditJMPTaskButtonPushed, true);
            app.EditJMPTaskButton.Text = 'Edit';
            app.DeleteJMPTaskButton = uibutton(app.UIFigure, 'push');
            app.DeleteJMPTaskButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteJMPTaskButtonPushed, true);
            app.DeleteJMPTaskButton.Text = 'Delete';
            app.LoadSettingsFileButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsFileButton.Text = 'Load';
            app.OutputModelFileSearchButton = uibutton(app.UIFigure, 'push');
            app.OutputModelFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @OutputModelFileSearchButtonPushed, true);
            app.OutputModelFileSearchButton.Icon = fullfile(pathToMLAPP, 'Images', 'folderIcon.svg');
            app.OutputModelFileSearchButton.Text = '';
            app.OutputModelFileEditField = uieditfield(app.UIFigure, 'text');
            app.OutputModelFileEditField.ValueChangedFcn = createCallbackFcn(app, @OutputModelFileEditFieldValueChanged, true);
            app.InputModelFileSearchButton = uibutton(app.UIFigure, 'push');
            app.InputModelFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputModelFileSearchButtonPushed, true);
            app.InputModelFileSearchButton.Icon = fullfile(pathToMLAPP, 'Images', 'folderIcon.svg');
            app.InputModelFileSearchButton.Text = '';
            app.InputModelFileEditField = uieditfield(app.UIFigure, 'text');
            app.InputModelFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputModelFileEditFieldValueChanged, true);
            app.InputModelFileEditField.ValueChangingFcn = createCallbackFcn(app, @InputModelFileEditFieldValueChanging, true);
            app.JMPImage = uiimage(app.UIFigure);
            app.JMPImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'jmpFigure.png');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.JointModelPersonalizationToolLabel = uilabel(app.UIFigure);
            app.JointModelPersonalizationToolLabel.Text = 'Joint Model Personalization Tool';
            app.RcnlLogo = uiimage(app.UIFigure);
            app.RcnlLogo.ImageClickedFcn = createCallbackFcn(app, @RcnlLogoClicked, true);
            app.RcnlLogo.ImageSource = fullfile(pathToMLAPP, 'Images', 'rcnlIcon.png');
            app.JMPTasksListBoxLabel = uilabel(app.UIFigure);
            app.JMPTasksListBoxLabel.Text = 'JMP Tasks';
            app.JMPTasksListBox = uilistbox(app.UIFigure);
            app.JMPTasksListBox.Items = {''};
            app.JMPTasksListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JMPBase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end