classdef JMPJointSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CloseButton                matlab.ui.control.Button
        OKButton                   matlab.ui.control.Button
        BoundsradEditField_2       matlab.ui.control.NumericEditField
        BoundsradEditField_2Label  matlab.ui.control.Label
        BoundsradEditField         matlab.ui.control.NumericEditField
        BoundsradEditFieldLabel    matlab.ui.control.Label
        BoundsmEditField_2         matlab.ui.control.NumericEditField
        BoundsmEditField_2Label    matlab.ui.control.Label
        BoundsmEditField           matlab.ui.control.NumericEditField
        BoundsmEditFieldLabel      matlab.ui.control.Label
        ChildTransLabel            matlab.ui.control.Label
        ChildTransX                matlab.ui.control.CheckBox
        ChildTransY                matlab.ui.control.CheckBox
        ChildTransZ                matlab.ui.control.CheckBox
        ChildRotX                  matlab.ui.control.CheckBox
        ChildRotY                  matlab.ui.control.CheckBox
        ChildRotZ                  matlab.ui.control.CheckBox
        ChildRotLabel              matlab.ui.control.Label
        ChildFrameLabel            matlab.ui.control.Label
        ParentRotX                 matlab.ui.control.CheckBox
        ParentRotY                 matlab.ui.control.CheckBox
        ParentRotZ                 matlab.ui.control.CheckBox
        ParentRotLabel             matlab.ui.control.Label
        ParentTransLabel           matlab.ui.control.Label
        ParentTransZ               matlab.ui.control.CheckBox
        ParentTransY               matlab.ui.control.CheckBox
        ParentTransX               matlab.ui.control.CheckBox
        ParentFrameLabel           matlab.ui.control.Label
        JointNameDropDown          matlab.ui.control.DropDown
        JointNameDropDownLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        JMPTaskCreation
        JointName string;
        JointSet struct = struct("Attributes", [], "parent_frame_transformation", ...
            [], "child_frame_transformation", []);
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, JMPTaskCreation, JointSet)
            app.JMPTaskCreation = JMPTaskCreation;
            if isempty(JointSet)
                app.JointSet.Attributes.name = "";
                app.JointSet.parent_frame_transformation.translation =  ...
                    [false false false];
                app.JointSet.parent_frame_transformation.rotation = ...
                    [false false false];
                app.JointSet.child_frame_transformation.translation = ...
                    [false false false];
                app.JointSet.child_frame_transformation.rotation = ...
                    [false false false];
            else
                app.JointSet = JointSet;
            end

            modelJoints = app.JMPTaskCreation.getJMPModelJoints();
            app.JointNameDropDown.Items = modelJoints;
        end

        % Value changed function: JointNameDropDown
        function JointNameDropDownValueChanged(app, event)
            value = app.JointNameDropDown.Value;
            app.JointSet.Attributes.name = value;
        end

        % Value changed function: ParentTransX, ParentTransY, ParentTransZ
        function ParentTransZValueChanged(app, event)
            app.JointSet.parent_frame_transformation.translation = ...
                [app.ParentTransX.Value, app.ParentTransY.Value, ...
                app.ParentTransZ.Value];
        end

        % Value changed function: ParentRotX, ParentRotY, ParentRotZ
        function ParentRotZValueChanged(app, event)
            app.JointSet.parent_frame_transformation.rotation = ...
                [app.ParentRotX.Value, app.ParentRotY.Value, ...
                app.ParentRotZ.Value];
        end

        % Value changed function: ChildTransX, ChildTransY, ChildTransZ
        function ChildTransZValueChanged(app, event)
            app.JointSet.child_frame_transformation.translation = ...
                [app.ChildTransX.Value, app.ChildTransY.Value, ...
                app.ChildTransZ.Value];
        end

        % Value changed function: ChildRotX, ChildRotY, ChildRotZ
        function ChildRotZValueChanged(app, event)
            app.JointSet.child_frame_transformation.rotation = ...
                [app.ChildRotX.Value, app.ChildRotY.Value, ...
                app.ChildRotZ.Value];
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.JMPTaskCreation.addJoint(app.JointSet);

            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.JointNameDropDownLabel = uilabel(app.UIFigure);
            app.JointNameDropDownLabel.Text = 'Joint Name';
            app.JointNameDropDown = uidropdown(app.UIFigure);
            app.JointNameDropDown.Editable = 'on';
            app.JointNameDropDown.ValueChangedFcn = createCallbackFcn(app, @JointNameDropDownValueChanged, true);
            app.ParentFrameLabel = uilabel(app.UIFigure);
            app.ParentFrameLabel.Text = 'Parent Frame';
            app.ParentTransX = uicheckbox(app.UIFigure);
            app.ParentTransX.ValueChangedFcn = createCallbackFcn(app, @ParentTransZValueChanged, true);
            app.ParentTransX.Text = 'x';
            app.ParentTransY = uicheckbox(app.UIFigure);
            app.ParentTransY.ValueChangedFcn = createCallbackFcn(app, @ParentTransZValueChanged, true);
            app.ParentTransY.Text = 'y';
            app.ParentTransZ = uicheckbox(app.UIFigure);
            app.ParentTransZ.ValueChangedFcn = createCallbackFcn(app, @ParentTransZValueChanged, true);
            app.ParentTransZ.Text = 'z';
            app.ParentTransLabel = uilabel(app.UIFigure);
            app.ParentTransLabel.Text = 'Translation';
            app.ParentRotLabel = uilabel(app.UIFigure);
            app.ParentRotLabel.Text = 'Rotation';
            app.ParentRotZ = uicheckbox(app.UIFigure);
            app.ParentRotZ.ValueChangedFcn = createCallbackFcn(app, @ParentRotZValueChanged, true);
            app.ParentRotZ.Text = 'z';
            app.ParentRotY = uicheckbox(app.UIFigure);
            app.ParentRotY.ValueChangedFcn = createCallbackFcn(app, @ParentRotZValueChanged, true);
            app.ParentRotY.Text = 'y';
            app.ParentRotX = uicheckbox(app.UIFigure);
            app.ParentRotX.ValueChangedFcn = createCallbackFcn(app, @ParentRotZValueChanged, true);
            app.ParentRotX.Text = 'x';
            app.ChildFrameLabel = uilabel(app.UIFigure);
            app.ChildFrameLabel.Text = 'Child Frame';
            app.ChildRotLabel = uilabel(app.UIFigure);
            app.ChildRotLabel.Text = 'Rotation';
            app.ChildRotZ = uicheckbox(app.UIFigure);
            app.ChildRotZ.ValueChangedFcn = createCallbackFcn(app, @ChildRotZValueChanged, true);
            app.ChildRotZ.Text = 'z';
            app.ChildRotY = uicheckbox(app.UIFigure);
            app.ChildRotY.ValueChangedFcn = createCallbackFcn(app, @ChildRotZValueChanged, true);
            app.ChildRotY.Text = 'y';
            app.ChildRotX = uicheckbox(app.UIFigure);
            app.ChildRotX.ValueChangedFcn = createCallbackFcn(app, @ChildRotZValueChanged, true);
            app.ChildRotX.Text = 'x';
            app.ChildTransZ = uicheckbox(app.UIFigure);
            app.ChildTransZ.ValueChangedFcn = createCallbackFcn(app, @ChildTransZValueChanged, true);
            app.ChildTransZ.Text = 'z';
            app.ChildTransY = uicheckbox(app.UIFigure);
            app.ChildTransY.ValueChangedFcn = createCallbackFcn(app, @ChildTransZValueChanged, true);
            app.ChildTransY.Text = 'y';
            app.ChildTransX = uicheckbox(app.UIFigure);
            app.ChildTransX.ValueChangedFcn = createCallbackFcn(app, @ChildTransZValueChanged, true);
            app.ChildTransX.Text = 'x';
            app.ChildTransLabel = uilabel(app.UIFigure);
            app.ChildTransLabel.Text = 'Translation';
            app.BoundsmEditFieldLabel = uilabel(app.UIFigure);
            app.BoundsmEditFieldLabel.Text = 'Bounds (m)';
            app.BoundsmEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoundsmEditField.Value = 0.05;
            app.BoundsmEditField_2Label = uilabel(app.UIFigure);
            app.BoundsmEditField_2Label.Text = 'Bounds (m)';
            app.BoundsmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.BoundsmEditField_2.Value = 0.05;
            app.BoundsradEditFieldLabel = uilabel(app.UIFigure);
            app.BoundsradEditFieldLabel.Text = 'Bounds (rad)';
            app.BoundsradEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoundsradEditField.Value = 0.5;
            app.BoundsradEditField_2Label = uilabel(app.UIFigure);
            app.BoundsradEditField_2Label.Text = 'Bounds (rad)';
            app.BoundsradEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.BoundsradEditField_2.Value = 0.5;
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JMPJointSelection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end