classdef JMPTaskCreation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TimeRangeError             matlab.ui.control.Image
        MarkersFileError           matlab.ui.control.Image
        MarkersFileWarning         matlab.ui.control.Image
        BodiesListBox              matlab.ui.control.ListBox
        BodiesListBoxLabel         matlab.ui.control.Label
        JointsListBox              matlab.ui.control.ListBox
        JointsListBoxLabel         matlab.ui.control.Label
        MarkersTextAreaLabel       matlab.ui.control.Label
        MarkersTextArea            matlab.ui.control.TextArea
        EndTimeField               matlab.ui.control.NumericEditField
        ToLabel                    matlab.ui.control.Label
        StartTimeField             matlab.ui.control.NumericEditField
        TimeRangesEditFieldLabel   matlab.ui.control.Label
        MarkersFileEditField       matlab.ui.control.EditField
        MarkersFileEditFieldLabel  matlab.ui.control.Label
        TaskNameEditField          matlab.ui.control.EditField
        TaskNameEditFieldLabel     matlab.ui.control.Label
        DeleteBodyButton           matlab.ui.control.Button
        EditBodyButton             matlab.ui.control.Button
        DeleteJointButton          matlab.ui.control.Button
        EditJointButton            matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        AddBodyButton              matlab.ui.control.Button
        AddJointButton             matlab.ui.control.Button
        EditMarkersButton          matlab.ui.control.Button
        OKButton                   matlab.ui.control.Button
        MarkerFileSearchButton     matlab.ui.control.Button
        EnabledCheckBox            matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        TaskApp JMPTaskCreation;
        Task = struct();
        JointSet cell;
        BodySet cell;
        JointNames string;
        BodyNames string;
        editingFlag logical = false;
        indexEditingCurrent;
        markerFileMarkers string;
        ErrorFlag logical = false;
        minimumTime double;
        maximumTime double;
    end
    
    properties (Access = public)
        JMPBase;
    end
    
    methods (Access = public)

        function setSelectedObjects(app, objects)
            % Function to assign objects selected by ObjectSelectionWindow
            app.Task.marker_names = objects;
            app.MarkersTextArea.Value = strjoin(objects, " ");
            app.EnableActionsCallback()
        end

        function objects = getSelectedObjects(app)
            % Function to interface with ObjectSelectionWindow
            objects = app.Task.marker_names;
        end

        function jointSet = getJointSet(app)
            jointSet = app.JointSet;
        end

        function jointNames = getJointNames(app)
            jointNames = app.JointNames;
        end

        function bodyNames = getBodyNames(app)
            bodyNames = app.BodyNames;
        end
        
        function addJoint(app, joint)
            app.JointSet{end+1} = joint;
            app.JointNames(end+1) = joint.Attributes.name;
            app.JointsListBox.Items = app.JointNames;
            app.EnableActionsCallback()
        end

        function editJoint(app, joint)
            selectedJointIndex = app.JointsListBox.ValueIndex;
            app.JointSet{selectedJointIndex} = joint;
            app.JointNames(selectedJointIndex) = joint.Attributes.name;
            app.JointsListBox.Items = app.JointNames;
        end

        function deleteJoint(app, jointIndex)
            app.JointSet = app.JointSet(~jointIndex);
            app.JointNames = app.JointNames(~jointIndex);
            app.JointsListBox.Items = app.JointNames;
            app.EnableActionsCallback()
        end

        function bodySet = getJMPBodySet(app)
            bodySet = app.BodySet;
        end
        
        function addBody(app, bodySet)
            app.BodySet{end+1} = bodySet;
            app.BodyNames(end+1) = bodySet.Attributes.name;
            app.BodiesListBox.Items = app.BodyNames;
            app.EnableActionsCallback()
        end

        function editBody(app, body)
            selectedBodyIndex = app.BodiesListBox.ValueIndex;
            app.BodySet{selectedBodyIndex} = body;
            app.BodyNames(selectedBodyIndex) = body.Attributes.name;
            app.BodiesListBox.Items = app.BodyNames;
        end

        function deleteBody(app, bodyIndex)
            app.BodySet = app.BodySet(~bodyIndex);
            app.BodyNames = app.BodyNames(~bodyIndex);
            app.BodiesListBox.Items = app.BodyNames;
            app.EnableActionsCallback()
        end
    end
    
    methods (Access = private)

        function ErrorsCallback(app)
        app.ErrorFlag = false;

        % Validate marker file opens and markers are in the model
        [markerErrorFlag, markerErrorMessage] = parseMarkerFileForErrors(app);
        [markerWarningFlag, markerWarningMessage] = checkValidMarkers(app);
        if markerErrorFlag
            clearGuiError(app.MarkersFileEditField, app.MarkersFileWarning)
            clearGuiError(app.MarkersFileEditField, app.MarkersFileError)
            throwGuiError(markerErrorMessage, ...
            app.MarkersFileEditField, app.MarkersFileError)
            app.ErrorFlag = true;
        elseif markerWarningFlag
            clearGuiError(app.MarkersFileEditField, app.MarkersFileWarning)
            clearGuiError(app.MarkersFileEditField, app.MarkersFileError)
            throwGuiWarning(markerWarningMessage, ...
                app.MarkersFileEditField, app.MarkersFileWarning);
        else
            clearGuiError(app.MarkersFileEditField, app.MarkersFileWarning)
            clearGuiError(app.MarkersFileEditField, app.MarkersFileError)
        end
        
        % validate time range start < end
        if strcmp(app.StartTimeField.Enable, 'on') && ...
                (app.StartTimeField.Value >= app.EndTimeField.Value || ...
                app.EndTimeField.Value <= app.StartTimeField.Value)
            app.ErrorFlag = true;
            throwGuiError("Start time must be smaller than end time", ...
                app.StartTimeField, app.TimeRangeError);
            throwGuiError("Start time must be smaller than end time", ...
                app.EndTimeField, app.TimeRangeError);
        % validate time range is within marker file.
        elseif strcmp(app.StartTimeField.Enable, 'on') && ...
                (app.StartTimeField.Value < app.minimumTime)
            throwGuiError(sprintf("The minimum time in the markers " + ...
                "file is %f", app.minimumTime), ...
                app.StartTimeField, app.TimeRangeError);
            app.ErrorFlag = true;
        elseif strcmp(app.StartTimeField.Enable, 'on') && ...
                (app.EndTimeField.Value > app.maximumTime)
            throwGuiError(sprintf("The maximum time in the markers " + ...
                "file is %f", app.maximumTime), ...
                app.EndTimeField, app.TimeRangeError);
            app.ErrorFlag = true;
        else
            clearGuiError(app.StartTimeField, app.TimeRangeError);
            clearGuiError(app.EndTimeField, app.TimeRangeError)
        end

        app.EnableActionsCallback();
        end

        function EnableActionsCallback(app)
            if strcmp(app.Task.marker_file_name, "")
                app.StartTimeField.Enable = 'off';
                app.EndTimeField.Enable = 'off';
                app.EditMarkersButton.Enable = 'off';
                app.EditMarkersButton.Tooltip = "You must select a " + ...
                    "valid marker file beofre you can select markers.";
                
            else
                app.StartTimeField.Enable = 'on';
                app.EndTimeField.Enable = 'on';
                app.EditMarkersButton.Enable = 'on';
                app.EditMarkersButton.Tooltip = "";
            end
            if isempty(app.JointSet)
                app.EditJointButton.Enable = 'off';
                app.DeleteJointButton.Enable = 'off';
            else
                app.EditJointButton.Enable = 'on';
                app.DeleteJointButton.Enable = 'on';
            end
            if isempty(app.BodySet)
                app.EditBodyButton.Enable = 'off';
                app.DeleteBodyButton.Enable = 'off';
            else
                app.EditBodyButton.Enable = 'on';
                app.DeleteBodyButton.Enable = 'on';
            end
            
            if ~app.ErrorFlag && ... 
                    ~isempty(app.Task.marker_file_name) && ...
                        isfield(app.Task, "marker_names") && ...
                ~all(strcmp(app.Task.marker_names, "")) && ...
                (~isempty(app.BodySet) || ~isempty(app.JointSet))
                app.OKButton.Enable = 'on';
            else
                app.OKButton.Enable = 'off';
            end
        end
        
        function makeEmptyTask(app)
            app.Task.Attributes.name = "Default";
            app.Task.index = 0;
            app.Task.is_enabled = 'true';
            app.Task.time_range = [0 0];
            app.Task.marker_file_name = "";
            app.Task.marker_names = [""];
            app.Task.JointSet = {};
            app.Task.BodySet = {};
        end

        function loadTask(app, loadedTask)
            app.Task = loadedTask;
            app.parseMarkerFileData();
            app.JointSet = loadedTask.JointSet;
            app.BodySet = loadedTask.BodySet;
            app.TaskNameEditField.Value = loadedTask.Attributes.name;
            if strcmp(loadedTask.is_enabled, 'true')
                app.EnabledCheckBox.Value = true;
            else
                app.EnabledCheckBox.Value = false;
            end
            app.StartTimeField.Value = loadedTask.time_range(1);
            app.EndTimeField.Value = loadedTask.time_range(2);
            app.Task.time_range = loadedTask.time_range;
            app.MarkersFileEditField.Value = getRelativePath( ...
                loadedTask.marker_file_name);
            
            app.MarkersTextArea.Value = strjoin(loadedTask.marker_names, " ");
            if isfield(loadedTask, "JointSet")
                for i = 1 : numel(loadedTask.JointSet)
                    app.JointNames(i) = loadedTask.JointSet{i}.Attributes.name;
                end
            end
            app.JointsListBox.Items = app.JointNames;
            if isfield(loadedTask, "BodySet")
                for i = 1 : numel(loadedTask.BodySet)
                    app.BodyNames(i) = loadedTask.BodySet{i}.Attributes.name;
                end
            end
            app.BodiesListBox.Items = app.BodyNames;
            app.EnableActionsCallback()
            app.ErrorsCallback()
        end

        function [errorFlag, errorMessage] = parseMarkerFileForErrors(app)
            import org.opensim.modeling.TimeSeriesTableVec3
            errorFlag = false;
            errorMessage = "";
            if strcmp(app.Task.marker_file_name, "")
                return
            end
            if ~exist(app.Task.marker_file_name)
                errorFlag = true;
                errorMessage = "The given marker file does not exist.";
                app.Task.marker_file_name = "";
                return
            end
            try
                TimeSeriesTableVec3(app.Task.marker_file_name);
            catch
                errorFlag = true;
                errorMessage = "The given marker file exists but " + ...
                    "cannot be parsed by OpenSim.";
                app.Task.marker_file_name = "";
                return
            end
        end

        function parseMarkerFileData(app)
            import org.opensim.modeling.TimeSeriesTableVec3
            try
                timeSeriesTable = TimeSeriesTableVec3(app.Task.marker_file_name);
            catch
                return
            end
            numTimePoints = timeSeriesTable.getNumRows;
            timeVector = timeSeriesTable.getIndependentColumn;
            columnLabelsStringVector = char(timeSeriesTable.getColumnLabels);
            app.markerFileMarkers = strsplit( ...
                columnLabelsStringVector(2:end-1), ", ");

            startTime = double(timeVector.get(0));
            endTime = double(timeVector.get(numTimePoints-1));
            app.Task.time_range = ...
                [startTime endTime];

            app.StartTimeField.Value = double(app.Task.time_range(1));
            app.minimumTime = app.StartTimeField.Value;
            app.EndTimeField.Value = double(app.Task.time_range(2));
            app.maximumTime = app.EndTimeField.Value;
        end

        function [warningFlag, warningMessage] = checkValidMarkers(app)
            warningFlag = false;
            warningMessage = "";
            if strcmp(app.Task.marker_file_name, "")
                return
            end
            invalidMarkerIndices = ~contains(app.markerFileMarkers, ...
                app.JMPBase.getModelMarkers);
            if any(invalidMarkerIndices)
                invalidMarkers = app.markerFileMarkers(invalidMarkerIndices);
                warningFlag = true;
                warningMessage = sprintf("The following markers are " + ...
                    "in the given marker file but not the given .osim" + ...
                    " model: %s", strjoin(invalidMarkers, ", "));
            end
        end

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, JMPBase, JMPTask)
            app.JMPBase = JMPBase;
            app.Task.marker_file_name = "";
            if isempty(JMPTask)
                app.makeEmptyTask()
            else
                app.loadTask(JMPTask)
                app.editingFlag = true;
            end
            app.EnableActionsCallback()
        end

        % Value changed function: TaskNameEditField
        function TaskNameEditFieldValueChanged(app, event)
            taskName = app.TaskNameEditField.Value;
            app.Task.Attributes.name = convertStringsToChars(taskName);
        end

        % Value changed function: EnabledCheckBox
        function EnabledCheckBoxValueChanged(app, event)
            if app.EnabledCheckBox.Value
                app.Task.is_enabled = 'true';
            else
                app.Task.is_enabled = 'false';
            end
        end

        % Button pushed function: MarkerFileSearchButton
        function MarkerFileSearchButtonPushed(app, event)
        [file, path] = uigetfile('*.trc', "Select a trc marker file.");
        % User hit "Cancel"
        if isequal(file, 0)
            return
        end
  
        fullpath = fullfile(path, file);
    
        % Update the text field
        app.MarkersFileEditField.Value = getRelativePath(fullpath, pwd);
        app.Task.marker_file_name = fullpath;

        app.parseMarkerFileData();
        app.EnableActionsCallback();
        app.ErrorsCallback();
        end

        % Value changed function: MarkersFileEditField
        function MarkersFileEditFieldValueChanged(app, event)
        if strcmp(app.MarkersFileEditField.Value, "")
            app.Task.marker_file_name = app.MarkersFileEditField.Value;
        else
           app.Task.marker_file_name = getAbsolutePath( ...
                app.MarkersFileEditField.Value);
        end

        app.parseMarkerFileData();
        app.EnableActionsCallback();
        app.ErrorsCallback();
        app.EnableActionsCallback();
        end

        % Button pushed function: EditMarkersButton
        function EditMarkersButtonPushed(app, event)
            modelMarkers = app.JMPBase.getModelMarkers();
            ObjectSelectionWindow(app, modelMarkers, ...
                app.Task.marker_names);
        end

        % Button pushed function: AddJointButton
        function AddJointButtonPushed(app, event)
            JMPJointSelection(app, []);
        end

        % Button pushed function: EditJointButton
        function EditJointButtonPushed(app, event)
            selectedJointIndex = app.JointsListBox.ValueIndex;
            JMPJointSelection(app, app.JointSet{selectedJointIndex});
        end

        % Button pushed function: DeleteJointButton
        function DeleteJointButtonPushed(app, event)
            selectedJointIndex = app.JointsListBox.ValueIndex;
            indicesVector = 1:1:numel(app.JointsListBox.Items) == selectedJointIndex;
            app.deleteJoint(indicesVector);
            app.EnableActionsCallback();
        end

        % Button pushed function: AddBodyButton
        function AddBodyButtonPushed(app, event)
            JMPBodySelection(app, [])
        end

        % Button pushed function: EditBodyButton
        function EditBodyButtonPushed(app, event)
            selectedBodyIndex = app.BodiesListBox.ValueIndex;
            JMPBodySelection(app, app.BodySet{selectedBodyIndex});
        end

        % Button pushed function: DeleteBodyButton
        function DeleteBodyButtonPushed(app, event)
            selectedBodyIndex = app.BodiesListBox.ValueIndex;
            indicesVector = 1:1:numel(app.BodiesListBox.Items) == selectedBodyIndex;
            app.deleteBody(indicesVector);
            app.EnableActionsCallback();
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if ~isempty(app.JointSet)
                app.Task.JointSet = app.JointSet;
            end
            if ~isempty(app.BodySet)
                app.Task.BodySet = app.BodySet;
            end
            
            if ~app.editingFlag
                app.Task.index = numel(app.JMPBase.getTaskList)+1;
                app.JMPBase.addTask(app.Task);
            else
                app.JMPBase.editTask(app.Task)
            end

            delete(app.UIFigure);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Value changed function: StartTimeField
        function StartTimeFieldValueChanged(app, event)
            value = app.StartTimeField.Value;
            app.Task.time_range(1) = value;
            app.ErrorsCallback();
        end

        % Value changed function: EndTimeField
        function EndTimeFieldValueChanged(app, event)
            value = app.EndTimeField.Value;
            app.Task.time_range(2) = value;
            app.ErrorsCallback();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnabledCheckBox = uicheckbox(app.UIFigure);
            app.EnabledCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnabledCheckBoxValueChanged, true);
            app.EnabledCheckBox.Text = 'Enabled';
            app.EnabledCheckBox.Value = true;
            app.MarkerFileSearchButton = uibutton(app.UIFigure, 'push');
            app.MarkerFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerFileSearchButtonPushed, true);
            app.MarkerFileSearchButton.Icon = fullfile(pathToMLAPP, 'Images', 'folderIcon.svg');
            app.MarkerFileSearchButton.Text = '';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Enable = 'off';
            app.OKButton.Text = 'OK';
            app.EditMarkersButton = uibutton(app.UIFigure, 'push');
            app.EditMarkersButton.ButtonPushedFcn = createCallbackFcn(app, @EditMarkersButtonPushed, true);
            app.EditMarkersButton.Enable = 'off';
            app.EditMarkersButton.Text = 'Edit Markers';
            app.AddJointButton = uibutton(app.UIFigure, 'push');
            app.AddJointButton.ButtonPushedFcn = createCallbackFcn(app, @AddJointButtonPushed, true);
            app.AddJointButton.Text = 'Add Joint';
            app.AddBodyButton = uibutton(app.UIFigure, 'push');
            app.AddBodyButton.ButtonPushedFcn = createCallbackFcn(app, @AddBodyButtonPushed, true);
            app.AddBodyButton.Text = 'Add Body';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.EditJointButton = uibutton(app.UIFigure, 'push');
            app.EditJointButton.ButtonPushedFcn = createCallbackFcn(app, @EditJointButtonPushed, true);
            app.EditJointButton.Enable = 'off';
            app.EditJointButton.Text = 'Edit Joint';
            app.DeleteJointButton = uibutton(app.UIFigure, 'push');
            app.DeleteJointButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteJointButtonPushed, true);
            app.DeleteJointButton.Enable = 'off';
            app.DeleteJointButton.Text = 'Delete Joint';
            app.EditBodyButton = uibutton(app.UIFigure, 'push');
            app.EditBodyButton.ButtonPushedFcn = createCallbackFcn(app, @EditBodyButtonPushed, true);
            app.EditBodyButton.Enable = 'off';
            app.EditBodyButton.Text = 'Edit Body';
            app.DeleteBodyButton = uibutton(app.UIFigure, 'push');
            app.DeleteBodyButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteBodyButtonPushed, true);
            app.DeleteBodyButton.Enable = 'off';
            app.DeleteBodyButton.Text = 'Delete Body';
            app.TaskNameEditFieldLabel = uilabel(app.UIFigure);
            app.TaskNameEditFieldLabel.Text = 'Task Name';
            app.TaskNameEditField = uieditfield(app.UIFigure, 'text');
            app.TaskNameEditField.ValueChangedFcn = createCallbackFcn(app, @TaskNameEditFieldValueChanged, true);
            app.TaskNameEditField.Value = 'Default';
            app.MarkersFileEditFieldLabel = uilabel(app.UIFigure);
            app.MarkersFileEditFieldLabel.Text = 'Markers File';
            app.MarkersFileEditField = uieditfield(app.UIFigure, 'text');
            app.MarkersFileEditField.ValueChangedFcn = createCallbackFcn(app, @MarkersFileEditFieldValueChanged, true);
            app.TimeRangesEditFieldLabel = uilabel(app.UIFigure);
            app.TimeRangesEditFieldLabel.Text = 'Time Range (s):';
            app.StartTimeField = uieditfield(app.UIFigure, 'numeric');
            app.StartTimeField.ValueChangedFcn = createCallbackFcn(app, @StartTimeFieldValueChanged, true);
            app.StartTimeField.Enable = 'off';
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To:';
            app.EndTimeField = uieditfield(app.UIFigure, 'numeric');
            app.EndTimeField.ValueChangedFcn = createCallbackFcn(app, @EndTimeFieldValueChanged, true);
            app.EndTimeField.Enable = 'off';
            app.MarkersTextArea = uitextarea(app.UIFigure);
            app.MarkersTextArea.Editable = 'off';
            app.MarkersTextAreaLabel = uilabel(app.UIFigure);
            app.MarkersTextAreaLabel.Text = 'Markers';
            app.JointsListBoxLabel = uilabel(app.UIFigure);
            app.JointsListBoxLabel.Text = 'Joints';
            app.JointsListBox = uilistbox(app.UIFigure);
            app.JointsListBox.Items = {''};
            app.JointsListBox.Value = {};
            app.BodiesListBoxLabel = uilabel(app.UIFigure);
            app.BodiesListBoxLabel.Text = 'Bodies';
            app.BodiesListBox = uilistbox(app.UIFigure);
            app.BodiesListBox.Items = {};
            app.BodiesListBox.Value = {};
            app.MarkersFileWarning = uiimage(app.UIFigure);
            app.MarkersFileWarning.Visible = 'off';
            app.MarkersFileWarning.ImageSource = fullfile(pathToMLAPP, 'Images', 'warning.png');
            app.MarkersFileError = uiimage(app.UIFigure);
            app.MarkersFileError.Visible = 'off';
            app.MarkersFileError.ImageSource = fullfile(pathToMLAPP, 'Images', 'error.png');
            app.TimeRangeError = uiimage(app.UIFigure);
            app.TimeRangeError.Visible = 'off';
            app.TimeRangeError.ImageSource = fullfile(pathToMLAPP, 'Images', 'error.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JMPTaskCreation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end