classdef MTPBase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MTPTasksButton                  matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        LoadSettingsFileButton          matlab.ui.control.Button
        RcnlLogo                        matlab.ui.control.Image
        InputsButton                    matlab.ui.control.Button
        AdvancedButton                  matlab.ui.control.Button
        AuxiliaryToolsButton            matlab.ui.control.Button
        MuscleGroupsButton              matlab.ui.control.Button
        MTPImage                        matlab.ui.control.Image
        Mask1                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        TaskPrefixesError               matlab.ui.control.Image
        TaskPrefixesWarning             matlab.ui.control.Image
        TaskPrefixesEditField           matlab.ui.control.EditField
        TaskPrefixesEditFieldLabel      matlab.ui.control.Label
        CoordinateListEditButton        matlab.ui.control.Button
        CoordinateListWarning           matlab.ui.control.Image
        CoordinatesListTextAreaLabel    matlab.ui.control.Label
        CoordinatesListTextArea         matlab.ui.control.TextArea
        InputDataDirectoryEditFieldLabel  matlab.ui.control.Label
        ResultsDirectoryError           matlab.ui.control.Image
        ResultsDirectoryEditField       matlab.ui.control.EditField
        ResultsDirectoryEditFieldLabel  matlab.ui.control.Label
        ResultsDirectorySearchButton    matlab.ui.control.Button
        InputDataError                  matlab.ui.control.Image
        InputDataEditField              matlab.ui.control.EditField
        InputDataSearchButton           matlab.ui.control.Button
        InputOsimxFileWarning           matlab.ui.control.Image
        InputOsimxFileEditField         matlab.ui.control.EditField
        InputOsimxFileEditFieldLabel    matlab.ui.control.Label
        InputOsimxFileSearchButton      matlab.ui.control.Button
        InputModelFileError             matlab.ui.control.Image
        InputModelFileEditField         matlab.ui.control.EditField
        InputModelFileEditFieldLabel    matlab.ui.control.Label
        InputModelFileSearchButton      matlab.ui.control.Button
        MuscleGroupsTab                 matlab.ui.container.Tab
        EditCollectedEmgGroupsButton    matlab.ui.control.Button
        EditMissingEmgGroupsButton      matlab.ui.control.Button
        EditFiberLengthGroupsButton     matlab.ui.control.Button
        EditActivationGroupsButton      matlab.ui.control.Button
        ActivationMuscleGroupsTextArea  matlab.ui.control.TextArea
        ActivationMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        CollectedEMGMuscleGroupsTextArea  matlab.ui.control.TextArea
        CollectedEMGMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        MissingEMGMuscleGroupsTextArea  matlab.ui.control.TextArea
        MissingEMGMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        NormalizedFiberLengthMuscleGroupsTextArea  matlab.ui.control.TextArea
        NormalizedFiberLengthMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        MTPTasksTab                     matlab.ui.container.Tab
        MoveTaskDownButton              matlab.ui.control.Button
        MoveTaskUpButton                matlab.ui.control.Button
        TasksTable                      matlab.ui.control.Table
        MuscleGroupsLabel               matlab.ui.control.Label
        DesignVariablesTable            matlab.ui.control.Table
        EditDesignVariablesLabel        matlab.ui.control.Label
        EditCostTermsLabel_2            matlab.ui.control.Label
        MTPTasksCostTermPanel           matlab.ui.container.Panel
        MTPTasksCostTermsTable          matlab.ui.control.Table
        MTPTasksErrorCenterEditField    matlab.ui.control.NumericEditField
        ErrorCenterEditField_2Label     matlab.ui.control.Label
        MTPTasksMaxAllowableErrorEditField  matlab.ui.control.NumericEditField
        MaxAllowableErrorEditField_2Label  matlab.ui.control.Label
        AuxiliaryTab                    matlab.ui.container.Tab
        AdvancedSettingsLabel           matlab.ui.control.Label
        OptimizationParamsTable         matlab.ui.control.Table
        EditCostTermsLabel              matlab.ui.control.Label
        CostTermPanel                   matlab.ui.container.Panel
        CostTermsTable                  matlab.ui.control.Table
        AuxErrorCenterEditField         matlab.ui.control.NumericEditField
        ErrorCenterEditFieldLabel       matlab.ui.control.Label
        AuxMaxAllowableErrorEditField   matlab.ui.control.NumericEditField
        MaxAllowableErrorEditFieldLabel  matlab.ui.control.Label
        SynxButton                      matlab.ui.control.Button
        MTLIButton                      matlab.ui.control.Button
        AuxiliaryToolsTab               matlab.ui.container.TabGroup
        MuscleTendonLengthInitializationTab  matlab.ui.container.Tab
        MaxNormalizedFiberLengthEditField  matlab.ui.control.NumericEditField
        MaxNormalizedFiberLengthEditFieldLabel  matlab.ui.control.Label
        MinNormalizedFiberLengthEditField  matlab.ui.control.NumericEditField
        MinNormalizedFiberLengthEditFieldLabel  matlab.ui.control.Label
        PassiveDataDirectoryEditField   matlab.ui.control.EditField
        PassiveDataDirectoryEditFieldLabel  matlab.ui.control.Label
        PassiveDataDirectorySearchButton  matlab.ui.control.Button
        InputModelFileError_2           matlab.ui.control.Image
        EnableMTLICheckBox              matlab.ui.control.CheckBox
        SynergyExtrapolationTab         matlab.ui.container.Tab
        NumSynergiesSpinnerLabel        matlab.ui.control.Label
        NumSynergiesSpinner             matlab.ui.control.Spinner
        EnableSynergyExtrapolationCheckBox  matlab.ui.control.CheckBox
        PassiveDataInputDirectoryEditFieldLabel  matlab.ui.control.Label
        AdvancedTab                     matlab.ui.container.Tab
        MuscletendonModelPersonalizationToolLabel  matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        RenameMenu                      matlab.ui.container.Menu
        CopyMenu                        matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
    end

    
    properties (Access = private, SetObservable)
        model_coordinates string = [];
        model_groups string = [];
        
        input_model_file string = "";
        input_osimx_file string = "";
        data_directory string = "";
        results_directory string = "";
        coordinate_list string = [];

        activation_muscle_groups string = [];
        normalized_fiber_length_muscle_groups string = [];
        missing_emg_channel_muscle_groups string = [];
        collected_emg_channel_muscle_groups string = [];

        MTPTask cell = cell(0)
        taskIndex = 1;

        designVariables string = ["Muscle Specific Electromechanical Delays"
            "Electromechanical Delays"
            "Activation Time Constants"
            "Activation Non-linearity Constants"
            "EMG Scale Factors"
            "Optimal Fiber Lengths"
            "Tendon Slack Lengths"];
        
        MuscleTendonLengthInitialization handle = ...
            MuscleTendonLengthInitializationClass();


        MTPSynergyExtrapolation handle = ...
            SynergyExtrapolationClass();


        objectSelectionType string = "";  % Used to filter in setSelectedObjects
        ErrorFlag logical = false;
        currentSettingsFile string; % Variable to store the current settings file name.
        needToSave logical = false; % flag indicating if the settings file changed and needs to be saved before running.
        modelErrorFlag logical = false;
        osimxWarningFlag logical = false;
        dataDirectoryErrorFlag logical = false;

        timePoints double = [];
        idLabels string = [];
        emgLabels string = [];

    end % Description
    
    properties (Access = public)
        accuracy double = 1e-6;
        diff_min_change double = 0.001;
        optimality_tolerance double = 1e-5;
        function_tolerance double = 1e-5;
        step_tolerance double = 1e-5;
        max_function_evaluations double = 200;
    end
    properties(Access = private)  % listner properties
        InputModelFileListener
        OsimXFileListener
        DataDirectoryListener
        ResultsDirectoryListener
        CoordinateListListener
        ActivationGroupsListener
        FiberLengthGroupsListener
        MissingEmgGroupsListener
        CollectedEmgGroupsListener
        PassiveDataDirectoryListener
        taskIndexListener
    end

    methods (Access = private) % listener methods
        function makeListeners(app)
            app.InputModelFileListener = addlistener(app, ...
                'input_model_file', 'PostSet', ...
                @(src,event)InputModelFileListenerFunction(app));

            app.OsimXFileListener = addlistener(app, ...
                'input_osimx_file', 'PostSet', ...
                @(src,event)OsimXFileListenerFunction(app));

            app.DataDirectoryListener = addlistener(app, ...
                'data_directory', 'PostSet', ...
                @(src,event)DataDirectoryListenerFunction(app));

            app.ResultsDirectoryListener = addlistener(app, ...
                'results_directory', 'PostSet', ...
                @(src,event)ResultsDirectoryListenerFunction(app));

            app.CoordinateListListener = addlistener(app, ...
                'coordinate_list', 'PostSet', ...
                @(src,event)CoordinateListListenerFunction(app));

            app.ActivationGroupsListener = addlistener(app, ...
                'activation_muscle_groups', 'PostSet', ...
                @(src,event)ActivationGroupsListenerFunction(app));

            app.FiberLengthGroupsListener = addlistener(app, ...
                'normalized_fiber_length_muscle_groups', 'PostSet', ...
                @(src,event)FiberLengthGroupsListenerFunction(app));

            app.MissingEmgGroupsListener = addlistener(app, ...
                'missing_emg_channel_muscle_groups', 'PostSet', ...
                @(src,event)MissingEmgGroupsListenerFunction(app));

            app.CollectedEmgGroupsListener = addlistener(app, ...
                'collected_emg_channel_muscle_groups', 'PostSet', ...
                @(src,event)CollectedEmgGroupsListenerFunction(app));

            app.PassiveDataDirectoryListener = addlistener(...
                app.MuscleTendonLengthInitialization, ...
                'passive_data_input_directory', 'PostSet', ...
                @(src,event)PassiveDataDirectoryListenerFunction(app));

            app.taskIndexListener = addlistener(app, ...
                'taskIndex', 'PostSet', ...
                @(src, event)updateTaskIndex(app));
        end

        function InputModelFileListenerFunction(app)
            app.InputModelFileEditField.Value = getRelativePath( ...
                app.input_model_file);
            parseModelFileGui(app, app.input_model_file);
            app.needToSave = true;
        end

        function OsimXFileListenerFunction(app)
            app.InputOsimxFileEditField.Value = getRelativePath( ...
                app.input_osimx_file);
            app.needToSave = true;
        end

        function DataDirectoryListenerFunction(app)
            app.InputDataEditField.Value = getRelativePath( ...
                app.data_directory);
            parseMtpDataDirectoryGui(app, app.data_directory)
            app.needToSave = true;
        end

        function ResultsDirectoryListenerFunction(app)
            app.ResultsDirectoryEditField.Value = getRelativePath( ...
                app.results_directory);
            app.needToSave = true;
        end

        function CoordinateListListenerFunction(app)
            app.CoordinatesListTextArea.Value =  ...
                strjoin(app.coordinate_list, ", ");
            app.needToSave = true;
        end

        function ActivationGroupsListenerFunction(app)
            app.ActivationMuscleGroupsTextArea.Value = ...
                strjoin(app.activation_muscle_groups, ", ");
            app.needToSave;
        end

        function FiberLengthGroupsListenerFunction(app)
            app.NormalizedFiberLengthMuscleGroupsTextArea.Value = ...
                strjoin(app.normalized_fiber_length_muscle_groups, ", ");
            app.needToSave;
        end

        function MissingEmgGroupsListenerFunction(app)
            app.MissingEMGMuscleGroupsTextArea.Value = ...
                strjoin(app.missing_emg_channel_muscle_groups, ", ");
            app.needToSave;
        end

        function CollectedEmgGroupsListenerFunction(app)
            app.CollectedEMGMuscleGroupsTextArea.Value = ...
                strjoin(app.collected_emg_channel_muscle_groups, ", ");
            app.needToSave;
        end

        function PassiveDataDirectoryListenerFunction(app)
            app.PassiveDataInputDirectoryEditField.Value = ...
                getRelativePath(app.MuscleTendonLengthInitialization. ...
                passive_data_input_directory);
            app.needToSave;
        end

        function createDefaultTask(app)
            app.MTPTask{end+1} = MTPTaskClass();
            app.MTPTask{end}.makeDefaultCostTermSet();
            app.MTPTask{end}.name = "Task " + num2str(length(app.MTPTask));
            app.taskIndex = length(app.MTPTask);
            app.MTPTask{end}.index = app.taskIndex;
            app.MTPTasksCostTermsTable.Selection = [];
            app.DesignVariablesTable.Selection = [];
            app.updateMTPTasksListBox()
            app.updateDesignVariablesTable()
            app.MTPTasksMaxAllowableErrorEditField.Value = [];
            app.MTPTasksErrorCenterEditField.Value = [];
            app.updateMTPCostTermsTable()
        end

        function deleteTask(app, taskIndex)
            app.MTPTask = app.MTPTask(~taskIndex);
            for i = 1 : length(app.MTPTask)
                app.MTPTask{i}.index = i;
            end
            if isempty(app.MTPTask)
                app.MTPTask = [];
                return
            end
            if app.taskIndex > 1
                app.taskIndex = app.taskIndex - 1;
            end
            app.needToSave = true;
            app.updateMTPTasksListBox();
        end

        function moveTaskUp(app)
            if app.taskIndex == 1 || ...
                    app.taskIndex >= height(app.TasksTable.Data)
                return
            end

            indicesList = 1:1:length(app.MTPTask);

            indicesList(app.taskIndex) = app.taskIndex-1;
            indicesList(app.taskIndex-1) = app.taskIndex;
            app.MTPTask{app.taskIndex}.index = app.taskIndex-1;
            app.MTPTask{app.taskIndex-1}.index = app.taskIndex;
            app.MTPTask = {app.MTPTask{indicesList}};
            app.taskIndex = app.taskIndex-1;
            app.updateMTPTasksListBox();
        end

        function moveTaskDown(app)
            if app.taskIndex == height(app.TasksTable.Data)-1 || ...
                    app.taskIndex >= height(app.TasksTable.Data)
                return
            end

            indicesList = 1:1:length(app.MTPTask);
            indicesList(app.taskIndex) = app.taskIndex+1;
            indicesList(app.taskIndex+1) = app.taskIndex;
            app.MTPTask{app.taskIndex}.index = app.taskIndex+1;
            app.MTPTask{app.taskIndex+1}.index = app.taskIndex;
            app.MTPTask = {app.MTPTask{indicesList}};
            app.taskIndex = app.taskIndex+1;
            app.updateMTPTasksListBox();
        end

        function updateTaskIndex(app)
            app.TasksTable.Selection = app.taskIndex;
            app.updateMTPTasksListBox()
            app.updateDesignVariablesTable()
            app.updateMTPCostTermsTable()
        end

        function updateMTPTasksListBox(app)
            taskNames = strings(length(app.MTPTask)+1, 1);
            isEnabled = true(size(taskNames));
            for i = 1 : length(app.MTPTask)
                taskNames(i) = app.MTPTask{i}.name;
                isEnabled(i) = strcmp(app.MTPTask{i}.is_enabled, "true");
            end
            isEnabled(end) = false;
            taskNames(end) = "Add a new task";
            app.TasksTable.Data = table(isEnabled, taskNames);
        end

        function updateDesignVariablesTable(app)
            isEnabled = false(length(app.designVariables), 1);
            if strcmp(app.MTPTask{app.taskIndex}.muscle_specific_electromechanical_delays, "true")
                isEnabled(1) = true;
            end
            if strcmp(app.MTPTask{app.taskIndex}.optimize_electromechanical_delays, "true")
                isEnabled(2) = true;
            end
            if strcmp(app.MTPTask{app.taskIndex}.optimize_activation_time_constants, "true")
                isEnabled(3) = true;
            end
            if strcmp(app.MTPTask{app.taskIndex}.optimize_activation_nonlinearity_constants, "true")
                isEnabled(4) = true;
            end
            if strcmp(app.MTPTask{app.taskIndex}.optimize_emg_scale_factors, "true")
                isEnabled(5) = true;
            end
            if strcmp(app.MTPTask{app.taskIndex}.optimize_optimal_fiber_lengths, "true")
                isEnabled(6) = true;
            end
            if strcmp(app.MTPTask{app.taskIndex}.optimize_tendon_slack_lengths, "true")
                isEnabled(7) = true;
            end
            app.DesignVariablesTable.Data = table(isEnabled, app.designVariables);
        end

        function updateMTPCostTermsTable(app)
            isEnabled = true(length(app.MTPTask{app.taskIndex}.RCNLCostTerm), 1);
            costTermTypes = strings(length(app.MTPTask{app.taskIndex}.RCNLCostTerm), 1);
            for i = 1 : length(app.MTPTask{app.taskIndex}.RCNLCostTerm)
                isEnabled(i) = strcmp(app.MTPTask{app.taskIndex}.RCNLCostTerm{i}.is_enabled, "true");
                costTermTypes(i) = app.MTPTask{app.taskIndex}.RCNLCostTerm{i}.type;
            end
            app.MTPTasksCostTermsTable.Data = table(isEnabled, costTermTypes);
        end

        function formatTabButtons(app) % good
            switch app.TabGroup.SelectedTab
                case app.InputsTab
                    app.InputsButton.BackgroundColor = [1 1 1];
                    app.InputsButton.FontColor = [0.13,0.18,0.40];
                    
                    app.MuscleGroupsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.MuscleGroupsButton.FontColor = [1 1 1];

                    app.MTPTasksButton.BackgroundColor = [0.13,0.18,0.40];
                    app.MTPTasksButton.FontColor = [1 1 1];

                    app.AuxiliaryToolsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AuxiliaryToolsButton.FontColor = [1 1 1];

                    app.AdvancedButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AdvancedButton.FontColor = [1 1 1];

                case app.MuscleGroupsTab
                    app.InputsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.InputsButton.FontColor = [1 1 1];
                    
                    app.MuscleGroupsButton.BackgroundColor = [1 1 1];
                    app.MuscleGroupsButton.FontColor = [0.13,0.18,0.40];

                    app.AuxiliaryToolsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AuxiliaryToolsButton.FontColor = [1 1 1];

                    app.AdvancedButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AdvancedButton.FontColor = [1 1 1];

                case app.MTPTasksTab
                    app.InputsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.InputsButton.FontColor = [1 1 1];
                    
                    app.MuscleGroupsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.MuscleGroupsButton.FontColor = [1 1 1];

                    app.MTPTasksButton.BackgroundColor = [1 1 1];
                    app.MTPTasksButton.FontColor = [0.13,0.18,0.40];

                    app.AuxiliaryToolsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AuxiliaryToolsButton.FontColor = [1 1 1];

                    app.AdvancedButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AdvancedButton.FontColor = [1 1 1];

                case app.AuxiliaryTab
                    app.InputsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.InputsButton.FontColor = [1 1 1];
                    
                    app.MuscleGroupsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.MuscleGroupsButton.FontColor = [1 1 1];

                    app.MTPTasksButton.BackgroundColor = [0.13,0.18,0.40];
                    app.MTPTasksButton.FontColor = [1 1 1];

                    app.AuxiliaryToolsButton.BackgroundColor = [1 1 1];
                    app.AuxiliaryToolsButton.FontColor = [0.13,0.18,0.40];

                    app.AdvancedButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AdvancedButton.FontColor = [1 1 1];

                case app.AdvancedTab
                    app.InputsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.InputsButton.FontColor = [1 1 1];
                    
                    app.MuscleGroupsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.MuscleGroupsButton.FontColor = [1 1 1];

                    app.MTPTasksButton.BackgroundColor = [0.13,0.18,0.40];
                    app.MTPTasksButton.FontColor = [1 1 1];

                    app.AuxiliaryToolsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AuxiliaryToolsButton.FontColor = [1 1 1];

                    app.AdvancedButton.BackgroundColor = [1 1 1];
                    app.AdvancedButton.FontColor = [0.13,0.18,0.40];
            end

        end
        end
    
    methods (Access = private)
        
        function ErrorsCallback(app)
        end

        function EnableActionsCallback(app)
        end
    end
    
    methods (Access = public)


        function setSelectedObjects(app, objects)
            % Function to assign objects selected by ObjectSelectionWindow
        switch app.objectSelectionType
            case 'coordinate'
                app.coordinate_list = objects;
            case 'activationGroup'
                app.activation_muscle_groups = objects;
            case 'fiberLengthGroup'
                app.normalized_fiber_length_muscle_groups = objects;
            case 'missingEmgGroup'
                app.missing_emg_channel_muscle_groups = objects;
            case 'collectedEmgGroup'
                app.collected_emg_channel_muscle_groups = objects;
        end
            app.EnableActionsCallback()
        end
        
        function modelFile = getInputModelFile(app)
            modelFile = app.input_model_file;
        end

        function model_coordinates = getModelCoordinate(app)
            model_coordinates = app.model_coordinates;
        end

        function setModelCoordinates(app, coordinates)
            app.model_coordinates = coordinates;
        end

        function setModelGroups(app, groups)
            app.model_groups = groups;
        end
        
        function setTimePoints(app, timePoints)
            app.timePoints = timePoints;
        end

        function setIDLabels(app, idLabels)
            app.idLabels = idLabels;
        end
        
        function setEmgLabels(app, emgLabels)
            app.emgLabels = emgLabels;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.createDefaultTask();
            app.makeListeners()
            app.formatTabButtons()
        end

        % Button pushed function: InputsButton
        function InputsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.InputsTab;
            app.formatTabButtons()
        end

        % Button pushed function: MTPTasksButton
        function MTPTasksButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.MTPTasksTab;
            app.formatTabButtons()
        end

        % Button pushed function: AdvancedButton
        function AdvancedButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AdvancedTab;
            app.formatTabButtons()
        end

        % Button pushed function: AuxiliaryToolsButton
        function AuxiliaryToolsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AuxiliaryTab;
            app.formatTabButtons()
        end

        % Button pushed function: MuscleGroupsButton
        function MuscleGroupsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.MuscleGroupsTab;
            app.formatTabButtons()
        end

        % Button pushed function: InputModelFileSearchButton
        function InputModelFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osim', "Select an OpenSim Model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.input_model_file = fullfile(path, file);
        end

        % Button pushed function: InputOsimxFileSearchButton
        function InputOsimxFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osimx', "Select an Osimx model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.input_osimx_file = fullfile(path, file);
        end

        % Button pushed function: ResultsDirectorySearchButton
        function ResultsDirectorySearchButtonPushed(app, event)
            folder = uigetdir("Select a folder to store your results");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.results_directory = folder;
        end

        % Button pushed function: InputDataSearchButton
        function InputDataSearchButtonPushed(app, event)
            folder = uigetdir("Select your input data folder");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.data_directory = folder;
        end

        % Button pushed function: CoordinateListEditButton
        function CoordinateListEditButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_coordinates, ...
                app.coordinate_list)
            app.objectSelectionType = 'coordinate';
        end

        % Value changed function: InputModelFileEditField
        function InputModelFileEditFieldValueChanged(app, event)
            app.input_model_file = GetFullPath( ...
                app.InputModelFileEditField.Value);
        end

        % Value changed function: InputOsimxFileEditField
        function InputOsimxFileEditFieldValueChanged(app, event)
            app.input_osimx_file = GetFullPath( ...
                app.InputOsimxFileEditField.Value);
        end

        % Value changed function: InputDataEditField
        function InputDataEditFieldValueChanged(app, event)
            app.data_directory = GetFullPath( ...
                app.InputDataEditField.Value);
        end

        % Value changed function: ResultsDirectoryEditField
        function ResultsDirectoryEditFieldValueChanged(app, event)
            app.results_directory = GetFullPath( ...
                app.ResultsDirectoryEditField.Value);
        end

        % Button pushed function: EditActivationGroupsButton
        function EditActivationGroupsButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_groups, ...
                app.activation_muscle_groups)
            app.objectSelectionType = 'activationGroup';
        end

        % Button pushed function: EditFiberLengthGroupsButton
        function EditFiberLengthGroupsButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_groups, ...
                app.normalized_fiber_length_muscle_groups)
            app.objectSelectionType = 'fiberLengthGroup';
        end

        % Button pushed function: EditMissingEmgGroupsButton
        function EditMissingEmgGroupsButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_groups, ...
                app.missing_emg_channel_muscle_groups)
            app.objectSelectionType = 'missingEmgGroup';
        end

        % Button pushed function: EditCollectedEmgGroupsButton
        function EditCollectedEmgGroupsButtonPushed(app, event)
            %% should read directly from EMG file
            
            ObjectSelectionWindow(app, app.emgLabels, ...
                app.collected_emg_channel_muscle_groups)
            app.objectSelectionType = 'collectedEmgGroup';
        end

        % Callback function
        function PasssiveDataSearchButtonPushed(app, event)
            folder = uigetdir("Select your passive data folder");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.passive_data_input_directory = folder;
        end

        % Callback function
        function PassiveDataInputDirectoryEditFieldValueChanged(app, event)
            app.passive_data_input_directory = GetFullPath( ...
                app.PassiveDataInputDirectoryEditField.Value);
        end

        % Value changed function: EnableMTLICheckBox
        function EnableMTLICheckBoxValueChanged(app, event)
            if app.EnableMTLICheckBox.Value
                app.MTLIEnabled = "true";
            else
                app.MTLIEnabled = "false";
            end
            
        end

        % Value changed function: EnableSynergyExtrapolationCheckBox
        function EnableSynergyExtrapolationCheckBoxValueChanged(app, event)
            if app.EnableSynergyExtrapolationCheckBox.Value
                app.SynxEnabled = "true";
            else
                app.SynxEnabled = "false";
            end
            
        end

        % Value changed function: NumSynergiesSpinner
        function NumSynergiesSpinnerValueChanged(app, event)
            app.number_of_synergies = app.NumSynergiesSpinner.Value;
        end

        % Button pushed function: LoadSettingsFileButton
        function LoadSettingsFileButtonPushed(app, event)
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web("https://nmsm.rice.edu/guides-and-publications/tool-overviews/model-personalization/muscle-tendon-personalization/")
        end

        % Image clicked function: RcnlLogo
        function RcnlLogoImageClicked(app, event)
            web("https://nmsm.rice.edu/")
        end

        % Cell edit callback: DesignVariablesTable
        function DesignVariablesTableCellEdit(app, event)
            indices = event.Indices;
            isEnabled = app.DesignVariablesTable.Data.isEnabled(indices(1));
            switch indices(1)
                case 1
                    if isEnabled 
                        app.MTPTask{app.taskIndex}.muscle_specific_electromechanical_delays = 'true';
                    else
                        app.MTPTask{app.taskIndex}.muscle_specific_electromechanical_delays = 'false';
                    end
                case 2
                    if isEnabled 
                        app.MTPTask{app.taskIndex}.optimize_electromechanical_delays = 'true';
                    else
                        app.MTPTask{app.taskIndex}.optimize_electromechanical_delays = 'false';
                    end
                case 3
                    if isEnabled 
                        app.MTPTask{app.taskIndex}.optimize_activation_time_constants = 'true';
                    else
                        app.MTPTask{app.taskIndex}.optimize_activation_time_constants = 'false';
                    end
                case 4
                    if isEnabled 
                        app.MTPTask{app.taskIndex}.optimize_activation_nonlinearity_constants = 'true';
                    else
                        app.MTPTask{app.taskIndex}.optimize_activation_nonlinearity_constants = 'false';
                    end
                case 5
                    if isEnabled 
                        app.MTPTask{app.taskIndex}.optimize_emg_scale_factors = 'true';
                    else
                        app.MTPTask{app.taskIndex}.optimize_emg_scale_factors = 'false';
                    end
                case 6
                    if isEnabled 
                        app.MTPTask{app.taskIndex}.optimize_optimal_fiber_lengths = 'true';
                    else
                        app.MTPTask{app.taskIndex}.optimize_optimal_fiber_lengths = 'false';
                    end
                case 7
                    if isEnabled 
                        app.MTPTask{app.taskIndex}.optimize_tendon_slack_lengths = 'true';
                    else
                        app.MTPTask{app.taskIndex}.optimize_tendon_slack_lengths = 'false';
                    end
            end
            
        end

        % Cell edit callback: MTPTasksCostTermsTable
        function MTPTasksCostTermsTableCellEdit(app, event)
            indices = event.Indices;
            isEnabled = app.MTPTasksCostTermsTable.Data.isEnabled(indices(1));
            if isEnabled
                app.MTPTask{app.taskIndex}.RCNLCostTermSet. ...
                    RCNLCostTerm{indices(1)}.is_enabled = 'true';
            else
                app.MTPTask{app.taskIndex}.RCNLCostTermSet. ...
                    RCNLCostTerm{indices(1)}.is_enabled = 'false';
            end
        end

        % Selection changed function: TasksTable
        function TasksTableSelectionChanged(app, event)
            if isempty(app.TasksTable.Selection)
                return
            end

            if app.TasksTable.Selection == height(app.TasksTable.Data) % if last element
                app.createDefaultTask();
                return
            else
                app.taskIndex = app.TasksTable.Selection;
            end
        end

        % Selection changed function: MTPTasksCostTermsTable
        function MTPTasksCostTermsTableSelectionChanged(app, event)
            costTermIndex = app.MTPTasksCostTermsTable.Selection;
            app.MTPTasksErrorCenterEditField.Value = ...
                app.MTPTask{app.taskIndex}.RCNLCostTermSet. ...
                RCNLCostTerm{costTermIndex}.error_center;
            app.MTPTasksMaxAllowableErrorEditField.Value = ...
                app.MTPTask{app.taskIndex}.RCNLCostTermSet. ...
                RCNLCostTerm{costTermIndex}.max_allowable_error;
        end

        % Value changed function: MTPTasksMaxAllowableErrorEditField
        function MTPTasksMaxAllowableErrorEditFieldValueChanged(app, event)
            costTermIndex = app.MTPTasksCostTermsTable.Selection;
            value = app.MTPTasksMaxAllowableErrorEditField.Value;
            app.MTPTask{app.taskIndex}.RCNLCostTermSet. ...
                RCNLCostTerm{costTermIndex}.max_allowable_error = value;
        end

        % Value changed function: MTPTasksErrorCenterEditField
        function MTPTasksErrorCenterEditFieldValueChanged(app, event)
            costTermIndex = app.MTPTasksCostTermsTable.Selection;
            value = app.MTPTasksErrorCenterEditField.Value;
            app.MTPTask{app.taskIndex}.RCNLCostTermSet. ...
                RCNLCostTerm{costTermIndex}.error_center = value;
        end

        % Cell edit callback: TasksTable
        function TasksTableCellEdit(app, event)
            indices = event.Indices;
            app.taskIndex = indices(1);
            if indices(2) == 1
                if app.TasksTable.Data.isEnabled(indices(1))
                    app.MTPTask{app.taskIndex}.is_enabled = 'true';
                else
                    app.MTPTask{app.taskIndex}.is_enabled = 'false';
                end
            end
            if indices(2) == 2
                app.MTPTask{app.taskIndex}.name = event.NewData;
            end
            app.updateMTPTasksListBox()
        end

        % Button pushed function: MoveTaskUpButton
        function MoveTaskUpButtonPushed(app, event)
            app.moveTaskUp()
        end

        % Button pushed function: MoveTaskDownButton
        function MoveTaskDownButtonPushed(app, event)
            app.moveTaskDown()
        end

        % Menu selected function: RenameMenu
        function RenameMenuSelected(app, event)
            oldName = app.MTPTask{app.taskIndex}.name;
            newName = inputdlg("Rename task:", "Rename", [1 40], {oldName});
            if isempty(newName)
                return
            end
            app.MTPTask{app.taskIndex}.name = newName;
            app.updateMTPTasksListBox()
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            deletionIndex = 1 : 1 : length(app.MTPTask) == ...
                app.TasksTable.Selection;
            app.deleteTask(deletionIndex);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MuscletendonModelPersonalizationToolLabel = uilabel(app.UIFigure);
            app.MuscletendonModelPersonalizationToolLabel.Text = 'Muscle-tendon Model Personalization Tool';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputModelFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputModelFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputModelFileSearchButtonPushed, true);
            app.InputModelFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputModelFileSearchButton.Text = '';
            app.InputModelFileEditFieldLabel = uilabel(app.InputsTab);
            app.InputModelFileEditFieldLabel.Text = 'Input Model File';
            app.InputModelFileEditField = uieditfield(app.InputsTab, 'text');
            app.InputModelFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputModelFileEditFieldValueChanged, true);
            app.InputModelFileError = uiimage(app.InputsTab);
            app.InputModelFileError.Visible = 'off';
            app.InputModelFileError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputOsimxFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputOsimxFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputOsimxFileSearchButtonPushed, true);
            app.InputOsimxFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputOsimxFileSearchButton.Text = '';
            app.InputOsimxFileEditFieldLabel = uilabel(app.InputsTab);
            app.InputOsimxFileEditFieldLabel.Text = 'Input Osimx File';
            app.InputOsimxFileEditField = uieditfield(app.InputsTab, 'text');
            app.InputOsimxFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputOsimxFileEditFieldValueChanged, true);
            app.InputOsimxFileWarning = uiimage(app.InputsTab);
            app.InputOsimxFileWarning.Visible = 'off';
            app.InputOsimxFileWarning.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'warning.png');
            app.InputDataSearchButton = uibutton(app.InputsTab, 'push');
            app.InputDataSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputDataSearchButtonPushed, true);
            app.InputDataSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputDataSearchButton.Text = '';
            app.InputDataEditField = uieditfield(app.InputsTab, 'text');
            app.InputDataEditField.ValueChangedFcn = createCallbackFcn(app, @InputDataEditFieldValueChanged, true);
            app.InputDataError = uiimage(app.InputsTab);
            app.InputDataError.Visible = 'off';
            app.InputDataError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.ResultsDirectorySearchButton = uibutton(app.InputsTab, 'push');
            app.ResultsDirectorySearchButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsDirectorySearchButtonPushed, true);
            app.ResultsDirectorySearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.ResultsDirectorySearchButton.Text = '';
            app.ResultsDirectoryEditFieldLabel = uilabel(app.InputsTab);
            app.ResultsDirectoryEditFieldLabel.Text = 'Results Directory';
            app.ResultsDirectoryEditField = uieditfield(app.InputsTab, 'text');
            app.ResultsDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @ResultsDirectoryEditFieldValueChanged, true);
            app.ResultsDirectoryError = uiimage(app.InputsTab);
            app.ResultsDirectoryError.Visible = 'off';
            app.ResultsDirectoryError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputDataDirectoryEditFieldLabel = uilabel(app.InputsTab);
            app.InputDataDirectoryEditFieldLabel.Text = 'Input Data Directory';
            app.CoordinatesListTextArea = uitextarea(app.InputsTab);
            app.CoordinatesListTextArea.Editable = 'off';
            app.CoordinatesListTextAreaLabel = uilabel(app.InputsTab);
            app.CoordinatesListTextAreaLabel.Text = 'Coordinates List';
            app.CoordinateListWarning = uiimage(app.InputsTab);
            app.CoordinateListWarning.Visible = 'off';
            app.CoordinateListWarning.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'warning.png');
            app.CoordinateListEditButton = uibutton(app.InputsTab, 'push');
            app.CoordinateListEditButton.ButtonPushedFcn = createCallbackFcn(app, @CoordinateListEditButtonPushed, true);
            app.CoordinateListEditButton.Text = 'Edit';
            app.TaskPrefixesEditFieldLabel = uilabel(app.InputsTab);
            app.TaskPrefixesEditFieldLabel.Text = 'Task Prefixes';
            app.TaskPrefixesEditField = uieditfield(app.InputsTab, 'text');
            app.TaskPrefixesWarning = uiimage(app.InputsTab);
            app.TaskPrefixesWarning.Visible = 'off';
            app.TaskPrefixesWarning.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.TaskPrefixesError = uiimage(app.InputsTab);
            app.TaskPrefixesError.Visible = 'off';
            app.TaskPrefixesError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'warning.png');
            app.MuscleGroupsTab = uitab(app.TabGroup);
            app.NormalizedFiberLengthMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.NormalizedFiberLengthMuscleGroupsTextAreaLabel.Text = 'Normalized Fiber Length Muscle Groups';
            app.NormalizedFiberLengthMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.NormalizedFiberLengthMuscleGroupsTextArea.Editable = 'off';
            app.MissingEMGMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.MissingEMGMuscleGroupsTextAreaLabel.Text = 'Missing EMG Muscle Groups';
            app.MissingEMGMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.MissingEMGMuscleGroupsTextArea.Editable = 'off';
            app.CollectedEMGMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.CollectedEMGMuscleGroupsTextAreaLabel.Text = 'Collected EMG Muscle Groups';
            app.CollectedEMGMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.CollectedEMGMuscleGroupsTextArea.Editable = 'off';
            app.ActivationMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.ActivationMuscleGroupsTextAreaLabel.Text = 'Activation Muscle Groups';
            app.ActivationMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.ActivationMuscleGroupsTextArea.Editable = 'off';
            app.EditActivationGroupsButton = uibutton(app.MuscleGroupsTab, 'push');
            app.EditActivationGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @EditActivationGroupsButtonPushed, true);
            app.EditActivationGroupsButton.Text = 'Edit';
            app.EditFiberLengthGroupsButton = uibutton(app.MuscleGroupsTab, 'push');
            app.EditFiberLengthGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @EditFiberLengthGroupsButtonPushed, true);
            app.EditFiberLengthGroupsButton.Text = 'Edit';
            app.EditMissingEmgGroupsButton = uibutton(app.MuscleGroupsTab, 'push');
            app.EditMissingEmgGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @EditMissingEmgGroupsButtonPushed, true);
            app.EditMissingEmgGroupsButton.Text = 'Edit';
            app.EditCollectedEmgGroupsButton = uibutton(app.MuscleGroupsTab, 'push');
            app.EditCollectedEmgGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @EditCollectedEmgGroupsButtonPushed, true);
            app.EditCollectedEmgGroupsButton.Text = 'Edit';
            app.MTPTasksTab = uitab(app.TabGroup);
            app.MTPTasksCostTermPanel = uipanel(app.MTPTasksTab);
            app.MaxAllowableErrorEditField_2Label = uilabel(app.MTPTasksCostTermPanel);
            app.MaxAllowableErrorEditField_2Label.Text = 'Max Allowable Error';
            app.MTPTasksMaxAllowableErrorEditField = uieditfield(app.MTPTasksCostTermPanel, 'numeric');
            app.MTPTasksMaxAllowableErrorEditField.AllowEmpty = 'on';
            app.MTPTasksMaxAllowableErrorEditField.ValueChangedFcn = createCallbackFcn(app, @MTPTasksMaxAllowableErrorEditFieldValueChanged, true);
            app.MTPTasksMaxAllowableErrorEditField.Value = [];
            app.ErrorCenterEditField_2Label = uilabel(app.MTPTasksCostTermPanel);
            app.ErrorCenterEditField_2Label.Text = 'Error Center';
            app.MTPTasksErrorCenterEditField = uieditfield(app.MTPTasksCostTermPanel, 'numeric');
            app.MTPTasksErrorCenterEditField.AllowEmpty = 'on';
            app.MTPTasksErrorCenterEditField.ValueChangedFcn = createCallbackFcn(app, @MTPTasksErrorCenterEditFieldValueChanged, true);
            app.MTPTasksErrorCenterEditField.Value = [];
            app.MTPTasksCostTermsTable = uitable(app.MTPTasksCostTermPanel);
            app.MTPTasksCostTermsTable.ColumnName = {''; 'Cost Term'};
            app.MTPTasksCostTermsTable.RowName = {};
            app.MTPTasksCostTermsTable.SelectionType = 'row';
            app.MTPTasksCostTermsTable.ColumnEditable = [true false];
            app.MTPTasksCostTermsTable.CellEditCallback = createCallbackFcn(app, @MTPTasksCostTermsTableCellEdit, true);
            app.MTPTasksCostTermsTable.SelectionChangedFcn = createCallbackFcn(app, @MTPTasksCostTermsTableSelectionChanged, true);
            app.MTPTasksCostTermsTable.Multiselect = 'off';
            app.EditCostTermsLabel_2 = uilabel(app.MTPTasksTab);
            app.EditCostTermsLabel_2.Text = 'Edit Cost Terms';
            app.EditDesignVariablesLabel = uilabel(app.MTPTasksTab);
            app.EditDesignVariablesLabel.Text = 'Edit Design Variables';
            app.DesignVariablesTable = uitable(app.MTPTasksTab);
            app.DesignVariablesTable.ColumnName = {''; 'Design Variable'};
            app.DesignVariablesTable.RowName = {};
            app.DesignVariablesTable.SelectionType = 'row';
            app.DesignVariablesTable.ColumnEditable = [true false];
            app.DesignVariablesTable.CellEditCallback = createCallbackFcn(app, @DesignVariablesTableCellEdit, true);
            app.DesignVariablesTable.Multiselect = 'off';
            app.MuscleGroupsLabel = uilabel(app.MTPTasksTab);
            app.MuscleGroupsLabel.Text = 'Tasks';
            app.TasksTable = uitable(app.MTPTasksTab);
            app.TasksTable.ColumnName = {''; 'Task'};
            app.TasksTable.RowName = {};
            app.TasksTable.ColumnSortable = [false true];
            app.TasksTable.SelectionType = 'row';
            app.TasksTable.ColumnEditable = true;
            app.TasksTable.CellEditCallback = createCallbackFcn(app, @TasksTableCellEdit, true);
            app.TasksTable.SelectionChangedFcn = createCallbackFcn(app, @TasksTableSelectionChanged, true);
            app.TasksTable.Multiselect = 'off';
            app.MoveTaskUpButton = uibutton(app.MTPTasksTab, 'push');
            app.MoveTaskUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveTaskUpButtonPushed, true);
            app.MoveTaskUpButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'ArrowUp.svg');
            app.MoveTaskUpButton.Text = '';
            app.MoveTaskDownButton = uibutton(app.MTPTasksTab, 'push');
            app.MoveTaskDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveTaskDownButtonPushed, true);
            app.MoveTaskDownButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'ArrowDown.svg');
            app.MoveTaskDownButton.Text = '';
            app.AuxiliaryTab = uitab(app.TabGroup);
            app.PassiveDataInputDirectoryEditFieldLabel = uilabel(app.AuxiliaryTab);
            app.PassiveDataInputDirectoryEditFieldLabel.Text = 'Passive Data Input Directory';
            app.AuxiliaryToolsTab = uitabgroup(app.AuxiliaryTab);
            app.MuscleTendonLengthInitializationTab = uitab(app.AuxiliaryToolsTab);
            app.MuscleTendonLengthInitializationTab.Title = 'Tab';
            app.EnableMTLICheckBox = uicheckbox(app.MuscleTendonLengthInitializationTab);
            app.EnableMTLICheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableMTLICheckBoxValueChanged, true);
            app.EnableMTLICheckBox.Text = 'Enable Muscle-tendon Length Initialization';
            app.InputModelFileError_2 = uiimage(app.MuscleTendonLengthInitializationTab);
            app.InputModelFileError_2.Visible = 'off';
            app.InputModelFileError_2.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.PassiveDataDirectorySearchButton = uibutton(app.MuscleTendonLengthInitializationTab, 'push');
            app.PassiveDataDirectorySearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.PassiveDataDirectorySearchButton.Text = '';
            app.PassiveDataDirectoryEditFieldLabel = uilabel(app.MuscleTendonLengthInitializationTab);
            app.PassiveDataDirectoryEditFieldLabel.Text = 'Passive Data Directory';
            app.PassiveDataDirectoryEditField = uieditfield(app.MuscleTendonLengthInitializationTab, 'text');
            app.MinNormalizedFiberLengthEditFieldLabel = uilabel(app.MuscleTendonLengthInitializationTab);
            app.MinNormalizedFiberLengthEditFieldLabel.Text = 'Min Normalized Fiber Length';
            app.MinNormalizedFiberLengthEditField = uieditfield(app.MuscleTendonLengthInitializationTab, 'numeric');
            app.MinNormalizedFiberLengthEditField.Limits = [0 3];
            app.MinNormalizedFiberLengthEditField.Value = 0.7;
            app.MaxNormalizedFiberLengthEditFieldLabel = uilabel(app.MuscleTendonLengthInitializationTab);
            app.MaxNormalizedFiberLengthEditFieldLabel.Text = 'Max Normalized Fiber Length';
            app.MaxNormalizedFiberLengthEditField = uieditfield(app.MuscleTendonLengthInitializationTab, 'numeric');
            app.MaxNormalizedFiberLengthEditField.Limits = [0 3];
            app.MaxNormalizedFiberLengthEditField.Value = 1;
            app.SynergyExtrapolationTab = uitab(app.AuxiliaryToolsTab);
            app.SynergyExtrapolationTab.Title = 'Tab2';
            app.EnableSynergyExtrapolationCheckBox = uicheckbox(app.SynergyExtrapolationTab);
            app.EnableSynergyExtrapolationCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableSynergyExtrapolationCheckBoxValueChanged, true);
            app.EnableSynergyExtrapolationCheckBox.Text = 'Enable Synergy Extrapolation';
            app.NumSynergiesSpinner = uispinner(app.SynergyExtrapolationTab);
            app.NumSynergiesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumSynergiesSpinnerValueChanged, true);
            app.NumSynergiesSpinnerLabel = uilabel(app.SynergyExtrapolationTab);
            app.NumSynergiesSpinnerLabel.Text = 'Num Synergies';
            app.MTLIButton = uibutton(app.AuxiliaryTab, 'push');
            app.MTLIButton.Text = 'Muscle Tendon Length Initialization';
            app.SynxButton = uibutton(app.AuxiliaryTab, 'push');
            app.SynxButton.Text = 'Synergy Extrapolation';
            app.CostTermPanel = uipanel(app.AuxiliaryTab);
            app.MaxAllowableErrorEditFieldLabel = uilabel(app.CostTermPanel);
            app.MaxAllowableErrorEditFieldLabel.Text = 'Max Allowable Error';
            app.AuxMaxAllowableErrorEditField = uieditfield(app.CostTermPanel, 'numeric');
            app.ErrorCenterEditFieldLabel = uilabel(app.CostTermPanel);
            app.ErrorCenterEditFieldLabel.Text = 'Error Center';
            app.AuxErrorCenterEditField = uieditfield(app.CostTermPanel, 'numeric');
            app.CostTermsTable = uitable(app.CostTermPanel);
            app.CostTermsTable.ColumnName = {''; 'Cost Term'};
            app.CostTermsTable.RowName = {};
            app.CostTermsTable.ColumnSortable = [false true];
            app.CostTermsTable.SelectionType = 'row';
            app.CostTermsTable.ColumnEditable = true;
            app.CostTermsTable.Multiselect = 'off';
            app.EditCostTermsLabel = uilabel(app.AuxiliaryTab);
            app.EditCostTermsLabel.Text = 'Edit Cost Terms';
            app.OptimizationParamsTable = uitable(app.AuxiliaryTab);
            app.OptimizationParamsTable.ColumnName = {'Parameter'; 'Value'};
            app.OptimizationParamsTable.RowName = {};
            app.OptimizationParamsTable.SelectionType = 'row';
            app.OptimizationParamsTable.ColumnEditable = [false true];
            app.AdvancedSettingsLabel = uilabel(app.AuxiliaryTab);
            app.AdvancedSettingsLabel.Text = 'Advanced Settings';
            app.AdvancedTab = uitab(app.TabGroup);
            app.Mask1 = uiimage(app.UIFigure);
            app.Mask1.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'greyMask.png');
            app.MTPImage = uiimage(app.UIFigure);
            app.MTPImage.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'mtpFigure.png');
            app.MuscleGroupsButton = uibutton(app.UIFigure, 'push');
            app.MuscleGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @MuscleGroupsButtonPushed, true);
            app.MuscleGroupsButton.Text = 'Muscle Groups';
            app.AuxiliaryToolsButton = uibutton(app.UIFigure, 'push');
            app.AuxiliaryToolsButton.ButtonPushedFcn = createCallbackFcn(app, @AuxiliaryToolsButtonPushed, true);
            app.AuxiliaryToolsButton.Text = 'Auxiliary Tools';
            app.AdvancedButton = uibutton(app.UIFigure, 'push');
            app.AdvancedButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedButtonPushed, true);
            app.AdvancedButton.Text = 'Advanced';
            app.InputsButton = uibutton(app.UIFigure, 'push');
            app.InputsButton.ButtonPushedFcn = createCallbackFcn(app, @InputsButtonPushed, true);
            app.InputsButton.Text = 'MTP Inputs';
            app.RcnlLogo = uiimage(app.UIFigure);
            app.RcnlLogo.ImageClickedFcn = createCallbackFcn(app, @RcnlLogoImageClicked, true);
            app.RcnlLogo.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'rcnlIcon.png');
            app.LoadSettingsFileButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsFileButtonPushed, true);
            app.LoadSettingsFileButton.Text = 'Load';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.MTPTasksButton = uibutton(app.UIFigure, 'push');
            app.MTPTasksButton.ButtonPushedFcn = createCallbackFcn(app, @MTPTasksButtonPushed, true);
            app.MTPTasksButton.Text = 'MTP Tasks';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.RenameMenu = uimenu(app.ContextMenu);
            app.RenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameMenuSelected, true);
            app.RenameMenu.Text = 'Rename';
            app.CopyMenu = uimenu(app.ContextMenu);
            app.CopyMenu.Text = 'Copy';
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            % Assign app.ContextMenu
            app.TasksTable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MTPBase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end