classdef MTPBaseVisual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResetButton                     matlab.ui.control.Button
        MTPTasksButton                  matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        LoadSettingsFileButton          matlab.ui.control.Button
        RcnlLogo                        matlab.ui.control.Image
        InputsButton                    matlab.ui.control.Button
        AdvancedButton                  matlab.ui.control.Button
        AuxiliaryToolsButton            matlab.ui.control.Button
        MuscleGroupsButton              matlab.ui.control.Button
        MTPImage                        matlab.ui.control.Image
        Mask1                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        TrialPrefixesStatus             matlab.ui.control.Image
        TrialPrefixesEditField          matlab.ui.control.EditField
        TrialPrefixesEditFieldLabel     matlab.ui.control.Label
        CoordinateListEditButton        matlab.ui.control.Button
        CoordinateListStatus            matlab.ui.control.Image
        CoordinatesListTextAreaLabel    matlab.ui.control.Label
        CoordinatesListTextArea         matlab.ui.control.TextArea
        InputDataDirectoryEditFieldLabel  matlab.ui.control.Label
        ResultsDirectoryStatus          matlab.ui.control.Image
        ResultsDirectoryEditField       matlab.ui.control.EditField
        ResultsDirectoryEditFieldLabel  matlab.ui.control.Label
        ResultsDirectorySearchButton    matlab.ui.control.Button
        InputDataStatus                 matlab.ui.control.Image
        InputDataEditField              matlab.ui.control.EditField
        InputDataSearchButton           matlab.ui.control.Button
        InputOsimxFileStatus            matlab.ui.control.Image
        InputOsimxFileEditField         matlab.ui.control.EditField
        InputOsimxFileEditFieldLabel    matlab.ui.control.Label
        InputOsimxFileSearchButton      matlab.ui.control.Button
        InputModelFileStatus            matlab.ui.control.Image
        InputModelFileEditField         matlab.ui.control.EditField
        InputModelFileEditFieldLabel    matlab.ui.control.Label
        InputModelFileSearchButton      matlab.ui.control.Button
        MuscleGroupsTab                 matlab.ui.container.Tab
        EditCollectedEmgGroupsButton    matlab.ui.control.Button
        EditMissingEmgGroupsButton      matlab.ui.control.Button
        EditFiberLengthGroupsButton     matlab.ui.control.Button
        EditActivationGroupsButton      matlab.ui.control.Button
        ActivationMuscleGroupsTextArea  matlab.ui.control.TextArea
        ActivationMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        CollectedEMGMuscleGroupsTextArea  matlab.ui.control.TextArea
        CollectedEMGMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        MissingEMGMuscleGroupsTextArea  matlab.ui.control.TextArea
        MissingEMGMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        NormalizedFiberLengthMuscleGroupsTextArea  matlab.ui.control.TextArea
        NormalizedFiberLengthMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        MTPTasksTab                     matlab.ui.container.Tab
        MTPDesignVariablesStatus        matlab.ui.control.Image
        MTPCostTermsStatus              matlab.ui.control.Image
        MTPTasksStatus                  matlab.ui.control.Image
        MoveTaskDownButton              matlab.ui.control.Button
        MoveTaskUpButton                matlab.ui.control.Button
        MTPTasksTable                   matlab.ui.control.Table
        MTPTasksLabel                   matlab.ui.control.Label
        MTPDesignVariablesTable         matlab.ui.control.Table
        EditMTPDesignVariablesLabel     matlab.ui.control.Label
        EditMTPCostTermsLabel           matlab.ui.control.Label
        MTPCostTermPanel                matlab.ui.container.Panel
        MTPCostTermsTable               matlab.ui.control.Table
        MTPErrorCenterEditField         matlab.ui.control.NumericEditField
        ErrorCenterEditField_2Label     matlab.ui.control.Label
        MTPMaxAllowableErrorEditField   matlab.ui.control.NumericEditField
        MaxAllowableErrorEditField_2Label  matlab.ui.control.Label
        AuxiliaryTab                    matlab.ui.container.Tab
        EditCostTermsLabel              matlab.ui.control.Label
        AdvancedSettingsLabel           matlab.ui.control.Label
        AuxAdvancedSettingsStatus       matlab.ui.control.Image
        AuxCostTermsStatus              matlab.ui.control.Image
        AuxAdvancedSettingsTable        matlab.ui.control.Table
        AuxCostTermPanel                matlab.ui.container.Panel
        AuxCostTermsTable               matlab.ui.control.Table
        AuxErrorCenterEditField         matlab.ui.control.NumericEditField
        ErrorCenterEditFieldLabel       matlab.ui.control.Label
        AuxMaxAllowableErrorEditField   matlab.ui.control.NumericEditField
        MaxAllowableErrorEditFieldLabel  matlab.ui.control.Label
        SynxButton                      matlab.ui.control.Button
        MTLIButton                      matlab.ui.control.Button
        AuxiliaryToolsTabGroup          matlab.ui.container.TabGroup
        MuscleTendonLengthInitializationTab  matlab.ui.container.Tab
        MaxNormalizedFiberLengthEditField  matlab.ui.control.NumericEditField
        MaxNormalizedFiberLengthEditFieldLabel  matlab.ui.control.Label
        MinNormalizedFiberLengthEditField  matlab.ui.control.NumericEditField
        MinNormalizedFiberLengthEditFieldLabel  matlab.ui.control.Label
        PassiveDataDirectoryEditField   matlab.ui.control.EditField
        PassiveDataDirectoryEditFieldLabel  matlab.ui.control.Label
        PassiveDataDirectorySearchButton  matlab.ui.control.Button
        PassiveDataDirectoryStatus      matlab.ui.control.Image
        EnableMTLICheckBox              matlab.ui.control.CheckBox
        SynergyExtrapolationTab         matlab.ui.container.Tab
        NumSynergiesStatus              matlab.ui.control.Image
        NumSynergiesSpinnerLabel        matlab.ui.control.Label
        NumSynergiesSpinner             matlab.ui.control.Spinner
        EnableSynergyExtrapolationCheckBox  matlab.ui.control.CheckBox
        PassiveDataInputDirectoryEditFieldLabel  matlab.ui.control.Label
        AdvancedTab                     matlab.ui.container.Tab
        AdvancedSettingsStatus          matlab.ui.control.Image
        AdvancedSettingsTable           matlab.ui.control.Table
        MuscletendonModelPersonalizationToolLabel  matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        RenameMenu                      matlab.ui.container.Menu
        CopyMenu                        matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
    end

    
    properties (Access = private, SetObservable)
        model_coordinates string = [];
        model_groups string = [];
        
        input_model_file string = "";
        input_osimx_file string = "";
        data_directory string = "";
        results_directory string = "";
        coordinate_list string = [];
        trial_prefixes string = [];
        v_max_factor double = 10;

        activation_muscle_groups string = [];
        normalized_fiber_length_muscle_groups string = [];
        missing_emg_channel_muscle_groups string = [];
        collected_emg_channel_muscle_groups string = [];

        MTPTask cell = cell(0)
        taskIndex = 1;

        designVariables string = ["Muscle Specific Electromechanical Delays"
            "Electromechanical Delays"
            "Activation Time Constants"
            "Activation Non-linearity Constants"
            "EMG Scale Factors"
            "Optimal Fiber Lengths"
            "Tendon Slack Lengths"];
        
        MuscleTendonLengthInitialization handle = ...
            MuscleTendonLengthInitializationClass();


        MTPSynergyExtrapolation handle = ...
            SynergyExtrapolationClass();

        objectSelectionType string = "";  % Used to filter in setSelectedObjects
        ErrorFlag logical = false;
        currentSettingsFile string; % Variable to store the current settings file name.
        needToSave logical = false; % flag indicating if the settings file changed and needs to be saved before running.
        modelErrorFlag logical = false;
        osimxWarningFlag logical = false;
        dataDirectoryErrorFlag logical = false;

        timePoints double = [];
        idLabels string = [];
        emgLabels string = [];

        advancedSettingNames = ...
            ["v_max_factor"
            "max_iterations"
            "max_function_evaluations"
            "step_tolerance"
            "function_tolerance"
            "optimality_tolerance"
            "diff_min_change"]

        advancedSettingValues = ...
            [10
            1000
            100000000
            1e-6
            1e-6
            1e-6
            0.0001]

    end % Description
    properties(Access = private)  % listner properties
        InputModelFileListener
        OsimXFileListener
        DataDirectoryListener
        ResultsDirectoryListener
        CoordinateListListener
        trialPrefixesListener
        ActivationGroupsListener
        FiberLengthGroupsListener
        MissingEmgGroupsListener
        CollectedEmgGroupsListener
        PassiveDataDirectoryListener
        MaxFiberLengthListener
        MinFiberLengthListener
        taskIndexListener
        advancedSettingsListener
        selectedTabListener
        auxTabListener
    end

    methods (Access = private) % listener methods
        function makeListeners(app)
            app.InputModelFileListener = addlistener(app, ...
                'input_model_file', 'PostSet', ...
                @(src,event)InputModelFileListenerFunction(app));

            app.OsimXFileListener = addlistener(app, ...
                'input_osimx_file', 'PostSet', ...
                @(src,event)OsimXFileListenerFunction(app));

            app.DataDirectoryListener = addlistener(app, ...
                'data_directory', 'PostSet', ...
                @(src,event)DataDirectoryListenerFunction(app));

            app.ResultsDirectoryListener = addlistener(app, ...
                'results_directory', 'PostSet', ...
                @(src,event)ResultsDirectoryListenerFunction(app));

            app.CoordinateListListener = addlistener(app, ...
                'coordinate_list', 'PostSet', ...
                @(src,event)CoordinateListListenerFunction(app));

            app.trialPrefixesListener = addlistener(app, ...
                'trial_prefixes', 'PostSet', ...
                @(src, event)updateTrialPrefixes(app));

            app.ActivationGroupsListener = addlistener(app, ...
                'activation_muscle_groups', 'PostSet', ...
                @(src,event)ActivationGroupsListenerFunction(app));

            app.FiberLengthGroupsListener = addlistener(app, ...
                'normalized_fiber_length_muscle_groups', 'PostSet', ...
                @(src,event)FiberLengthGroupsListenerFunction(app));

            app.MissingEmgGroupsListener = addlistener(app, ...
                'missing_emg_channel_muscle_groups', 'PostSet', ...
                @(src,event)MissingEmgGroupsListenerFunction(app));

            app.CollectedEmgGroupsListener = addlistener(app, ...
                'collected_emg_channel_muscle_groups', 'PostSet', ...
                @(src,event)CollectedEmgGroupsListenerFunction(app));

            app.PassiveDataDirectoryListener = addlistener(...
                app.MuscleTendonLengthInitialization, ...
                'passive_data_input_directory', 'PostSet', ...
                @(src,event)updatePassiveDataDirectory(app));

            app.MaxFiberLengthListener = addlistener(...
                app.MuscleTendonLengthInitialization, ...
                'max_normalized_muscle_fiber_length', 'PostSet', ...
                @(src,event)maxFiberLengthListenerFunction(app));

            app.MinFiberLengthListener = addlistener(...
                app.MuscleTendonLengthInitialization, ...
                'min_normalized_muscle_fiber_length', 'PostSet', ...
                @(src,event)minFiberLengthListenerFunction(app));

            app.taskIndexListener = addlistener(app, ...
                'taskIndex', 'PostSet', ...
                @(src, event)updateTaskIndex(app));

            app.advancedSettingsListener = addlistener(app, ...
                'advancedSettingValues', 'PostSet', ...
                @(src, event)updateAdvancedSettingValues(app));

            app.selectedTabListener = addlistener(app.TabGroup, ...
                'SelectedTab', 'PostSet', ...
                @(src, event)formatTabButtons(app));
           
            app.auxTabListener = addlistener(app.AuxiliaryToolsTabGroup, ...
                'SelectedTab', 'PostSet', ...
                @(src, event)formatTabButtons(app));
        end

        function InputModelFileListenerFunction(app)
            app.InputModelFileEditField.Value = getRelativePath( ...
                app.input_model_file);
            parseModelFileGui(app, app.input_model_file);
            app.needToSave = true;
        end

        function OsimXFileListenerFunction(app)
            app.InputOsimxFileEditField.Value = getRelativePath( ...
                app.input_osimx_file);
            app.needToSave = true;
        end

        function DataDirectoryListenerFunction(app)
            app.InputDataEditField.Value = getRelativePath( ...
                app.data_directory);
            parseMtpDataDirectoryGui(app, app.data_directory);
            app.needToSave = true;
        end

        function ResultsDirectoryListenerFunction(app)
            app.ResultsDirectoryEditField.Value = getRelativePath( ...
                app.results_directory);
            app.needToSave = true;
        end

        function CoordinateListListenerFunction(app)
            app.CoordinatesListTextArea.Value =  ...
                strjoin(app.coordinate_list, ", ");
            app.needToSave = true;
        end

        function updateTrialPrefixes(app)
            app.TrialPrefixesEditField.Value = app.trial_prefixes;
        end

        function ActivationGroupsListenerFunction(app)
            app.ActivationMuscleGroupsTextArea.Value = ...
                strjoin(app.activation_muscle_groups, ", ");
            app.needToSave;
        end

        function FiberLengthGroupsListenerFunction(app)
            app.NormalizedFiberLengthMuscleGroupsTextArea.Value = ...
                strjoin(app.normalized_fiber_length_muscle_groups, ", ");
            app.needToSave;
        end

        function MissingEmgGroupsListenerFunction(app)
            app.MissingEMGMuscleGroupsTextArea.Value = ...
                strjoin(app.missing_emg_channel_muscle_groups, ", ");
            app.needToSave;
        end

        function CollectedEmgGroupsListenerFunction(app)
            app.CollectedEMGMuscleGroupsTextArea.Value = ...
                strjoin(app.collected_emg_channel_muscle_groups, ", ");
            app.needToSave;
        end

        function updatePassiveDataDirectory(app)
            app.PassiveDataDirectoryEditField.Value = ...
                getRelativePath(app.MuscleTendonLengthInitialization. ...
                passive_data_input_directory);
        end

        function maxFiberLengthListenerFunction(app)
            app.MaxNormalizedFiberLengthEditField.Value = ...
                app.MuscleTendonLengthInitialization.max_normalized_muscle_fiber_length;
        end

        function minFiberLengthListenerFunction(app)
            app.MinNormalizedFiberLengthEditField.Value = ...
                app.MuscleTendonLengthInitialization.min_normalized_muscle_fiber_length;
        end

        function updateAdvancedSettingValues(app)
            app.AdvancedSettingsTable.Data.Values = ...
                app.advancedSettingValues;
        end
        

        function updateMtliPanel(app)
            app.EnableMTLICheckBox.Value = strcmp( ...
                app.MuscleTendonLengthInitialization.is_enabled, "true");
            app.PassiveDataDirectoryEditField.Value = ...
                getRelativePath(app.MuscleTendonLengthInitialization. ...
                passive_data_input_directory);
            app.MaxNormalizedFiberLengthEditField.Value = ...
                app.MuscleTendonLengthInitialization.max_normalized_muscle_fiber_length;
            app.MinNormalizedFiberLengthEditField.Value = ...
                app.MuscleTendonLengthInitialization.min_normalized_muscle_fiber_length;
        end

        function updateSynxPanel(app)
            app.EnableSynergyExtrapolationCheckBox.Value = strcmp( ...
                app.MTPSynergyExtrapolation.is_enabled, "true");
            app.NumSynergiesSpinner.Value = ...
                app.MTPSynergyExtrapolation.number_of_synergies;
        end

        function updateAuxPanel(app)
            switch app.AuxiliaryToolsTabGroup.SelectedTab
                case app.SynergyExtrapolationTab
                    app.updateSynxPanel()
                case app.MuscleTendonLengthInitializationTab
                    app.updateMtliPanel()
            end
            app.updateAuxCostTermsTable()
            app.updateAuxAdvancedOptionsTable()
        end

        function updateAuxCostTermsTable(app)
            switch app.AuxiliaryToolsTabGroup.SelectedTab
                case app.SynergyExtrapolationTab
                    numCostTerms = length(app.MTPSynergyExtrapolation. ...
                        RCNLCostTerm);
                    isEnabled = false(numCostTerms, 1);
                    costTermNames = strings(numCostTerms, 1);
                    for i = 1 : numCostTerms
                        isEnabled(i) = strcmp(app.MTPSynergyExtrapolation. ...
                            RCNLCostTerm{i}.is_enabled, "true");
                        costTermNames(i) = app.MTPSynergyExtrapolation. ...
                            RCNLCostTerm{i}.type;
                        app.AuxCostTermsTable.Data = table(isEnabled, costTermNames);
                    end
                case app.MuscleTendonLengthInitializationTab
                    numCostTerms = length(app.MuscleTendonLengthInitialization. ...
                        RCNLCostTerm);
                    isEnabled = false(numCostTerms, 1);
                    costTermNames = strings(numCostTerms, 1);
                    for i = 1 : numCostTerms
                        isEnabled(i) = strcmp(app.MuscleTendonLengthInitialization. ...
                            RCNLCostTerm{i}.is_enabled, "true");
                        costTermNames(i) = app.MuscleTendonLengthInitialization. ...
                            RCNLCostTerm{i}.type;
                        app.AuxCostTermsTable.Data = table(isEnabled, costTermNames);
                    end
            end
        end

        function updateAuxAdvancedOptionsTable(app)
            switch app.AuxiliaryToolsTabGroup.SelectedTab
                case app.SynergyExtrapolationTab
                    options = ["matrix_factorization_method"
                        "synergy_extrapolation_categorization"
                        "residual_categorization"];
                    value = ["PCA"
                        "trial"
                        "task"];
                    app.AuxAdvancedSettingsTable.Data = table(options, value);
                case app.MuscleTendonLengthInitializationTab
                    options = ["optimize_maximum_muscle_stress"
                        "optimize_isometric_max_force"
                        "optimize_absolute_length_changes"
                        "maximum_muscle_stress"];
                    value = ["true"
                        "true"
                        "true"
                        "610000"];
                    app.AuxAdvancedSettingsTable.Data = table(options, value);
            end
        end

        function createDefaultTask(app)
            app.MTPTask{end+1} = MTPTaskClass();
            app.MTPTask{end}.makeDefaultCostTermSet();
            app.MTPTask{end}.name = "Task " + num2str(length(app.MTPTask));
            app.taskIndex = length(app.MTPTask);
            app.MTPTask{end}.index = app.taskIndex;
            app.MTPCostTermsTable.Selection = [];
            app.MTPDesignVariablesTable.Selection = [];
            app.updateMTPTasksListBox()
            app.updateDesignVariablesTable()
            app.MTPMaxAllowableErrorEditField.Value = [];
            app.MTPErrorCenterEditField.Value = [];
            app.updateMTPCostTermsTable()
        end

        function initSynx(app)
            app.MTPSynergyExtrapolation.makeDefaultCostTermSet();
        end

        function initMtli(app)
            app.MuscleTendonLengthInitialization.makeDefaultCostTermSet();
        end

        function deleteTask(app, taskIndex)
            app.MTPTask = app.MTPTask(~taskIndex);
            for i = 1 : length(app.MTPTask)
                app.MTPTask{i}.index = i;
            end
            if isempty(app.MTPTask)
                app.MTPTask = [];
                return
            end
            if app.taskIndex > 1
                app.taskIndex = app.taskIndex - 1;
            end
            app.needToSave = true;
            app.updateMTPTasksListBox();
        end

        function moveTaskUp(app)
            if app.taskIndex == 1 || ...
                    app.taskIndex >= height(app.MTPTasksTable.Data)
                return
            end

            indicesList = 1:1:length(app.MTPTask);

            indicesList(app.taskIndex) = app.taskIndex-1;
            indicesList(app.taskIndex-1) = app.taskIndex;
            app.MTPTask{app.taskIndex}.index = app.taskIndex-1;
            app.MTPTask{app.taskIndex-1}.index = app.taskIndex;
            app.MTPTask = {app.MTPTask{indicesList}};
            app.taskIndex = app.taskIndex-1;
            app.updateMTPTasksListBox();
        end

        function moveTaskDown(app)
            if app.taskIndex == height(app.MTPTasksTable.Data)-1 || ...
                    app.taskIndex >= height(app.MTPTasksTable.Data)
                return
            end

            indicesList = 1:1:length(app.MTPTask);
            indicesList(app.taskIndex) = app.taskIndex+1;
            indicesList(app.taskIndex+1) = app.taskIndex;
            app.MTPTask{app.taskIndex}.index = app.taskIndex+1;
            app.MTPTask{app.taskIndex+1}.index = app.taskIndex;
            app.MTPTask = {app.MTPTask{indicesList}};
            app.taskIndex = app.taskIndex+1;
            app.updateMTPTasksListBox();
        end

        function updateTaskIndex(app)
            app.MTPTasksTable.Selection = app.taskIndex;
            app.updateMTPTasksListBox()
            app.updateDesignVariablesTable()
            app.updateMTPCostTermsTable()
        end

        function updateMTPTasksListBox(app)
            taskNames = strings(length(app.MTPTask)+1, 1);
            isEnabled = true(size(taskNames));
            for i = 1 : length(app.MTPTask)
                taskNames(i) = app.MTPTask{i}.name;
                isEnabled(i) = strcmp(app.MTPTask{i}.is_enabled, "true");
            end
            isEnabled(end) = false;
            taskNames(end) = "Add a new task";
            app.MTPTasksTable.Data = table(isEnabled, taskNames);
        end

        function updateDesignVariablesTable(app)
            isEnabled = false(length(app.designVariables), 1);
            for i = 1 : length(isEnabled)
                isEnabled(i) = strcmp(app.MTPTask{app.taskIndex}. ...
                    getParameterValueByIndex(i), "true");
            end
            app.MTPDesignVariablesTable.Data = table(isEnabled, app.designVariables);
        end

        function updateMTPCostTermsTable(app)
            isEnabled = true(length(app.MTPTask{app.taskIndex}.RCNLCostTerm), 1);
            costTermTypes = strings(length(app.MTPTask{app.taskIndex}.RCNLCostTerm), 1);
            for i = 1 : length(app.MTPTask{app.taskIndex}.RCNLCostTerm)
                isEnabled(i) = strcmp(app.MTPTask{app.taskIndex}.RCNLCostTerm{i}.is_enabled, "true");
                costTermTypes(i) = app.MTPTask{app.taskIndex}.RCNLCostTerm{i}.type;
            end
            app.MTPCostTermsTable.Data = table(isEnabled, costTermTypes);
        end

        function formatTabButtons(app) % good
            try
            switch app.TabGroup.SelectedTab
                case app.InputsTab
                    app.InputsButton.BackgroundColor = [1 1 1];
                    app.InputsButton.FontColor = [0.13,0.18,0.40];
                    
                    app.MuscleGroupsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.MuscleGroupsButton.FontColor = [1 1 1];

                    app.MTPTasksButton.BackgroundColor = [0.13,0.18,0.40];
                    app.MTPTasksButton.FontColor = [1 1 1];

                    app.AuxiliaryToolsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AuxiliaryToolsButton.FontColor = [1 1 1];

                    app.AdvancedButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AdvancedButton.FontColor = [1 1 1];

                case app.MuscleGroupsTab
                    app.InputsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.InputsButton.FontColor = [1 1 1];
                    
                    app.MuscleGroupsButton.BackgroundColor = [1 1 1];
                    app.MuscleGroupsButton.FontColor = [0.13,0.18,0.40];

                    app.MTPTasksButton.BackgroundColor = [0.13,0.18,0.40];
                    app.MTPTasksButton.FontColor = [1 1 1];

                    app.AuxiliaryToolsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AuxiliaryToolsButton.FontColor = [1 1 1];

                    app.AdvancedButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AdvancedButton.FontColor = [1 1 1];

                case app.MTPTasksTab
                    app.InputsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.InputsButton.FontColor = [1 1 1];
                    
                    app.MuscleGroupsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.MuscleGroupsButton.FontColor = [1 1 1];

                    app.MTPTasksButton.BackgroundColor = [1 1 1];
                    app.MTPTasksButton.FontColor = [0.13,0.18,0.40];

                    app.AuxiliaryToolsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AuxiliaryToolsButton.FontColor = [1 1 1];

                    app.AdvancedButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AdvancedButton.FontColor = [1 1 1];

                case app.AuxiliaryTab
                    app.InputsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.InputsButton.FontColor = [1 1 1];
                    
                    app.MuscleGroupsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.MuscleGroupsButton.FontColor = [1 1 1];

                    app.MTPTasksButton.BackgroundColor = [0.13,0.18,0.40];
                    app.MTPTasksButton.FontColor = [1 1 1];

                    app.AuxiliaryToolsButton.BackgroundColor = [1 1 1];
                    app.AuxiliaryToolsButton.FontColor = [0.13,0.18,0.40];

                    app.AdvancedButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AdvancedButton.FontColor = [1 1 1];

                    switch app.AuxiliaryToolsTabGroup.SelectedTab

                        case app.MuscleTendonLengthInitializationTab
                            app.MTLIButton.BackgroundColor = [1 1 1];
                            app.MTLIButton.FontColor = [0.13,0.18,0.40];

                            app.SynxButton.BackgroundColor = [0.13,0.18,0.40];
                            app.SynxButton.FontColor = [1 1 1];

                        case app.SynergyExtrapolationTab
                            app.SynxButton.BackgroundColor = [1 1 1];
                            app.SynxButton.FontColor = [0.13,0.18,0.40];

                            app.MTLIButton.BackgroundColor = [0.13,0.18,0.40];
                            app.MTLIButton.FontColor = [1 1 1];
                    end

                case app.AdvancedTab
                    app.InputsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.InputsButton.FontColor = [1 1 1];
                    
                    app.MuscleGroupsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.MuscleGroupsButton.FontColor = [1 1 1];

                    app.MTPTasksButton.BackgroundColor = [0.13,0.18,0.40];
                    app.MTPTasksButton.FontColor = [1 1 1];

                    app.AuxiliaryToolsButton.BackgroundColor = [0.13,0.18,0.40];
                    app.AuxiliaryToolsButton.FontColor = [1 1 1];

                    app.AdvancedButton.BackgroundColor = [1 1 1];
                    app.AdvancedButton.FontColor = [0.13,0.18,0.40];
            end
            catch
            end
        end

        function saveSettingsFile(app, settingsFileName)
            settingsFilePath = fileparts(settingsFileName);
            cd (settingsFilePath);
            settingsTree = makeMTPSettingsStruct(app, settingsFileName);
            settingsFileStruct.NMSMPipelineDocument.MuscleTendonPersonalizationTool = ...
                settingsTree;
            settingsFileStruct.NMSMPipelineDocument.Attributes.Version = ...
                convertStringsToChars(getPipelineVersion());
            struct2xml(settingsFileStruct, settingsFileName)
        end
        
        function settingsTree = makeMTPSettingsStruct(app, settingsFileName)
            settingsFilePath = fileparts(settingsFileName);
        
            % Top-level file inputs
            settingsTree.input_model_file = getRelativePath( ...
                app.input_model_file, settingsFilePath);
            settingsTree.input_osimx_file = getRelativePath( ...
                app.input_osimx_file, settingsFilePath);
            settingsTree.data_directory = getRelativePath( ...
                app.data_directory, settingsFilePath);
            settingsTree.results_directory = getRelativePath( ...
                app.results_directory, settingsFilePath);
            settingsTree.trial_prefixes = app.trial_prefixes;
        
            % Coordinate and muscle group lists
            settingsTree.coordinate_list = strjoin(app.coordinate_list, " ");
            settingsTree.activation_muscle_groups = strjoin( ...
                app.activation_muscle_groups, " ");
            settingsTree.normalized_fiber_length_muscle_groups = strjoin( ...
                app.normalized_fiber_length_muscle_groups, " ");
            settingsTree.missing_emg_channel_muscle_groups = strjoin( ...
                app.missing_emg_channel_muscle_groups, " ");
            settingsTree.collected_emg_channel_muscle_groups = strjoin( ...
                app.collected_emg_channel_muscle_groups, " ");
        
            % MuscleTendonLengthInitialization
            settingsTree.MuscleTendonLengthInitialization = ...
                app.MuscleTendonLengthInitialization.toStruct();
            settingsTree.MuscleTendonLengthInitialization.passive_data_input_directory = ...
                getRelativePath(settingsTree.MuscleTendonLengthInitialization.passive_data_input_directory);
        
            % MTPSynergyExtrapolation
            settingsTree.MTPSynergyExtrapolation = ...
                app.MTPSynergyExtrapolation.toStruct();
        
            % MTP task list
            settingsTree.MTPTaskList = struct("MTPTask", cell(1));
            for i = 1 : length(app.MTPTask)
                settingsTree.MTPTaskList.MTPTask{i} = app.MTPTask{i}.toStruct();
            end
        
            % Optimization parameters
            settingsTree = setOptimizationParams(app, settingsTree);
            settingsTree = formatGuiDataForXml(settingsTree);
        end

        function settingsTree = setOptimizationParams(app, settingsTree)
            for i = 1 : length(app.advancedSettingNames)
                settingsTree.(app.advancedSettingNames(i)) = ...
                    app.advancedSettingValues(i);
            end
        end

        function loadSettingsFile(app, settingsFileName)
            app.resetAllFields();
            settingsFilePath = fileparts(settingsFileName);
            cd (settingsFilePath);
            app.currentSettingsFile = settingsFileName;
            settingsTree = xml2struct(settingsFileName);
            settingsTree = settingsTree.NMSMPipelineDocument. ...
                MuscleTendonPersonalizationTool;
            settingsTree = formatXmlDataForGui(settingsTree);
        
            % Top-level file inputs — assigning through the observable properties
            % so their listeners fire and update the GUI fields automatically
            app.input_model_file  = settingsTree.input_model_file;
            app.input_osimx_file  = settingsTree.input_osimx_file;
            app.data_directory    = settingsTree.data_directory;
            app.results_directory = settingsTree.results_directory;
            try
            app.trial_prefixes = settingsTree.trial_prefixes;
            catch
            end

            app.loadAdvancedParams(settingsTree);
        
            % Space-separated list fields — split back into string arrays
            app.coordinate_list = settingsTree.coordinate_list;
            app.activation_muscle_groups = settingsTree.activation_muscle_groups;
            app.normalized_fiber_length_muscle_groups = settingsTree.normalized_fiber_length_muscle_groups;
            app.missing_emg_channel_muscle_groups = settingsTree.missing_emg_channel_muscle_groups;
            app.collected_emg_channel_muscle_groups = settingsTree.collected_emg_channel_muscle_groups;
        
            % MuscleTendonLengthInitialization
            if isfield(settingsTree, 'MuscleTendonLengthInitialization')
                mtli = settingsTree.MuscleTendonLengthInitialization;
                app.MuscleTendonLengthInitialization.is_enabled = mtli.is_enabled;
                app.MuscleTendonLengthInitialization.passive_data_input_directory = ...
                    mtli.passive_data_input_directory;
                app.MuscleTendonLengthInitialization.max_normalized_muscle_fiber_length = ...
                    mtli.max_normalized_muscle_fiber_length;
                app.MuscleTendonLengthInitialization.min_normalized_muscle_fiber_length = ...
                    mtli.min_normalized_muscle_fiber_length;
                app.MuscleTendonLengthInitialization.optimize_maximum_muscle_stress = ...
                    mtli.optimize_maximum_muscle_stress;
                app.MuscleTendonLengthInitialization.optimize_absolute_length_changes = ...
                    mtli.optimize_absolute_length_changes;
                app.MuscleTendonLengthInitialization.optimize_isometric_max_force = ...
                    mtli.optimize_isometric_max_force;
                app.MuscleTendonLengthInitialization.maximum_muscle_stress = ...
                    mtli.maximum_muscle_stress;
                if isfield(mtli, 'RCNLCostTermSet') && ...
                        isfield(mtli.RCNLCostTermSet, 'RCNLCostTerm')
                    for j = 1 : length(mtli.RCNLCostTermSet.RCNLCostTerm)
                        app.MuscleTendonLengthInitialization.RCNLCostTerm{j} = ...
                            RCNLCostTermClass(mtli.RCNLCostTermSet.RCNLCostTerm{j});
                    end
                end
            end
        
            % MTPSynergyExtrapolation
            if isfield(settingsTree, 'MTPSynergyExtrapolation')
                synx = settingsTree.MTPSynergyExtrapolation;
                app.MTPSynergyExtrapolation.is_enabled = synx.is_enabled;
                app.MTPSynergyExtrapolation.matrix_factorization_method = ...
                    synx.matrix_factorization_method;
                app.MTPSynergyExtrapolation.number_of_synergies = ...
                    synx.number_of_synergies;
                app.MTPSynergyExtrapolation.synergy_extrapolation_categorization = ...
                    synx.synergy_extrapolation_categorization;
                app.MTPSynergyExtrapolation.residual_categorization = ...
                    synx.residual_categorization;
                if isfield(synx, 'RCNLCostTermSet') && ...
                        isfield(synx.RCNLCostTermSet, 'RCNLCostTerm')
                    for j = 1 : length(synx.RCNLCostTermSet.RCNLCostTerm)
                        app.MTPSynergyExtrapolation.RCNLCostTerm{j} = ...
                            RCNLCostTermClass(synx.RCNLCostTermSet.RCNLCostTerm{j});
                    end
                end
            end
            
            app.updateAuxPanel()
        
            % MTP tasks
            if isfield(settingsTree, 'MTPTaskList') && ...
                    isfield(settingsTree.MTPTaskList, 'MTPTask')
                tasks = settingsTree.MTPTaskList.MTPTask;
                if ~iscell(tasks)
                    tasks = {tasks};
                end
                app.MTPTask = cell(0);
                for i = 1 : length(tasks)
                    task = tasks{i};
                    app.createDefaultTask();
                    if isfield(task, "attributes")
                        app.MTPTask{i}.name       = task.Attributes.name;
                    end
                    app.MTPTask{i}.is_enabled = task.is_enabled;
                    app.MTPTask{i}.index      = task.index;
                    app.MTPTask{i}.muscle_specific_electromechanical_delays = ...
                        task.muscle_specific_electromechanical_delays;
                    app.MTPTask{i}.optimize_electromechanical_delays = ...
                        task.optimize_electromechanical_delays;
                    app.MTPTask{i}.optimize_activation_time_constants = ...
                        task.optimize_activation_time_constants;
                    app.MTPTask{i}.optimize_activation_nonlinearity_constants = ...
                        task.optimize_activation_nonlinearity_constants;
                    app.MTPTask{i}.optimize_emg_scale_factors = ...
                        task.optimize_emg_scale_factors;
                    app.MTPTask{i}.optimize_optimal_fiber_lengths = ...
                        task.optimize_optimal_fiber_lengths;
                    app.MTPTask{i}.optimize_tendon_slack_lengths = ...
                        task.optimize_tendon_slack_lengths;
                    if isfield(task, 'RCNLCostTermSet') && ...
                            isfield(task.RCNLCostTermSet, 'RCNLCostTerm')
                        for j = 1 : length(task.RCNLCostTermSet.RCNLCostTerm)
                            app.MTPTask{i}.RCNLCostTerm{j} = RCNLCostTermClass( ...
                                task.RCNLCostTermSet.RCNLCostTerm{j});
                        end
                        app.updateMTPCostTermsTable()
                    end
                end
            end
            app.updateMTPTasksListBox();
            app.needToSave = false;
        end

        function loadAdvancedParams(app, settingsTree)
            for i = 1 : length(app.advancedSettingNames)
                paramName = app.advancedSettingNames(i);
                if isfield(settingsTree, paramName)
                    app.advancedSettingValues(i) = settingsTree.(paramName);
                end
            end
        end

        function resetAllFields(app)
            app.model_coordinates = [];
            app.model_groups = [];
            
            app.input_model_file = "";
            app.input_osimx_file = "";
            app.data_directory = "";
            app.results_directory = "";
            app.coordinate_list = [];
            app.trial_prefixes = [];
            app.v_max_factor = 10;
    
            app.activation_muscle_groups = [];
            app.normalized_fiber_length_muscle_groups = [];
            app.missing_emg_channel_muscle_groups = [];
            app.collected_emg_channel_muscle_groups = [];
    
            app.MTPTask = cell(0);
            app.createDefaultTask();
            app.taskIndex = 1;
    
            app.designVariables = ["Muscle Specific Electromechanical Delays"
                "Electromechanical Delays"
                "Activation Time Constants"
                "Activation Non-linearity Constants"
                "EMG Scale Factors"
                "Optimal Fiber Lengths"
                "Tendon Slack Lengths"];
            
            app.MuscleTendonLengthInitialization = ...
                MuscleTendonLengthInitializationClass();
    
    
            app.MTPSynergyExtrapolation = ...
                SynergyExtrapolationClass();


            app.objectSelectionType = "";  % Used to filter in setSelectedObjects
            app.ErrorFlag = false;
            app.currentSettingsFile; % Variable to store the current settings file name.
            app.needToSave = false; % flag indicating if the settings file changed and needs to be saved before running.
            app.modelErrorFlag = false;
            app.osimxWarningFlag = false;
            app.dataDirectoryErrorFlag = false;
    
            app.timePoints = [];
            app.idLabels = [];
            app.emgLabels = [];
    
            app.advancedSettingNames = ...
                ["v_max_factor"
                "max_iterations"
                "max_function_evaluations"
                "step_tolerance"
                "function_tolerance"
                "optimality_tolerance"
                "diff_min_change"];
    
            app.advancedSettingValues = ...
                [10
                1000
                100000000
                1e-6
                1e-6
                1e-6
                0.0001];
        end
        end
    
    methods (Access = private)
        
        function ErrorsCallback(app)
        end

        function EnableActionsCallback(app)
        end
    end
    
    methods (Access = public)
        function setSelectedObjects(app, objects)
            % Function to assign objects selected by ObjectSelectionWindow
        switch app.objectSelectionType
            case 'coordinate'
                app.coordinate_list = objects;
            case 'activationGroup'
                app.activation_muscle_groups = objects;
            case 'fiberLengthGroup'
                app.normalized_fiber_length_muscle_groups = objects;
            case 'missingEmgGroup'
                app.missing_emg_channel_muscle_groups = objects;
            case 'collectedEmgGroup'
                app.collected_emg_channel_muscle_groups = objects;
        end
            app.EnableActionsCallback()
        end
        
        function modelFile = getInputModelFile(app)
            modelFile = app.input_model_file;
        end

        function model_coordinates = getModelCoordinate(app)
            model_coordinates = app.model_coordinates;
        end

        function setModelCoordinates(app, coordinates)
            app.model_coordinates = coordinates;
        end

        function setModelGroups(app, groups)
            app.model_groups = groups;
        end
        
        function setTimePoints(app, timePoints)
            app.timePoints = timePoints;
        end

        function setIDLabels(app, idLabels)
            app.idLabels = idLabels;
        end
        
        function setEmgLabels(app, emgLabels)
            app.emgLabels = emgLabels;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.createDefaultTask();
            app.initSynx();
            app.initMtli();
            app.updateAuxPanel();
            app.makeListeners()
            Options = app.advancedSettingNames;
            Values = [
                sprintf("%.0f", app.advancedSettingValues(1))
                sprintf("%.0f", app.advancedSettingValues(2))
                sprintf("%.3e", app.advancedSettingValues(3))
                sprintf("%.3e", app.advancedSettingValues(4))
                sprintf("%.3e", app.advancedSettingValues(5))
                sprintf("%.3e", app.advancedSettingValues(6))
                sprintf("%.3e", app.advancedSettingValues(7))];
            app.AdvancedSettingsTable.Data = table( ...
                Options, Values);
            app.AuxiliaryToolsTabGroup.SelectedTab = app.MuscleTendonLengthInitializationTab;
            app.formatTabButtons()
        end

        % Image clicked function: RcnlLogo
        function RcnlLogoImageClicked(app, event)
            web("https://nmsm.rice.edu/")
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetAllFields();
        end

        % Button pushed function: LoadSettingsFileButton
        function LoadSettingsFileButtonPushed(app, event)
            [file, path] = uigetfile('*.xml', "Load XML Settings File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.loadSettingsFile(fullfile(path, file));
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file, path] = uiputfile('*.xml', "Save XML Settings File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.saveSettingsFile(fullfile(path, file))
            app.currentSettingsFile = fullfile(path, file);
            app.needToSave = false;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            close all
            MTPRun(app, app.currentSettingsFile);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web("https://nmsm.rice.edu/guides-and-publications/tool-overviews/model-personalization/muscle-tendon-personalization/")
        end

        % Button pushed function: InputsButton
        function InputsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.InputsTab;
            % app.formatTabButtons()
        end

        % Button pushed function: MuscleGroupsButton
        function MuscleGroupsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.MuscleGroupsTab;
            % app.formatTabButtons()
        end

        % Button pushed function: MTPTasksButton
        function MTPTasksButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.MTPTasksTab;
            % app.formatTabButtons()
        end

        % Button pushed function: AuxiliaryToolsButton
        function AuxiliaryToolsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AuxiliaryTab;
            % app.formatTabButtons()
        end

        % Button pushed function: AdvancedButton
        function AdvancedButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AdvancedTab;
            % app.formatTabButtons()
        end

        % Value changed function: InputModelFileEditField
        function InputModelFileEditFieldValueChanged(app, event)
            app.input_model_file = GetFullPath( ...
                app.InputModelFileEditField.Value);
        end

        % Button pushed function: InputModelFileSearchButton
        function InputModelFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osim', "Select an OpenSim Model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.input_model_file = fullfile(path, file);
        end

        % Value changed function: InputOsimxFileEditField
        function InputOsimxFileEditFieldValueChanged(app, event)
            app.input_osimx_file = GetFullPath( ...
                app.InputOsimxFileEditField.Value);
        end

        % Button pushed function: InputOsimxFileSearchButton
        function InputOsimxFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osimx', "Select an Osimx model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.input_osimx_file = fullfile(path, file);
        end

        % Value changed function: ResultsDirectoryEditField
        function ResultsDirectoryEditFieldValueChanged(app, event)
            app.results_directory = GetFullPath( ...
                app.ResultsDirectoryEditField.Value);
        end

        % Button pushed function: ResultsDirectorySearchButton
        function ResultsDirectorySearchButtonPushed(app, event)
            folder = uigetdir("Select a folder to store your results");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.results_directory = folder;
        end

        % Value changed function: InputDataEditField
        function InputDataEditFieldValueChanged(app, event)
            app.data_directory = GetFullPath( ...
                app.InputDataEditField.Value);
        end

        % Button pushed function: InputDataSearchButton
        function InputDataSearchButtonPushed(app, event)
            folder = uigetdir("Select your input data folder");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.data_directory = folder;
        end

        % Button pushed function: CoordinateListEditButton
        function CoordinateListEditButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_coordinates, ...
                app.coordinate_list)
            app.objectSelectionType = 'coordinate';
        end

        % Value changed function: TrialPrefixesEditField
        function TrialPrefixesEditFieldValueChanged(app, event)
            value = app.TrialPrefixesEditField.Value;
            app.trial_prefixes = value;
        end

        % Button pushed function: EditActivationGroupsButton
        function EditActivationGroupsButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_groups, ...
                app.activation_muscle_groups)
            app.objectSelectionType = 'activationGroup';
        end

        % Button pushed function: EditFiberLengthGroupsButton
        function EditFiberLengthGroupsButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_groups, ...
                app.normalized_fiber_length_muscle_groups)
            app.objectSelectionType = 'fiberLengthGroup';
        end

        % Button pushed function: EditMissingEmgGroupsButton
        function EditMissingEmgGroupsButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_groups, ...
                app.missing_emg_channel_muscle_groups)
            app.objectSelectionType = 'missingEmgGroup';
        end

        % Button pushed function: EditCollectedEmgGroupsButton
        function EditCollectedEmgGroupsButtonPushed(app, event)
            %% should read directly from EMG file
            
            ObjectSelectionWindow(app, app.emgLabels, ...
                app.collected_emg_channel_muscle_groups)
            app.objectSelectionType = 'collectedEmgGroup';
        end

        % Selection changed function: MTPTasksTable
        function MTPTasksTableSelectionChanged(app, event)
            if isempty(app.MTPTasksTable.Selection)
                return
            end

            if app.MTPTasksTable.Selection == height(app.MTPTasksTable.Data) % if last element
                app.createDefaultTask();
                return
            else
                app.taskIndex = app.MTPTasksTable.Selection;
            end
        end

        % Cell edit callback: MTPTasksTable
        function MTPTasksTableCellEdit(app, event)
            indices = event.Indices;
            app.taskIndex = indices(1);
            if indices(2) == 1
                if app.MTPTasksTable.Data.isEnabled(indices(1))
                    app.MTPTask{app.taskIndex}.is_enabled = 'true';
                else
                    app.MTPTask{app.taskIndex}.is_enabled = 'false';
                end
            end
            if indices(2) == 2
                app.MTPTask{app.taskIndex}.name = event.NewData;
            end
            app.updateMTPTasksListBox()
        end

        % Button pushed function: MoveTaskUpButton
        function MoveTaskUpButtonPushed(app, event)
            app.moveTaskUp()
        end

        % Button pushed function: MoveTaskDownButton
        function MoveTaskDownButtonPushed(app, event)
            app.moveTaskDown()
        end

        % Menu selected function: RenameMenu
        function RenameMenuSelected(app, event)
            oldName = app.MTPTask{app.taskIndex}.name;
            newName = inputdlg("Rename task:", "Rename", [1 40], {oldName});
            if isempty(newName)
                return
            end
            app.MTPTask{app.taskIndex}.name = newName;
            app.updateMTPTasksListBox()
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            deletionIndex = 1 : 1 : length(app.MTPTask) == ...
                app.MTPTasksTable.Selection;
            app.deleteTask(deletionIndex);
        end

        % Cell edit callback: MTPCostTermsTable
        function MTPCostTermsTableCellEdit(app, event)
            indices = event.Indices;
            isEnabled = app.MTPCostTermsTable.Data.isEnabled(indices(1));
            if isEnabled
                app.MTPTask{app.taskIndex}.RCNLCostTerm{indices(1)}. ...
                    is_enabled = 'true';
            else
                app.MTPTask{app.taskIndex}.RCNLCostTerm{indices(1)}. ...
                    is_enabled = 'false';
            end
        end

        % Selection changed function: MTPCostTermsTable
        function MTPCostTermsTableSelectionChanged(app, event)
            if isempty(app.MTPCostTermsTable.Selection)
                return
            end
            costTermIndex = app.MTPCostTermsTable.Selection;
            app.MTPErrorCenterEditField.Value = ...
                app.MTPTask{app.taskIndex}.RCNLCostTerm{costTermIndex}. ...
                error_center;
            app.MTPMaxAllowableErrorEditField.Value = ...
                app.MTPTask{app.taskIndex}.RCNLCostTerm{costTermIndex}. ...
                max_allowable_error;
        end

        % Value changed function: MTPMaxAllowableErrorEditField
        function MTPMaxAllowableErrorEditFieldValueChanged(app, event)
            costTermIndex = app.MTPCostTermsTable.Selection;
            value = app.MTPMaxAllowableErrorEditField.Value;
            app.MTPTask{app.taskIndex}.RCNLCostTerm{costTermIndex}. ...
                max_allowable_error = value;
        end

        % Value changed function: MTPErrorCenterEditField
        function MTPErrorCenterEditFieldValueChanged(app, event)
            costTermIndex = app.MTPCostTermsTable.Selection;
            value = app.MTPErrorCenterEditField.Value;
            app.MTPTask{app.taskIndex}.RCNLCostTerm{costTermIndex}. ...
                error_center = value;
        end

        % Cell edit callback: MTPDesignVariablesTable
        function MTPDesignVariablesTableCellEdit(app, event)
            indices = event.Indices;
            isEnabled = app.MTPDesignVariablesTable.Data.isEnabled(indices(1));
            if isEnabled
                app.MTPTask{app.taskIndex}.setParameterValueByIndex(... 
                    indices(1), 'true')
            else
                app.MTPTask{app.taskIndex}.setParameterValueByIndex(... 
                    indices(1), 'false')
            end
        end

        % Button pushed function: MTLIButton
        function MTLIButtonPushed(app, event)
            app.AuxiliaryToolsTabGroup.SelectedTab = app.MuscleTendonLengthInitializationTab;
            % app.formatTabButtons()
            app.updateAuxPanel()
        end

        % Button pushed function: SynxButton
        function SynxButtonPushed(app, event)
            app.AuxiliaryToolsTabGroup.SelectedTab = app.SynergyExtrapolationTab;
            % app.formatTabButtons()
            app.updateAuxPanel()
        end

        % Value changed function: EnableMTLICheckBox
        function EnableMTLICheckBoxValueChanged(app, event)
            if app.EnableMTLICheckBox.Value
                app.MuscleTendonLengthInitialization.is_enabled = "true";
            else
                app.MuscleTendonLengthInitialization.is_enabled = "false";
            end
        end

        % Value changed function: PassiveDataDirectoryEditField
        function PassiveDataDirectoryEditFieldValueChanged(app, event)
            app.MuscleTendonLengthInitialization.passive_data_input_directory = ...
                GetFullPath(app.PassiveDataDirectoryEditField.Value);
        end

        % Button pushed function: PassiveDataDirectorySearchButton
        function PassiveDataDirectorySearchButtonPushed(app, event)
            folder = uigetdir("Select your passive data folder");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.MuscleTendonLengthInitialization.passive_data_input_directory = folder;
        end

        % Value changed function: MaxNormalizedFiberLengthEditField
        function MaxNormalizedFiberLengthEditFieldValueChanged(app, event)
            value = app.MaxNormalizedFiberLengthEditField.Value;
            app.MuscleTendonLengthInitialization.max_normalized_muscle_fiber_length = value;
        end

        % Value changed function: MinNormalizedFiberLengthEditField
        function MinNormalizedFiberLengthEditFieldValueChanged(app, event)
            value = app.MinNormalizedFiberLengthEditField.Value;
            app.MuscleTendonLengthInitialization.min_normalized_muscle_fiber_length = value;
        end

        % Value changed function: EnableSynergyExtrapolationCheckBox
        function EnableSynergyExtrapolationCheckBoxValueChanged(app, event)
            if app.EnableSynergyExtrapolationCheckBox.Value
                app.MTPSynergyExtrapolation.is_enabled = "true";
            else
                app.MTPSynergyExtrapolation.is_enabled = "false";
            end
        end

        % Value changed function: NumSynergiesSpinner
        function NumSynergiesSpinnerValueChanged(app, event)
            app.MTPSynergyExtrapolation.number_of_synergies = app.NumSynergiesSpinner.Value;
        end

        % Cell edit callback: AuxAdvancedSettingsTable
        function AuxAdvancedSettingsTableCellEdit(app, event)
            indices = event.Indices;
            switch app.AuxiliaryToolsTabGroup.SelectedTab
                case app.SynergyExtrapolationTab
                    app.MTPSynergyExtrapolation.setParameterValueByIndex(...
                        indices(1), app.AuxAdvancedSettingsTable.Data(indices(1), indices(2)).value);
                case app.MuscleTendonLengthInitializationTab
                    app.MuscleTendonLengthInitialization.setParameterValueByIndex(...
                        indices(1), app.AuxAdvancedSettingsTable.Data(indices(1), indices(2)).value);
            end
            
        end

        % Cell edit callback: AuxCostTermsTable
        function AuxCostTermsTableCellEdit(app, event)
            indices = event.Indices;
            isEnabled = app.AuxCostTermsTable.Data.isEnabled(indices(1));
            switch app.AuxiliaryToolsTabGroup.SelectedTab
                case app.SynergyExtrapolationTab
                    if isEnabled
                        app.MTPSynergyExtrapolation.RCNLCostTerm{indices(1)}. ...
                            is_enabled = "true";
                    else
                        app.MTPSynergyExtrapolation.RCNLCostTerm{indices(1)}. ...
                            is_enabled = "false";
                    end
                case app.MuscleTendonLengthInitializationTab
                    if isEnabled
                        app.MuscleTendonLengthInitialization.RCNLCostTerm{indices(1)}. ...
                            is_enabled = "true";
                    else
                        app.MuscleTendonLengthInitialization.RCNLCostTerm{indices(1)}. ...
                            is_enabled = "false";
                    end
            end
        end

        % Selection changed function: AuxCostTermsTable
        function AuxCostTermsTableSelectionChanged(app, event)
            if isempty(app.AuxCostTermsTable.Selection)
                return
            end
            costTermIndex = app.AuxCostTermsTable.Selection;
            switch app.AuxiliaryToolsTabGroup.SelectedTab
                case app.SynergyExtrapolationTab
                    app.AuxErrorCenterEditField.Value = ...
                        app.MTPSynergyExtrapolation.RCNLCostTerm{costTermIndex}. ...
                        error_center;
                    app.AuxMaxAllowableErrorEditField.Value = ...
                        app.MTPSynergyExtrapolation.RCNLCostTerm{costTermIndex}. ...
                        max_allowable_error;
                case app.MuscleTendonLengthInitializationTab
                    app.AuxErrorCenterEditField.Value = ...
                        app.MuscleTendonLengthInitialization.RCNLCostTerm{costTermIndex}. ...
                        error_center;
                    app.AuxMaxAllowableErrorEditField.Value = ...
                        app.MuscleTendonLengthInitialization.RCNLCostTerm{costTermIndex}. ...
                        max_allowable_error;
            end
        end

        % Value changed function: AuxMaxAllowableErrorEditField
        function AuxMaxAllowableErrorEditFieldValueChanged(app, event)
            value = app.AuxMaxAllowableErrorEditField.Value;
            costTermIndex = app.AuxCostTermsTable.Selection;
            switch app.AuxiliaryToolsTabGroup.SelectedTab
                case app.SynergyExtrapolationTab
                    app.MTPSynergyExtrapolation.RCNLCostTerm{costTermIndex}. ...
                        max_allowable_error = value;
                case app.MuscleTendonLengthInitializationTab
                    app.MuscleTendonLengthInitialization.RCNLCostTerm{costTermIndex}. ...
                        max_allowable_error = value;
            end
        end

        % Value changed function: AuxErrorCenterEditField
        function AuxErrorCenterEditFieldValueChanged(app, event)
            value = app.AuxErrorCenterEditField.Value;
            costTermIndex = app.AuxCostTermsTable.Selection;
            switch app.AuxiliaryToolsTabGroup.SelectedTab
                case app.SynergyExtrapolationTab
                    app.MTPSynergyExtrapolation.RCNLCostTerm{costTermIndex}. ...
                        error_center = value;
                case app.MuscleTendonLengthInitializationTab
                    app.MuscleTendonLengthInitialization.RCNLCostTerm{costTermIndex}. ...
                        error_center = value;
            end
        end

        % Cell edit callback: AdvancedSettingsTable
        function AdvancedSettingsTableCellEdit(app, event)
            index = event.Indices(1);

            if isnan(double(convertCharsToStrings(app.AdvancedSettingsTable.Data.Values(index))))
                app.AdvancedSettingsTable.Data.Values(index) = "NaN";
            else
                app.advancedSettingValues(index) = app.AdvancedSettingsTable.Data.Values(index);
            end
        end

        % Menu selected function: CopyMenu
        function CopyMenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MuscletendonModelPersonalizationToolLabel = uilabel(app.UIFigure);
            app.MuscletendonModelPersonalizationToolLabel.Text = 'Muscle-tendon Model Personalization Tool';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputModelFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputModelFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputModelFileSearchButtonPushed, true);
            app.InputModelFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputModelFileSearchButton.Text = '';
            app.InputModelFileEditFieldLabel = uilabel(app.InputsTab);
            app.InputModelFileEditFieldLabel.Text = 'Input Model File';
            app.InputModelFileEditField = uieditfield(app.InputsTab, 'text');
            app.InputModelFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputModelFileEditFieldValueChanged, true);
            app.InputModelFileStatus = uiimage(app.InputsTab);
            app.InputModelFileStatus.Visible = 'off';
            app.InputModelFileStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputOsimxFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputOsimxFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputOsimxFileSearchButtonPushed, true);
            app.InputOsimxFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputOsimxFileSearchButton.Text = '';
            app.InputOsimxFileEditFieldLabel = uilabel(app.InputsTab);
            app.InputOsimxFileEditFieldLabel.Text = 'Input Osimx File';
            app.InputOsimxFileEditField = uieditfield(app.InputsTab, 'text');
            app.InputOsimxFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputOsimxFileEditFieldValueChanged, true);
            app.InputOsimxFileStatus = uiimage(app.InputsTab);
            app.InputOsimxFileStatus.Visible = 'off';
            app.InputOsimxFileStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputDataSearchButton = uibutton(app.InputsTab, 'push');
            app.InputDataSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputDataSearchButtonPushed, true);
            app.InputDataSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputDataSearchButton.Text = '';
            app.InputDataEditField = uieditfield(app.InputsTab, 'text');
            app.InputDataEditField.ValueChangedFcn = createCallbackFcn(app, @InputDataEditFieldValueChanged, true);
            app.InputDataStatus = uiimage(app.InputsTab);
            app.InputDataStatus.Visible = 'off';
            app.InputDataStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.ResultsDirectorySearchButton = uibutton(app.InputsTab, 'push');
            app.ResultsDirectorySearchButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsDirectorySearchButtonPushed, true);
            app.ResultsDirectorySearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.ResultsDirectorySearchButton.Text = '';
            app.ResultsDirectoryEditFieldLabel = uilabel(app.InputsTab);
            app.ResultsDirectoryEditFieldLabel.Text = 'Results Directory';
            app.ResultsDirectoryEditField = uieditfield(app.InputsTab, 'text');
            app.ResultsDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @ResultsDirectoryEditFieldValueChanged, true);
            app.ResultsDirectoryStatus = uiimage(app.InputsTab);
            app.ResultsDirectoryStatus.Visible = 'off';
            app.ResultsDirectoryStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputDataDirectoryEditFieldLabel = uilabel(app.InputsTab);
            app.InputDataDirectoryEditFieldLabel.Text = 'Input Data Directory';
            app.CoordinatesListTextArea = uitextarea(app.InputsTab);
            app.CoordinatesListTextArea.Editable = 'off';
            app.CoordinatesListTextAreaLabel = uilabel(app.InputsTab);
            app.CoordinatesListTextAreaLabel.Text = 'Coordinates List';
            app.CoordinateListStatus = uiimage(app.InputsTab);
            app.CoordinateListStatus.Visible = 'off';
            app.CoordinateListStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.CoordinateListEditButton = uibutton(app.InputsTab, 'push');
            app.CoordinateListEditButton.ButtonPushedFcn = createCallbackFcn(app, @CoordinateListEditButtonPushed, true);
            app.CoordinateListEditButton.Text = 'Edit';
            app.TrialPrefixesEditFieldLabel = uilabel(app.InputsTab);
            app.TrialPrefixesEditFieldLabel.Text = 'Trial Prefixes';
            app.TrialPrefixesEditField = uieditfield(app.InputsTab, 'text');
            app.TrialPrefixesEditField.ValueChangedFcn = createCallbackFcn(app, @TrialPrefixesEditFieldValueChanged, true);
            app.TrialPrefixesStatus = uiimage(app.InputsTab);
            app.TrialPrefixesStatus.Visible = 'off';
            app.TrialPrefixesStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.MuscleGroupsTab = uitab(app.TabGroup);
            app.NormalizedFiberLengthMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.NormalizedFiberLengthMuscleGroupsTextAreaLabel.Text = 'Normalized Fiber Length Muscle Groups';
            app.NormalizedFiberLengthMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.NormalizedFiberLengthMuscleGroupsTextArea.Editable = 'off';
            app.MissingEMGMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.MissingEMGMuscleGroupsTextAreaLabel.Text = 'Missing EMG Muscle Groups';
            app.MissingEMGMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.MissingEMGMuscleGroupsTextArea.Editable = 'off';
            app.CollectedEMGMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.CollectedEMGMuscleGroupsTextAreaLabel.Text = 'Collected EMG Muscle Groups';
            app.CollectedEMGMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.CollectedEMGMuscleGroupsTextArea.Editable = 'off';
            app.ActivationMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.ActivationMuscleGroupsTextAreaLabel.Text = 'Activation Muscle Groups';
            app.ActivationMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.ActivationMuscleGroupsTextArea.Editable = 'off';
            app.EditActivationGroupsButton = uibutton(app.MuscleGroupsTab, 'push');
            app.EditActivationGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @EditActivationGroupsButtonPushed, true);
            app.EditActivationGroupsButton.Text = 'Edit';
            app.EditFiberLengthGroupsButton = uibutton(app.MuscleGroupsTab, 'push');
            app.EditFiberLengthGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @EditFiberLengthGroupsButtonPushed, true);
            app.EditFiberLengthGroupsButton.Text = 'Edit';
            app.EditMissingEmgGroupsButton = uibutton(app.MuscleGroupsTab, 'push');
            app.EditMissingEmgGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @EditMissingEmgGroupsButtonPushed, true);
            app.EditMissingEmgGroupsButton.Text = 'Edit';
            app.EditCollectedEmgGroupsButton = uibutton(app.MuscleGroupsTab, 'push');
            app.EditCollectedEmgGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @EditCollectedEmgGroupsButtonPushed, true);
            app.EditCollectedEmgGroupsButton.Text = 'Edit';
            app.MTPTasksTab = uitab(app.TabGroup);
            app.MTPCostTermPanel = uipanel(app.MTPTasksTab);
            app.MaxAllowableErrorEditField_2Label = uilabel(app.MTPCostTermPanel);
            app.MaxAllowableErrorEditField_2Label.Text = 'Max Allowable Error';
            app.MTPMaxAllowableErrorEditField = uieditfield(app.MTPCostTermPanel, 'numeric');
            app.MTPMaxAllowableErrorEditField.AllowEmpty = 'on';
            app.MTPMaxAllowableErrorEditField.ValueChangedFcn = createCallbackFcn(app, @MTPMaxAllowableErrorEditFieldValueChanged, true);
            app.MTPMaxAllowableErrorEditField.Value = [];
            app.ErrorCenterEditField_2Label = uilabel(app.MTPCostTermPanel);
            app.ErrorCenterEditField_2Label.Text = 'Error Center';
            app.MTPErrorCenterEditField = uieditfield(app.MTPCostTermPanel, 'numeric');
            app.MTPErrorCenterEditField.AllowEmpty = 'on';
            app.MTPErrorCenterEditField.ValueChangedFcn = createCallbackFcn(app, @MTPErrorCenterEditFieldValueChanged, true);
            app.MTPErrorCenterEditField.Value = [];
            app.MTPCostTermsTable = uitable(app.MTPCostTermPanel);
            app.MTPCostTermsTable.ColumnName = {''; 'Cost Term'};
            app.MTPCostTermsTable.RowName = {};
            app.MTPCostTermsTable.SelectionType = 'row';
            app.MTPCostTermsTable.ColumnEditable = [true false];
            app.MTPCostTermsTable.CellEditCallback = createCallbackFcn(app, @MTPCostTermsTableCellEdit, true);
            app.MTPCostTermsTable.SelectionChangedFcn = createCallbackFcn(app, @MTPCostTermsTableSelectionChanged, true);
            app.MTPCostTermsTable.Multiselect = 'off';
            app.EditMTPCostTermsLabel = uilabel(app.MTPTasksTab);
            app.EditMTPCostTermsLabel.Text = 'Edit Cost Terms';
            app.EditMTPDesignVariablesLabel = uilabel(app.MTPTasksTab);
            app.EditMTPDesignVariablesLabel.Text = 'Edit Design Variables';
            app.MTPDesignVariablesTable = uitable(app.MTPTasksTab);
            app.MTPDesignVariablesTable.ColumnName = {''; 'Design Variable'};
            app.MTPDesignVariablesTable.RowName = {};
            app.MTPDesignVariablesTable.SelectionType = 'row';
            app.MTPDesignVariablesTable.ColumnEditable = [true false];
            app.MTPDesignVariablesTable.CellEditCallback = createCallbackFcn(app, @MTPDesignVariablesTableCellEdit, true);
            app.MTPDesignVariablesTable.Multiselect = 'off';
            app.MTPTasksLabel = uilabel(app.MTPTasksTab);
            app.MTPTasksLabel.Text = 'Tasks';
            app.MTPTasksTable = uitable(app.MTPTasksTab);
            app.MTPTasksTable.ColumnName = {''; 'Task'};
            app.MTPTasksTable.RowName = {};
            app.MTPTasksTable.ColumnSortable = [false true];
            app.MTPTasksTable.SelectionType = 'row';
            app.MTPTasksTable.ColumnEditable = true;
            app.MTPTasksTable.CellEditCallback = createCallbackFcn(app, @MTPTasksTableCellEdit, true);
            app.MTPTasksTable.SelectionChangedFcn = createCallbackFcn(app, @MTPTasksTableSelectionChanged, true);
            app.MTPTasksTable.Multiselect = 'off';
            app.MoveTaskUpButton = uibutton(app.MTPTasksTab, 'push');
            app.MoveTaskUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveTaskUpButtonPushed, true);
            app.MoveTaskUpButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'ArrowUp.svg');
            app.MoveTaskUpButton.Text = '';
            app.MoveTaskDownButton = uibutton(app.MTPTasksTab, 'push');
            app.MoveTaskDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveTaskDownButtonPushed, true);
            app.MoveTaskDownButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'ArrowDown.svg');
            app.MoveTaskDownButton.Text = '';
            app.MTPTasksStatus = uiimage(app.MTPTasksTab);
            app.MTPTasksStatus.Visible = 'off';
            app.MTPTasksStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.MTPCostTermsStatus = uiimage(app.MTPTasksTab);
            app.MTPCostTermsStatus.Visible = 'off';
            app.MTPCostTermsStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.MTPDesignVariablesStatus = uiimage(app.MTPTasksTab);
            app.MTPDesignVariablesStatus.Visible = 'off';
            app.MTPDesignVariablesStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.AuxiliaryTab = uitab(app.TabGroup);
            app.PassiveDataInputDirectoryEditFieldLabel = uilabel(app.AuxiliaryTab);
            app.PassiveDataInputDirectoryEditFieldLabel.Text = 'Passive Data Input Directory';
            app.AuxiliaryToolsTabGroup = uitabgroup(app.AuxiliaryTab);
            app.MuscleTendonLengthInitializationTab = uitab(app.AuxiliaryToolsTabGroup);
            app.MuscleTendonLengthInitializationTab.Title = 'Tab';
            app.EnableMTLICheckBox = uicheckbox(app.MuscleTendonLengthInitializationTab);
            app.EnableMTLICheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableMTLICheckBoxValueChanged, true);
            app.EnableMTLICheckBox.Text = 'Enable Muscle-tendon Length Initialization';
            app.PassiveDataDirectoryStatus = uiimage(app.MuscleTendonLengthInitializationTab);
            app.PassiveDataDirectoryStatus.Visible = 'off';
            app.PassiveDataDirectoryStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.PassiveDataDirectorySearchButton = uibutton(app.MuscleTendonLengthInitializationTab, 'push');
            app.PassiveDataDirectorySearchButton.ButtonPushedFcn = createCallbackFcn(app, @PassiveDataDirectorySearchButtonPushed, true);
            app.PassiveDataDirectorySearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.PassiveDataDirectorySearchButton.Text = '';
            app.PassiveDataDirectoryEditFieldLabel = uilabel(app.MuscleTendonLengthInitializationTab);
            app.PassiveDataDirectoryEditFieldLabel.Text = 'Passive Data Directory';
            app.PassiveDataDirectoryEditField = uieditfield(app.MuscleTendonLengthInitializationTab, 'text');
            app.PassiveDataDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @PassiveDataDirectoryEditFieldValueChanged, true);
            app.MinNormalizedFiberLengthEditFieldLabel = uilabel(app.MuscleTendonLengthInitializationTab);
            app.MinNormalizedFiberLengthEditFieldLabel.Text = 'Min Normalized Fiber Length';
            app.MinNormalizedFiberLengthEditField = uieditfield(app.MuscleTendonLengthInitializationTab, 'numeric');
            app.MinNormalizedFiberLengthEditField.Limits = [0 3];
            app.MinNormalizedFiberLengthEditField.ValueChangedFcn = createCallbackFcn(app, @MinNormalizedFiberLengthEditFieldValueChanged, true);
            app.MinNormalizedFiberLengthEditField.Value = 0.7;
            app.MaxNormalizedFiberLengthEditFieldLabel = uilabel(app.MuscleTendonLengthInitializationTab);
            app.MaxNormalizedFiberLengthEditFieldLabel.Text = 'Max Normalized Fiber Length';
            app.MaxNormalizedFiberLengthEditField = uieditfield(app.MuscleTendonLengthInitializationTab, 'numeric');
            app.MaxNormalizedFiberLengthEditField.Limits = [0 3];
            app.MaxNormalizedFiberLengthEditField.ValueChangedFcn = createCallbackFcn(app, @MaxNormalizedFiberLengthEditFieldValueChanged, true);
            app.MaxNormalizedFiberLengthEditField.Value = 1;
            app.SynergyExtrapolationTab = uitab(app.AuxiliaryToolsTabGroup);
            app.SynergyExtrapolationTab.Title = 'Tab2';
            app.EnableSynergyExtrapolationCheckBox = uicheckbox(app.SynergyExtrapolationTab);
            app.EnableSynergyExtrapolationCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableSynergyExtrapolationCheckBoxValueChanged, true);
            app.EnableSynergyExtrapolationCheckBox.Text = 'Enable Synergy Extrapolation';
            app.NumSynergiesSpinner = uispinner(app.SynergyExtrapolationTab);
            app.NumSynergiesSpinner.Limits = [1 Inf];
            app.NumSynergiesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumSynergiesSpinnerValueChanged, true);
            app.NumSynergiesSpinner.Value = 1;
            app.NumSynergiesSpinnerLabel = uilabel(app.SynergyExtrapolationTab);
            app.NumSynergiesSpinnerLabel.Text = 'Num Synergies';
            app.NumSynergiesStatus = uiimage(app.SynergyExtrapolationTab);
            app.NumSynergiesStatus.Visible = 'off';
            app.NumSynergiesStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.MTLIButton = uibutton(app.AuxiliaryTab, 'push');
            app.MTLIButton.ButtonPushedFcn = createCallbackFcn(app, @MTLIButtonPushed, true);
            app.MTLIButton.Text = 'Muscle Tendon Length Initialization';
            app.SynxButton = uibutton(app.AuxiliaryTab, 'push');
            app.SynxButton.ButtonPushedFcn = createCallbackFcn(app, @SynxButtonPushed, true);
            app.SynxButton.Text = 'Synergy Extrapolation';
            app.AuxCostTermPanel = uipanel(app.AuxiliaryTab);
            app.MaxAllowableErrorEditFieldLabel = uilabel(app.AuxCostTermPanel);
            app.MaxAllowableErrorEditFieldLabel.Text = 'Max Allowable Error';
            app.AuxMaxAllowableErrorEditField = uieditfield(app.AuxCostTermPanel, 'numeric');
            app.AuxMaxAllowableErrorEditField.ValueChangedFcn = createCallbackFcn(app, @AuxMaxAllowableErrorEditFieldValueChanged, true);
            app.ErrorCenterEditFieldLabel = uilabel(app.AuxCostTermPanel);
            app.ErrorCenterEditFieldLabel.Text = 'Error Center';
            app.AuxErrorCenterEditField = uieditfield(app.AuxCostTermPanel, 'numeric');
            app.AuxErrorCenterEditField.ValueChangedFcn = createCallbackFcn(app, @AuxErrorCenterEditFieldValueChanged, true);
            app.AuxCostTermsTable = uitable(app.AuxCostTermPanel);
            app.AuxCostTermsTable.ColumnName = {''; 'Cost Term'};
            app.AuxCostTermsTable.RowName = {};
            app.AuxCostTermsTable.ColumnSortable = [false true];
            app.AuxCostTermsTable.SelectionType = 'row';
            app.AuxCostTermsTable.ColumnEditable = true;
            app.AuxCostTermsTable.CellEditCallback = createCallbackFcn(app, @AuxCostTermsTableCellEdit, true);
            app.AuxCostTermsTable.SelectionChangedFcn = createCallbackFcn(app, @AuxCostTermsTableSelectionChanged, true);
            app.AuxCostTermsTable.Multiselect = 'off';
            app.AuxAdvancedSettingsTable = uitable(app.AuxiliaryTab);
            app.AuxAdvancedSettingsTable.ColumnName = {'Parameter'; 'Value'};
            app.AuxAdvancedSettingsTable.RowName = {};
            app.AuxAdvancedSettingsTable.SelectionType = 'row';
            app.AuxAdvancedSettingsTable.ColumnEditable = [false true];
            app.AuxAdvancedSettingsTable.CellEditCallback = createCallbackFcn(app, @AuxAdvancedSettingsTableCellEdit, true);
            app.AuxCostTermsStatus = uiimage(app.AuxiliaryTab);
            app.AuxCostTermsStatus.Visible = 'off';
            app.AuxCostTermsStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.AuxAdvancedSettingsStatus = uiimage(app.AuxiliaryTab);
            app.AuxAdvancedSettingsStatus.Visible = 'off';
            app.AuxAdvancedSettingsStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.AdvancedSettingsLabel = uilabel(app.AuxiliaryTab);
            app.AdvancedSettingsLabel.Text = 'Advanced Settings';
            app.EditCostTermsLabel = uilabel(app.AuxiliaryTab);
            app.EditCostTermsLabel.Text = 'Edit Cost Terms';
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedSettingsTable = uitable(app.AdvancedTab);
            app.AdvancedSettingsTable.ColumnName = {'Option'; 'Value'};
            app.AdvancedSettingsTable.RowName = {};
            app.AdvancedSettingsTable.SelectionType = 'row';
            app.AdvancedSettingsTable.ColumnEditable = [false true];
            app.AdvancedSettingsTable.CellEditCallback = createCallbackFcn(app, @AdvancedSettingsTableCellEdit, true);
            app.AdvancedSettingsStatus = uiimage(app.AdvancedTab);
            app.AdvancedSettingsStatus.Visible = 'off';
            app.AdvancedSettingsStatus.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.Mask1 = uiimage(app.UIFigure);
            app.Mask1.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'greyMask.png');
            app.MTPImage = uiimage(app.UIFigure);
            app.MTPImage.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'mtpFigure.png');
            app.MuscleGroupsButton = uibutton(app.UIFigure, 'push');
            app.MuscleGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @MuscleGroupsButtonPushed, true);
            app.MuscleGroupsButton.Text = 'Muscle Groups';
            app.AuxiliaryToolsButton = uibutton(app.UIFigure, 'push');
            app.AuxiliaryToolsButton.ButtonPushedFcn = createCallbackFcn(app, @AuxiliaryToolsButtonPushed, true);
            app.AuxiliaryToolsButton.Text = 'Auxiliary Tools';
            app.AdvancedButton = uibutton(app.UIFigure, 'push');
            app.AdvancedButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedButtonPushed, true);
            app.AdvancedButton.Text = 'Advanced';
            app.InputsButton = uibutton(app.UIFigure, 'push');
            app.InputsButton.ButtonPushedFcn = createCallbackFcn(app, @InputsButtonPushed, true);
            app.InputsButton.Text = 'MTP Inputs';
            app.RcnlLogo = uiimage(app.UIFigure);
            app.RcnlLogo.ImageClickedFcn = createCallbackFcn(app, @RcnlLogoImageClicked, true);
            app.RcnlLogo.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'rcnlIcon.png');
            app.LoadSettingsFileButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsFileButtonPushed, true);
            app.LoadSettingsFileButton.Text = 'Load';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.MTPTasksButton = uibutton(app.UIFigure, 'push');
            app.MTPTasksButton.ButtonPushedFcn = createCallbackFcn(app, @MTPTasksButtonPushed, true);
            app.MTPTasksButton.Text = 'MTP Tasks';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.RenameMenu = uimenu(app.ContextMenu);
            app.RenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameMenuSelected, true);
            app.RenameMenu.Text = 'Rename';
            app.CopyMenu = uimenu(app.ContextMenu);
            app.CopyMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyMenuSelected, true);
            app.CopyMenu.Text = 'Copy';
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            % Assign app.ContextMenu
            app.MTPTasksTable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MTPBaseVisual

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end